classdef lvd_EditActionConditionalGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditActionConditionalUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        SaveandCloseButton             matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout9                    matlab.ui.container.GridLayout
        GridLayout10                   matlab.ui.container.GridLayout
        ConditionalTypeDropDown        matlab.ui.control.DropDown
        ConditionalTypeLabel           matlab.ui.control.Label
        ConditionalPropertiesPanel     matlab.ui.container.Panel
        ConditionalPropsGridLayout     matlab.ui.container.GridLayout
        GridLayout8                    matlab.ui.container.GridLayout
        Label                          matlab.ui.control.Label
        GridLayout5                    matlab.ui.container.GridLayout
        QuantityFrameLabel             matlab.ui.control.Label
        QuantityFrameSelector          referenceFrameSelectComp
        QuantityDropdown               matlab.ui.control.DropDown
        QuantityLabel                  matlab.ui.control.Label
        GridLayout7                    matlab.ui.container.GridLayout
        ToleranceText                  matlab.ui.control.EditField
        ToleranceUnitLabel             matlab.ui.control.Label
        ToleranceLabel                 matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        ComparisonValueText            matlab.ui.control.EditField
        ComparisonValueUnitLabel       matlab.ui.control.Label
        ComparisonValueLabel           matlab.ui.control.Label
        ComparisonTypeLabel            matlab.ui.control.Label
        ComparisonTypeDropdown         matlab.ui.control.DropDown
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        RemoveConditionalButton        matlab.ui.control.Button
        AddConditionalButton           matlab.ui.control.Button
        ConditionalTree                matlab.ui.container.Tree
        text2                          matlab.ui.control.Label
    end

    properties (Access = private)
        conditional AbstractActionConditional
        lvdData LvdData
        output AppDesignerGUIOutput
    end
    
    methods (Access = private)
        function populateGUI(app)
            app.populateTree();

            [listboxStr, m] = ConditionalTypeEnum.getListBoxStr();
            app.ConditionalTypeDropDown.Items = listboxStr;
            app.ConditionalTypeDropDown.ItemsData = m;

            taskList = lvd_getGraphAnalysisTaskList(app.lvdData, getLvdGAExcludeList());
            app.QuantityDropdown.Items = taskList;
            
            [listboxStr, m] = ComparisonTypeEnum.getListBoxStr();
            app.ComparisonTypeDropdown.Items = listboxStr;
            app.ComparisonTypeDropdown.ItemsData = m;

            app.QuantityFrameSelector.initializeWithFrames(app.lvdData);
            app.populateConditionalArea();

            addlistener(app.QuantityFrameSelector, 'FrameChanged', @(src,evt) app.handleQuantityFrameChanged(src,evt));
        end

        function populateTree(app)
            treeChildren = app.ConditionalTree.Children;
            treeChildren.delete();

            app.conditional.getTreeNodes(app.ConditionalTree);
            expand(app.ConditionalTree);

            if(isempty(app.ConditionalTree.SelectedNodes))
                app.ConditionalTree.SelectedNodes = app.ConditionalTree.Children(1);
            end
        end

        function populateConditionalArea(app)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;

            app.ConditionalTypeDropDown.Value = thisConditional.enum;
            
            if(thisConditional.enum == ConditionalTypeEnum.QuantityComparison)
                app.QuantityDropdown.Enable = true;
                app.QuantityDropdown.Value = thisConditional.task.taskStr;

                app.ComparisonTypeDropdown.Enable = true;
                app.ComparisonTypeDropdown.Value = thisConditional.comparisonType;

                app.ComparisonValueText.Enable = true;
                app.ComparisonValueText.Value = fullAccNum2Str(thisConditional.tgtValue);

                app.ToleranceText.Enable = true;
                app.ToleranceText.Value = fullAccNum2Str(thisConditional.tol);

                stateLogEntry = app.lvdData.initStateModel.getInitialStateLogEntry();
                [~, unitStr] = thisConditional.evaluateConditional(stateLogEntry);
                app.ComparisonValueUnitLabel.Text = unitStr;
                app.ComparisonValueUnitLabel.Tooltip = unitStr;
                app.ToleranceUnitLabel.Text = unitStr;
                app.ToleranceUnitLabel.Tooltip = unitStr;

                app.QuantityFrameSelector.Enable = true;
                if(isempty(thisConditional.frame))
                    thisConditional.frame = app.lvdData.initStateModel.centralBody.getBodyCenteredInertialFrame();
                end
                app.QuantityFrameSelector.setSelectedFrame(thisConditional.frame);

            else
                app.QuantityDropdown.Enable = false;
                app.ComparisonTypeDropdown.Enable = false;
                app.ComparisonValueText.Enable = false;
                app.ToleranceText.Enable = false;

                app.ComparisonValueText.Value = "";
                app.ToleranceText.Value = "";

                app.ComparisonValueUnitLabel.Text = "";
                app.ComparisonValueUnitLabel.Tooltip = "";
                app.ToleranceUnitLabel.Text = "";
                app.ToleranceUnitLabel.Tooltip = "";

                app.QuantityFrameSelector.Enable = false;
            end

            app.Label.Text = thisConditional.getConditionalString();
            app.Label.Tooltip = app.Label.Text;

            app.setAddRemoveConditionalButtonEnables();
        end

        function setAddRemoveConditionalButtonEnables(app)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;

            allNodes = app.getAllTreeNodes();
            if(thisConditional.enum == ConditionalTypeEnum.LogicalAnd || thisConditional.enum == ConditionalTypeEnum.LogicalOr)
                app.AddConditionalButton.Enable = true;
                if(numel(allNodes) > 1)
                    app.RemoveConditionalButton.Enable = true;
                else
                    app.RemoveConditionalButton.Enable = false;
                end

            elseif(thisConditional ~= app.conditional)
                parentNode = node.Parent;
                parentNodeConditional = parentNode.NodeData;
                
                if(parentNodeConditional.enum == ConditionalTypeEnum.LogicalAnd || parentNodeConditional.enum == ConditionalTypeEnum.LogicalOr)
                    app.AddConditionalButton.Enable = true;
                    if(numel(allNodes) > 1)
                        app.RemoveConditionalButton.Enable = true;
                    else
                        app.RemoveConditionalButton.Enable = false;
                    end
                end

            else
                app.AddConditionalButton.Enable = false;
                app.RemoveConditionalButton.Enable = false;
            end
        end

        function saveAndClose(app)
            app.output.output = {app.conditional, true};
            close(app.EditActionConditionalUIFigure);
        end

        function nodes = getAllTreeNodes(app)
            nodes = app.ConditionalTree.Children;
            nodes = nodes(:)';

            for(i=1:numel(nodes))
                newNodes = app.getNodeChildren(nodes(i));
                nodes = horzcat(nodes, newNodes(:)'); %#ok<AGROW>
            end
        end

        function nodes = getNodeChildren(app, node)
            nodes = node.Children;

            for(i=1:numel(nodes))
                nodes = horzcat(nodes, app.getNodeChildren(nodes(i))); %#ok<AGROW>
            end
        end

        function repopulateTreeAndSetToSelectedConditional(app, cond)
            app.populateTree();

            nC = app.getAllTreeNodes();
            for(i=1:numel(nC))
                node = nC(i);

                if(node.NodeData == cond)
                    app.ConditionalTree.SelectedNodes = nC(i);

                    break;
                end
            end

            app.populateConditionalArea();
        end

        function handleQuantityFrameChanged(app, src, evt)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;

            if(thisConditional.enum == ConditionalTypeEnum.QuantityComparison)
                thisConditional.frame = app.QuantityFrameSelector.getSelectedFrame();
                thisConditional.task.frame = thisConditional.frame;
            end

            app.Label.Text = thisConditional.getConditionalString();
            app.Label.Tooltip = app.Label.Text;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, conditional, lvdData, output)
            centerUIFigure(app.EditActionConditionalUIFigure);
            applySelectedThemeToApp(app);

            app.conditional = conditional;
            app.lvdData = lvdData;
            app.output = output;

            app.populateGUI();

            uiwait(app.EditActionConditionalUIFigure);
        end

        % Button pushed function: AddConditionalButton
        function AddConditionalButtonPushed(app, event)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;

            if(thisConditional.enum == ConditionalTypeEnum.LogicalAnd || thisConditional.enum == ConditionalTypeEnum.LogicalOr)
                newConditional = AlwaysTrueActionCondition();
                thisConditional.addConditional(newConditional);

                app.repopulateTreeAndSetToSelectedConditional(newConditional);

            elseif(thisConditional ~= app.conditional)
                parentNode = node.Parent;
                parentNodeConditional = parentNode.NodeData;
                
                if(parentNodeConditional.enum == ConditionalTypeEnum.LogicalAnd || parentNodeConditional.enum == ConditionalTypeEnum.LogicalOr)
                    newConditional = AlwaysTrueActionCondition();
                    parentNodeConditional.addConditional(newConditional);
    
                    app.repopulateTreeAndSetToSelectedConditional(newConditional);
                end
            end
        end

        % Button pushed function: RemoveConditionalButton
        function RemoveConditionalButtonPushed(app, event)
            node = app.ConditionalTree.SelectedNodes;            
            thisConditional = node.NodeData;

            if(thisConditional == app.conditional)
                newConditional = AlwaysTrueActionCondition();
                app.conditional = newConditional;

                app.repopulateTreeAndSetToSelectedConditional(newConditional);
            else
                parentNode = node.Parent;
                parentNodeConditional = parentNode.NodeData;

                if(parentNodeConditional.enum == ConditionalTypeEnum.LogicalAnd || parentNodeConditional.enum == ConditionalTypeEnum.LogicalOr)
                    parentNodeConditional.removeConditional(thisConditional);

                    app.repopulateTreeAndSetToSelectedConditional(parentNodeConditional);
                end
            end
        end

        % Value changed function: ComparisonValueText
        function ComparisonValueTextValueChanged(app, event)
            app.ComparisonValueText.Value = attemptStrEval(app.ComparisonValueText.Value);

            value = str2double(app.ComparisonValueText.Value);
            enteredStr = app.ComparisonValueText.Value;
            numberName = 'Comparison Value';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, {}, enteredStr);

            if(isempty(errMsg))
                node = app.ConditionalTree.SelectedNodes;
                thisConditional = node.NodeData;
                thisConditional.tgtValue = str2double(app.ComparisonValueText.Value);

                app.repopulateTreeAndSetToSelectedConditional(thisConditional);
            else
                uialert(app.EditActionConditionalUIFigure, errMsg, 'Invalid Comparison Value','Icon','error', 'Modal',true);
            end
        end

        % Value changed function: ToleranceText
        function ToleranceTextValueChanged(app, event)
            app.ToleranceText.Value = attemptStrEval(app.ToleranceText.Value);

            value = str2double(app.ToleranceText.Value);
            enteredStr = app.ToleranceText.Value;
            numberName = 'Comparison Tolerance';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, {}, enteredStr);

            if(isempty(errMsg))
                node = app.ConditionalTree.SelectedNodes;
                thisConditional = node.NodeData;
                thisConditional.tol = str2double(app.ToleranceText.Value);

                app.repopulateTreeAndSetToSelectedConditional(thisConditional);
            else
                uialert(app.EditActionConditionalUIFigure, errMsg, 'Invalid Comparison Tolerance','Icon','error', 'Modal',true);
            end            
        end

        % Value changed function: QuantityDropdown
        function QuantityDropdownValueChanged(app, event)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;
            
            if(thisConditional.enum == ConditionalTypeEnum.QuantityComparison)
                thisConditional.task.taskStr = app.QuantityDropdown.Value;
            end

            app.repopulateTreeAndSetToSelectedConditional(thisConditional);
        end

        % Value changed function: ComparisonTypeDropdown
        function ComparisonTypeDropdownValueChanged(app, event)
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;
            
            if(thisConditional.enum == ConditionalTypeEnum.QuantityComparison)
                thisConditional.comparisonType = app.ComparisonTypeDropdown.Value;
            end

            app.repopulateTreeAndSetToSelectedConditional(thisConditional);
        end

        % Value changed function: ConditionalTypeDropDown
        function ConditionalTypeDropDownValueChanged(app, event)
            newConditionalType = app.ConditionalTypeDropDown.Value;
            
            node = app.ConditionalTree.SelectedNodes;
            thisConditional = node.NodeData;

            switch newConditionalType
                case ConditionalTypeEnum.QuantityComparison
                    frame = app.lvdData.initStateModel.centralBody.getBodyCenteredInertialFrame(); %TODO this needs to be a user set frame
                    task = GraphicalAnalysisTask('Altitude', frame);
                    newConditional = QuantityComparisonActionCondition(task, 0, ComparisonTypeEnum.Equals, 0.0, frame);

                case ConditionalTypeEnum.AlwaysTrue
                    newConditional = AlwaysTrueActionCondition();

                case ConditionalTypeEnum.AlwaysFalse
                    newConditional = AlwaysFalseActionCondition();

                case ConditionalTypeEnum.LogicalAnd
                    newConditional = LogicalAndActionConditional(AbstractActionConditional.empty(1,0));

                case ConditionalTypeEnum.LogicalOr
                    newConditional = LogicalOrActionConditional(AbstractActionConditional.empty(1,0));

                otherwise
                    error('Unknown conditonal type: %s', newConditionalType.name);
            end

            if(thisConditional == app.conditional)
                app.conditional = newConditional;

            else
                parentNode = node.Parent;
                parentNodeConditional = parentNode.NodeData;

                parentNodeConditional.substituteConditional(thisConditional, newConditional);

            end

            app.populateTree();

            nC = app.getAllTreeNodes();
            for(i=1:numel(nC))
                node = nC(i);

                if(node.NodeData == newConditional)
                    app.ConditionalTree.SelectedNodes = node;

                    break;
                end
            end

            app.populateConditionalArea();
        end

        % Button pushed function: SaveandCloseButton
        function SaveandCloseButtonPushed(app, event)
            app.saveAndClose();
        end

        % Selection changed function: ConditionalTree
        function ConditionalTreeSelectionChanged(app, event)
            app.populateConditionalArea();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditActionConditionalUIFigure = uifigure('Visible', 'off');
            app.EditActionConditionalUIFigure.Name = 'Edit Action Conditional';
            app.GridLayout = uigridlayout(app.EditActionConditionalUIFigure);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Action Conditional';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ConditionalTree = uitree(app.GridLayout3);
            app.ConditionalTree.SelectionChangedFcn = createCallbackFcn(app, @ConditionalTreeSelectionChanged, true);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.AddConditionalButton = uibutton(app.GridLayout4, 'push');
            app.AddConditionalButton.ButtonPushedFcn = createCallbackFcn(app, @AddConditionalButtonPushed, true);
            app.AddConditionalButton.Icon = 'add.png';
            app.AddConditionalButton.Tooltip = {'Adds a condition to an AND or OR conditional.'};
            app.AddConditionalButton.Text = 'Add Condition';
            app.RemoveConditionalButton = uibutton(app.GridLayout4, 'push');
            app.RemoveConditionalButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveConditionalButtonPushed, true);
            app.RemoveConditionalButton.Icon = 'remove.png';
            app.RemoveConditionalButton.Tooltip = {'Removes a condition to an AND or OR conditional.'};
            app.RemoveConditionalButton.Text = 'Remove Condition';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.ConditionalPropertiesPanel = uipanel(app.GridLayout9);
            app.ConditionalPropertiesPanel.Title = 'Conditional Properties';
            app.ConditionalPropsGridLayout = uigridlayout(app.ConditionalPropertiesPanel);
            app.GridLayout8 = uigridlayout(app.ConditionalPropsGridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout8);
            app.ComparisonTypeDropdown = uidropdown(app.GridLayout5);
            app.ComparisonTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @ComparisonTypeDropdownValueChanged, true);
            app.ComparisonTypeLabel = uilabel(app.GridLayout5);
            app.ComparisonTypeLabel.Text = 'Comparison Type';
            app.ComparisonValueLabel = uilabel(app.GridLayout5);
            app.ComparisonValueLabel.Text = 'Comparison Value';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.ComparisonValueUnitLabel = uilabel(app.GridLayout6);
            app.ComparisonValueUnitLabel.Tag = 'utUnitLabel';
            app.ComparisonValueUnitLabel.Tooltip = {'Plugin variable units  are determined by the user directly.'};
            app.ComparisonValueUnitLabel.Text = '<unit>';
            app.ComparisonValueText = uieditfield(app.GridLayout6, 'text');
            app.ComparisonValueText.ValueChangedFcn = createCallbackFcn(app, @ComparisonValueTextValueChanged, true);
            app.ToleranceLabel = uilabel(app.GridLayout5);
            app.ToleranceLabel.Text = 'Tolerance';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.ToleranceUnitLabel = uilabel(app.GridLayout7);
            app.ToleranceUnitLabel.Tag = 'utUnitLabel';
            app.ToleranceUnitLabel.Tooltip = {'Plugin variable units  are determined by the user directly.'};
            app.ToleranceUnitLabel.Text = '<unit>';
            app.ToleranceText = uieditfield(app.GridLayout7, 'text');
            app.ToleranceText.ValueChangedFcn = createCallbackFcn(app, @ToleranceTextValueChanged, true);
            app.QuantityLabel = uilabel(app.GridLayout5);
            app.QuantityLabel.Text = 'Quantity';
            app.QuantityDropdown = uidropdown(app.GridLayout5);
            app.QuantityDropdown.ValueChangedFcn = createCallbackFcn(app, @QuantityDropdownValueChanged, true);
            app.QuantityFrameSelector = referenceFrameSelectComp(app.GridLayout5);
            app.QuantityFrameLabel = uilabel(app.GridLayout5);
            app.QuantityFrameLabel.Tooltip = {'The frame the quantity selected above is evaluated in (if relevant).'};
            app.QuantityFrameLabel.Text = 'Quantity Frame';
            app.Label = uilabel(app.GridLayout8);
            app.Label.Text = '';
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.ConditionalTypeLabel = uilabel(app.GridLayout10);
            app.ConditionalTypeLabel.Text = 'Conditional Type';
            app.ConditionalTypeDropDown = uidropdown(app.GridLayout10);
            app.ConditionalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionalTypeDropDownValueChanged, true);
            app.SaveandCloseButton = uibutton(app.GridLayout, 'push');
            app.SaveandCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandCloseButtonPushed, true);
            app.SaveandCloseButton.Icon = 'save-file.png';
            app.SaveandCloseButton.Text = 'Save and Close';
            % Show the figure after all components are created
            app.EditActionConditionalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditActionConditionalGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditActionConditionalUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditActionConditionalUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditActionConditionalUIFigure)
        end
    end
end