classdef lvd_editEventGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_editEventGUI                matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        EventSpecificPropagatorSettingsPanel  matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        GridLayout12                    matlab.ui.container.GridLayout
        integratorCombo                 matlab.ui.control.DropDown
        integratorOptionsButton         matlab.ui.control.Button
        GridLayout11                    matlab.ui.container.GridLayout
        propagatorCombo                 matlab.ui.control.DropDown
        propagatorOptionsButton         matlab.ui.control.Button
        propagatorDetailLabel           matlab.ui.control.Label
        integratorDetailLabel           matlab.ui.control.Label
        propDirCombo                    matlab.ui.control.DropDown
        checkSoITransCheckbox           matlab.ui.control.CheckBox
        ActionsPanel                    matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        removeActionButton              matlab.ui.control.Button
        addActionButton                 matlab.ui.control.Button
        actionExecNodeCombo             matlab.ui.control.DropDown
        actionsListbox                  matlab.ui.control.ListBox
        TerminationConditionPanel       matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        eventTermCondDirCombo           matlab.ui.control.DropDown
        editTermCondParamsButton        matlab.ui.control.Button
        termCondNameLabel               matlab.ui.control.Label
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout15                    matlab.ui.container.GridLayout
        Image4                          matlab.ui.control.Image
        plotMethodCombo                 matlab.ui.control.DropDown
        GridLayout14                    matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        colorSpecCombo                  matlab.ui.control.DropDown
        GridLayout13                    matlab.ui.container.GridLayout
        Image3                          matlab.ui.control.Image
        markerSizeCombo                 matlab.ui.control.DropDown
        text9_2                         matlab.ui.control.Label
        markerTypeCombo                 matlab.ui.control.DropDown
        GridLayout5                     matlab.ui.container.GridLayout
        Image2                          matlab.ui.control.Image
        lineWidthCombo                  matlab.ui.control.DropDown
        text9                           matlab.ui.control.Label
        lineSpecCombo                   matlab.ui.control.DropDown
        eventNameText                   matlab.ui.control.EditField
        text3                           matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        saveAndCloseButton              matlab.ui.control.Button
        nextEventButton                 matlab.ui.control.Button
        prevEventButton                 matlab.ui.control.Button
        text2                           matlab.ui.control.Label
        integratorContextMenu           matlab.ui.container.ContextMenu
        determineFastestIntegratorMenu  matlab.ui.container.Menu
        initialStepSizeContextMenu      matlab.ui.container.ContextMenu
        optimizeInitialStepSizeMenu     matlab.ui.container.Menu
    end

    
    properties (Access = private)
        output AppDesignerGUIOutput
    end
    methods (Access = private)
        function cancelButton_Callback(app, hObject, eventdata, handles) %#ok<INUSL> 
            % hObject    handle to cancelButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_editEventGUI);
        end

        function integratorObj = getSelectedEventIntegratorObj(app, handles)
            event = getappdata(handles.lvd_editEventGUI,'event');

            propagatorObj = app.getSelectedEventPropagatorObj(handles);

            contents = cellstr(get(handles.integratorCombo,'String'));
            nameStr = contents{get(handles.integratorCombo,'Value')};
            [~,m] = IntegratorEnum.getIndOfListboxStr(nameStr, propagatorObj.propagatorEnum.needsFirstOrderIntegrator, propagatorObj.propagatorEnum.needsSecondOrderIntegrator);

            if(isempty(m))
                [~,m] = IntegratorEnum.getListBoxStrs(propagatorObj.propagatorEnum.needsFirstOrderIntegrator, propagatorObj.propagatorEnum.needsSecondOrderIntegrator);
                m = m(1);
            end

            switch m
                case IntegratorEnum.ODE113
                    integratorObj = event.ode113Integrator;

                case IntegratorEnum.ODE15s
                    integratorObj = event.ode15sIntegrator;

                case IntegratorEnum.ODE23
                    integratorObj = event.ode23Integrator;

                case IntegratorEnum.ODE23s
                    integratorObj = event.ode23sIntegrator;

                case IntegratorEnum.ODE45
                    integratorObj = event.ode45Integrator;

                case IntegratorEnum.ODE78
                    integratorObj = event.ode78Integrator;

                case IntegratorEnum.ODE89
                    integratorObj = event.ode89Integrator;

                case IntegratorEnum.ODE5
                    integratorObj = event.ode5Integrator;

                case IntegratorEnum.RKN1210
                    integratorObj = event.rkn1210Integrator;

                otherwise
                    error('Unknown integrator type.');
            end
        end

        function propagatorObj = getSelectedEventPropagatorObj(app, handles) %#ok<INUSL> 
            event = getappdata(handles.lvd_editEventGUI,'event');

            contents = cellstr(get(handles.propagatorCombo,'String'));
            nameStr = contents{get(handles.propagatorCombo,'Value')};
            [~,m] = PropagatorEnum.getIndForName(nameStr);

            switch m
                case PropagatorEnum.ForceModel
                    propagatorObj = event.forceModelPropagator;

                case PropagatorEnum.TwoBody
                    propagatorObj = event.twoBodyPropagator;

                case PropagatorEnum.SecOrdGravOnly
                    propagatorObj = event.secOrdGravOnlyPropagator;

                otherwise
                    error('Unknown propagator type.');
            end
        end

        function loadNewEvent(app, newEvent, handles)
            hUiProg = uiprogressdlg(app.lvd_editEventGUI,"Indeterminate","on");
            
            saveEvent(app, handles);

            setappdata(handles.lvd_editEventGUI,'event',newEvent);
            newEvent.clearActiveOptVarsCache();

            isNonSeq = getappdata(handles.lvd_editEventGUI,'isNonSeq');
            populateGUI(app, handles, newEvent, isNonSeq);

            if(isvalid(hUiProg))
                close(hUiProg);
            end
        end

        function elapsedTime = optInitStepSizeObjFcn(app, x, event, initStateLogEntry, simDriver, tStartSimTime, isSparseOutput, activeNonSeqEvts) %#ok<INUSL> 
            integrator = event.integratorObj;
            propOptions = integrator.getOptions();
            propOptions.setIntegratorStepSize(x(1));

            tStartPropTime = tic();

            tt = tic;
            event.executeEvent(initStateLogEntry, simDriver, tStartPropTime, tStartSimTime, isSparseOutput, activeNonSeqEvts);
            elapsedTime = toc(tt);
        end

        function populateGUI(app, handles, event, isNonSeq)
            set(handles.eventNameText,'String',event.name);

            evtNum = event.getEventNum();
            if(not(isempty(evtNum)) && not(isnan(evtNum)))
                app.lvd_editEventGUI.Name = sprintf('Edit Event #%u', evtNum);
                app.prevEventButton.Enable = "on";
                app.nextEventButton.Enable = "on";
            else
                app.lvd_editEventGUI.Name = 'Edit Event';
                app.prevEventButton.Enable = "off";
                app.nextEventButton.Enable = "off";
            end

            termCond = event.termCond;
            set(handles.termCondNameLabel,'String',termCond.getName());
            set(handles.termCondNameLabel,'TooltipString',termCond.getName());

            [actionsListboxStr, actions] = event.getActionsListboxStr();
            app.actionsListbox.Items = actionsListboxStr;
            app.actionsListbox.ItemsData = actions;

            handles.colorSpecCombo.String = ColorSpecEnum.getListboxStr();
            [~,ind] = ColorSpecEnum.getEnumForListboxStr(event.colorLineSpec.color.name);
            handles.colorSpecCombo.Value = ind;

            handles.lineSpecCombo.String = LineSpecEnum.getListboxStr();
            [~,ind] = LineSpecEnum.getEnumForListboxStr(event.colorLineSpec.lineSpec.name);
            handles.lineSpecCombo.Value = ind;

            [listboxStrMarker, mMarker] = MarkerStyleEnum.getListboxStr();
            app.markerTypeCombo.Items = listboxStrMarker;
            app.markerTypeCombo.ItemsData = mMarker;
            app.markerTypeCombo.Value = event.colorLineSpec.markerSpec;

            validMarkerSizes = 1:12;
            app.markerSizeCombo.Items = string(validMarkerSizes);
            app.markerSizeCombo.ItemsData = validMarkerSizes;
            app.markerSizeCombo.Value = event.colorLineSpec.markerSize;

            handles.plotMethodCombo.String = EventPlottingMethodEnum.getListBoxStr();
            ind = EventPlottingMethodEnum.getIndForName(event.plotMethod.name);
            handles.plotMethodCombo.Value = ind;

            handles.eventTermCondDirCombo.String = EventTermCondDirectionEnum.getListboxStr();
            [~,ind] = EventTermCondDirectionEnum.getEnumForListboxStr(event.termCondDir.name);
            handles.eventTermCondDirCombo.Value = ind;

            contents = handles.lineWidthCombo.String;
            contentsDouble = str2double(contents);
            ind = find(contentsDouble == event.colorLineSpec.lineWidth);
            set(handles.lineWidthCombo,'Value',ind);

            handles.integratorCombo.String = IntegratorEnum.getListBoxStrs();
            ind = IntegratorEnum.getIndOfListboxStr(event.integratorObj.integratorEnum.nameStr);
            handles.integratorCombo.Value = ind;
            
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            integratorCombo_Callback(app, evt);

            handles.propagatorCombo.String = PropagatorEnum.getListBoxStr();
            ind = PropagatorEnum.getIndForName(event.propagatorObj.propagatorEnum.name);
            handles.propagatorCombo.Value = ind;
            
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            propagatorCombo_Callback(app, evt);

            handles.propDirCombo.String = PropagationDirectionEnum.getListBoxStr();
            handles.propDirCombo.Value = PropagationDirectionEnum.getIndForName(event.propDir.name);

            handles.checkSoITransCheckbox.Value = double(event.checkForSoITrans);

            handles.actionExecNodeCombo.String = ActionExecNodeEnum.getListBoxStr();
            handles.actionExecNodeCombo.Value = ActionExecNodeEnum.getIndForName(event.execActionsNode.name);

            if(isNonSeq)
                handles.colorSpecCombo.Enable = 'off';
                handles.lineSpecCombo.Enable = 'off';
                handles.lineWidthCombo.Enable = 'off';
                handles.plotMethodCombo.Enable = 'off';
                handles.propagatorCombo.Enable = 'off';
                handles.propagatorOptionsButton.Enable = 'off';
                handles.integratorCombo.Enable = 'off';
                handles.integratorOptionsButton.Enable = 'off';
                handles.propDirCombo.Enable = 'off';
                handles.checkSoITransCheckbox.Enable = 'off';
                handles.prevEventButton.Enable = 'off';
                handles.nextEventButton.Enable = 'off';
            end
        end

        function saveEvent(app, handles)
            event = getappdata(handles.lvd_editEventGUI,'event');

            evtName = get(handles.eventNameText,'String');
            event.name = evtName;

            nameStr = handles.colorSpecCombo.String(handles.colorSpecCombo.Value);
            [enum,~] = ColorSpecEnum.getEnumForListboxStr(nameStr);
            event.colorLineSpec.color = enum;

            nameStr = handles.lineSpecCombo.String(handles.lineSpecCombo.Value);
            [enum,~] = LineSpecEnum.getEnumForListboxStr(nameStr);
            event.colorLineSpec.lineSpec = enum;

            event.colorLineSpec.markerSpec = app.markerTypeCombo.Value;
            event.colorLineSpec.markerSize = app.markerSizeCombo.Value;

            nameStr = handles.eventTermCondDirCombo.String(handles.eventTermCondDirCombo.Value);
            [enum,~] = EventTermCondDirectionEnum.getEnumForListboxStr(nameStr);
            event.termCondDir = enum;

            nameStr = handles.plotMethodCombo.String(handles.plotMethodCombo.Value);
            [enum,~] = EventPlottingMethodEnum.getEnumForListboxStr(nameStr);
            event.plotMethod = enum;

            contents = handles.lineWidthCombo.String;
            contentsDouble = str2double(contents);
            contensInd = get(handles.lineWidthCombo,'Value');
            lineWidth = contentsDouble(contensInd);
            event.colorLineSpec.lineWidth = lineWidth;

            integratorObj = getSelectedEventIntegratorObj(app, handles);
            event.integratorObj = integratorObj;

            propagatorObj = getSelectedEventPropagatorObj(app, handles);
            event.propagatorObj = propagatorObj;

            nameStr = handles.propDirCombo.String(handles.propDirCombo.Value);
            [enum,~] = PropagationDirectionEnum.getEnumForListboxStr(nameStr);
            event.propDir = enum;

            event.checkForSoITrans = logical(handles.checkSoITransCheckbox.Value);

            nameStr = handles.actionExecNodeCombo.String(handles.actionExecNodeCombo.Value);
            [enum,~] = ActionExecNodeEnum.getEnumForListboxStr(nameStr);
            event.execActionsNode = enum;

            event.clearActiveOptVarsCache();
        end

        function errMsg = validateInputs(app, handles) %#ok<INUSD> 
            errMsg = {};
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_editEventGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_editEventGUI (see VARARGIN)

            % Choose default command line output for lvd_editEventGUI
            handles.output = hObject;
            
            centerUIFigure(app.lvd_editEventGUI);
            applySelectedThemeToApp(app);

            event = varargin{1};
            setappdata(hObject,'event',event);
            event.clearActiveOptVarsCache();

            isNonSeq = varargin{2};
            setappdata(hObject,'isNonSeq',isNonSeq);
            
            app.output = varargin{3};

            populateGUI(app, handles, event, isNonSeq);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_editEventGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_editEventGUI);
        end

        % Value changed function: actionsListbox
        function actionsListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to actionsListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns actionsListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from actionsListbox
%             if(strcmpi(get(handles.lvd_editEventGUI,'SelectionType'),'open'))
%                 event = getappdata(handles.lvd_editEventGUI,'event');
%                 lv = event.script.lvdData.launchVehicle;
% 
%                 if(not(isempty(event.getActionsListboxStr())))
%                     selActionInd = get(handles.actionsListbox,'Value');
%                     action = event.getActionForInd(selActionInd);
% 
%                     action.event = event;
%                     action.openEditActionUI(action, lv);
% 
%                     actionsListBoxStr = event.getActionsListboxStr();
% 
%                     if(not(isempty(actionsListBoxStr)))
%                         set(handles.actionsListbox,'String',actionsListBoxStr);
%                     else
%                         set(handles.actionsListbox,'String','');
%                         set(handles.actionsListbox,'Value',1);
%                     end
%                 end
%             end
        end

        % Button pushed function: addActionButton
        function addActionButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addActionButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            event = getappdata(handles.lvd_editEventGUI,'event');
            lv = event.script.lvdData.launchVehicle;
            actionStrs = EventActionEnum.getActionTypeNameStrs();

%             [dialogInd,tf] = listdlg('ListString',actionStrs, ...
%                 'Name','Select Action', ...
%                 'PromptString',{'Select the action you wish to add','to this event:'}, ...
%                 'SelectionMode','single', ...
%                 'ListSize',[210 300]);

            out = AppDesignerGUIOutput({[],false});
            listdlgARH_App('ListString',actionStrs, ...
                            'Name','Select Action', ...
                            'PromptString',{'Select the action you wish to add','to this event:'}, ...
                            'SelectionMode','single', ...
                            'ListSize',[210 300], ...
                            'out',out);
            dialogInd = out.output{1};
            tf = out.output{2};

            if(tf == 1)
                [m,~] = enumeration('EventActionEnum');
                inds = strcmpi({m.nameStr},actionStrs{dialogInd});

                actionEnum = m(inds);

                newAction = feval(actionEnum.classNameStr);
                newAction.event = event;
                addActionTf = newAction.openEditActionUI(newAction, lv);

                if(addActionTf)
                    event.addAction(newAction);
                end

%                 set(handles.actionsListbox,'String',event.getActionsListboxStr());
                [actionsListboxStr, actions] = event.getActionsListboxStr();
                app.actionsListbox.Items = actionsListboxStr;
                app.actionsListbox.ItemsData = actions;

                if(handles.actionsListbox.Value <= 0)
                    handles.actionsListbox.Value = 1;
                elseif(handles.actionsListbox.Value > length(handles.actionsListbox.String))
                    handles.actionsListbox.Value = length(handles.actionsListbox.String);
                end
            end
        end

        % Menu selected function: determineFastestIntegratorMenu
        function determineFastestIntegratorMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to determineFastestIntegratorMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            event = getappdata(handles.lvd_editEventGUI,'event');
            lvdData = event.lvdData;
            script = lvdData.script;

            initStateLogEntry = lvdData.stateLog.getFirstStateLogForEvent(event);

            simDriver = script.simDriver;
            tStartSimTime = initStateLogEntry.time;
            isSparseOutput = false;

            activeNonSeqEvts = script.nonSeqEvts.getNonSeqEventsForScriptEvent(event);
            for(j=1:length(activeNonSeqEvts))
                activeNonSeqEvts(j).initEvent(initStateLogEntry);
            end

            oldIntegrator = event.integratorObj;

%             hWaitBar = waitbar(0, '');
            hWaitBar = uiprogressdlg(app.lvd_editEventGUI, "Title","Determine Fastest Integrator");

            try
                [m,~] = enumeration('IntegratorEnum');
                m = m([m.isFirstOrder]);

                results = cell(length(m),2);
                for(i=1:length(m))
                    newIntegratorEnum = m(i);

                    if(isvalid(hWaitBar))
                        progVal = ((i-1)/length(m));
                        msgStr = sprintf('Running event with integrator "%s"...',newIntegratorEnum.nameStr);
%                         waitbar(progVal, hWaitBar, msgStr);
                        hWaitBar.Value = progVal;
                        hWaitBar.Message = msgStr;
                    end

                    tStartPropTime = tic();

                    integratorObj = IntegratorEnum.getIntegratorObjFromEnum(newIntegratorEnum);
                    event.integratorObj = integratorObj;

                    tt = tic;
                    event.executeEvent(initStateLogEntry, simDriver, tStartPropTime, tStartSimTime, isSparseOutput, activeNonSeqEvts);
                    elapsedTime = toc(tt);

                    results(i,:) = {newIntegratorEnum, elapsedTime};
                end
            catch ME
                event.integratorObj = oldIntegrator;
                throw(ME);
            end

            if(isvalid(hWaitBar))
                close(hWaitBar);
            end

            msg = {'Results of integrator speed test:', ''};
            for(i=1:size(results,1))
                integrator = results{i,1};
                time = results{i,2};

                msg = horzcat(msg, sprintf('%s:     %.3f sec', integrator.nameStr, time)); %#ok<AGROW>
            end

            [~,I] = min([results{:,2}]);
            fastestIntegratorEnum = m(I);

            msg = horzcat(msg,{'',sprintf('Apply fastest integrator (%s) for this event?',fastestIntegratorEnum.nameStr)});

            button = questdlg(msg,'Speed Test Results','Yes','No','Yes');

            if(strcmpi(button,'Yes'))
                fastestIntegratorObj = IntegratorEnum.getIntegratorObjFromEnum(fastestIntegratorEnum);
                event.integratorObj = fastestIntegratorObj;

                ind = IntegratorEnum.getIndOfListboxStr(fastestIntegratorEnum.nameStr);
                handles.integratorCombo.Value = ind;
                
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                integratorCombo_Callback(app, evt);
            else
                event.integratorObj = oldIntegrator;
            end
        end

        % Button pushed function: editTermCondParamsButton
        function editTermCondParamsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editTermCondParamsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            event = getappdata(handles.lvd_editEventGUI,'event');
            lvd_editEvtTermCond_App(event);

            termCond = event.termCond;
            set(handles.termCondNameLabel,'String',termCond.getName());
        end

        % Value changed function: integratorCombo
        function integratorCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.integratorCombo;
            
            % hObject    handle to integratorCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns integratorCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from integratorCombo
            contents = cellstr(get(hObject,'String'));

            if(get(hObject,'Value') < 1 || get(hObject,'Value') > length(contents))
                nameStr = contents{1};
            else
                nameStr = contents{get(hObject,'Value')};
            end

            [~,m] = IntegratorEnum.getIndOfListboxStr(nameStr);
            handles.integratorDetailLabel.String = m.descStr;
        end

        % Button pushed function: integratorOptionsButton
        function integratorOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to integratorOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            integratorObj = getSelectedEventIntegratorObj(app, handles);

            options = integratorObj.getOptions();
            options.openOptionsDialog();
        end

        % Window key press function: lvd_editEventGUI
        function lvd_editEventGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_editEventGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            
            switch(eventdata.Key)
                case 'return'
                    saveAndCloseButton_Callback(app, evt);
                case 'enter'
                    saveAndCloseButton_Callback(app, evt);
                case 'escape'
                    close(handles.lvd_editEventGUI);
            end
        end

        % Button pushed function: nextEventButton
        function nextEventButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to nextEventButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            event = getappdata(handles.lvd_editEventGUI,'event');
            eventNum = event.getEventNum();

            script = event.script;
            numEvents = script.getTotalNumOfEvents();

            if(numEvents > 1)
                if(eventNum >= numEvents)
                    newEvent = script.getEventForInd(1);
                else
                    newEvent = script.getEventForInd(eventNum + 1);
                end

                loadNewEvent(app, newEvent, handles);
            end
        end

        % Menu selected function: optimizeInitialStepSizeMenu
        function optimizeInitialStepSizeMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to optimizeInitialStepSizeMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            event = getappdata(handles.lvd_editEventGUI,'event');
            lvdData = event.lvdData;
            script = lvdData.script;

            simDriver = script.simDriver;
            tStartSimTime = 0;
            isSparseOutput = false;

            initStateLogEntry = lvdData.stateLog.getFirstStateLogForEvent(event);

            activeNonSeqEvts = script.nonSeqEvts.getNonSeqEventsForScriptEvent(event);
            for(j=1:length(activeNonSeqEvts)) %#ok<*NO4LP> 
                activeNonSeqEvts(j).initEvent(initStateLogEntry);
            end

            oldInitStep = event.initialStep;

            fun = @(x) optInitStepSizeObjFcn(app, x, event, initStateLogEntry, simDriver, tStartSimTime, isSparseOutput, activeNonSeqEvts);
            x0 = oldInitStep;
            lb = 0.01;
            ub = 1E10;
            %     options = optimoptions(@fmincon, 'Display','iter', 'OptimalityTolerance',1E-2, 'StepTolerance',1E-10, 'PlotFcn',{@optimplotx, @optimplotfval});
            options = optimoptions(@patternsearch, 'Display','iter', 'UseParallel',lvdData.optimizer.usesParallel(), 'PlotFcn',{@psplotbestx, @psplotbestf});

            %     x = fmincon(fun,x0,[],[],[],[],lb,ub,[],options);
            x = patternsearch(fun,x0,[],[],[],[],lb,ub,[],options);
            msg = sprintf('Results of initial step size tuning: %0.3f sec', x);

            msg = horzcat(msg,{'','',sprintf('Apply optimized initial step size to this event?')});

            button = questdlg(msg,'Speed Test Results','Yes','No','Yes');

            if(strcmpi(button,'Yes'))
                handles.initialStepText.String = fullAccNum2Str(x);
                initialStepText_Callback(handles.initialStepText, [], handles);
            else
                integrator = event.integratorObj;
                propOptions = integrator.getOptions();
                propOptions.setIntegratorStepSize(oldInitStep);
            end
        end

        % Button pushed function: prevEventButton
        function prevEventButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to prevEventButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            event = getappdata(handles.lvd_editEventGUI,'event');
            eventNum = event.getEventNum();

            script = event.script;
            numEvents = script.getTotalNumOfEvents();

            if(numEvents > 1)
                if(eventNum <= 1)
                    newEvent = script.getEventForInd(numEvents);
                else
                    newEvent = script.getEventForInd(eventNum - 1);
                end

                loadNewEvent(app, newEvent, handles);
            end
        end

        % Value changed function: propagatorCombo
        function propagatorCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = handles.propagatorCombo;
            
            % hObject    handle to propagatorCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns propagatorCombo contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from propagatorCombo
            contents = cellstr(get(hObject,'String'));

            if(get(hObject,'Value') < 1 || get(hObject,'Value') > length(contents))
                nameStr = contents{1};
            else
                nameStr = contents{get(hObject,'Value')};
            end

            [~,m] = PropagatorEnum.getIndForName(nameStr);
            handles.propagatorDetailLabel.String = m.desc;

            %Handle changing available integrators
            integratorObj = app.getSelectedEventIntegratorObj(handles);
            [intListboxStr, m] = IntegratorEnum.getListBoxStrs(integratorObj.integratorEnum.isFirstOrder, integratorObj.integratorEnum.isSecondOrder);

            app.integratorCombo.Items = string(intListboxStr);
            if(any(integratorObj.integratorEnum == m))
                handles.integratorCombo.Value = find(integratorObj.integratorEnum == m, 1);
            end

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            integratorCombo_Callback(app, evt);
        end

        % Button pushed function: propagatorOptionsButton
        function propagatorOptionsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to propagatorOptionsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            propagatorObj = getSelectedEventPropagatorObj(app, handles);

            propagatorObj.openOptionsDialog();
        end

        % Button pushed function: removeActionButton
        function removeActionButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeActionButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            event = getappdata(handles.lvd_editEventGUI,'event');

%             selActionInd = get(handles.actionsListbox,'Value');
            action = app.actionsListbox.Value;
            if(not(isempty(action)))
                event.removeAction(action);
    
    %             set(handles.actionsListbox,'String',event.getActionsListboxStr());
                [actionsListboxStr, actions] = event.getActionsListboxStr();
                app.actionsListbox.Items = actionsListboxStr;
                app.actionsListbox.ItemsData = actions;
    
%                 aListBosStr = get(handles.actionsListbox,'String');
%                 if(iscell(aListBosStr) && length(aListBosStr)<selActionInd)
%                     set(handles.actionsListbox,'Value',length(aListBosStr));
%                 end
            end
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
%             hObject = handles.saveAndCloseButton;
            
            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(isempty(errMsg))
                saveEvent(app, handles);
            
                app.output.output = {true};
                close(app.lvd_editEventGUI);
            else
                msgbox(errMsg,'Errors were found while editing the event.','error');
            end
        end

        % Window button down function: lvd_editEventGUI
        function lvd_editEventGUIWindowButtonDown(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            selType = app.lvd_editEventGUI.SelectionType;
            curObj = app.lvd_editEventGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.actionsListbox)
                    event = getappdata(handles.lvd_editEventGUI,'event');
                    lv = event.script.lvdData.launchVehicle;
    
                    if(not(isempty(event.getActionsListboxStr())))
%                         hProgDlg = uiprogressdlg(app.lvd_editEventGUI, "Indeterminate","on", "Title","Opening Action", "Message","Opening the Edit Action UI, please wait.");
                        
%                         selActionInd = get(handles.actionsListbox,'Value');
%                         action = event.getActionForInd(selActionInd);
                        action = app.actionsListbox.Value;
    
                        if(not(isempty(action)))
                            action.event = event;
                            action.openEditActionUI(action, lv);
        
                            [actionsListBoxStr, actions] = event.getActionsListboxStr();
                            
                            if(not(isempty(actionsListBoxStr)))
    %                             set(handles.actionsListbox,'String',actionsListBoxStr);
                                app.actionsListbox.Items = actionsListBoxStr;
                                app.actionsListbox.ItemsData = actions;
                            else
    %                             set(handles.actionsListbox,'String','');
    %                             set(handles.actionsListbox,'Value',1);
                                app.actionsListbox.Items = {};
                                app.actionsListbox.ItemsData = AbstractEventAction.empty(1,0);
                            end
                            
    %                         close(hProgDlg);
                        end
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_editEventGUI = uifigure('Visible', 'off');
            app.lvd_editEventGUI.Name = 'Edit Event';
            app.lvd_editEventGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_editEventGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_editEventGUIWindowButtonDown, true);
            app.lvd_editEventGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_editEventGUI_WindowKeyPressFcn, true);
            app.lvd_editEventGUI.Tag = 'lvd_editEventGUI';
            app.GridLayout = uigridlayout(app.lvd_editEventGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Event';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.prevEventButton = uibutton(app.GridLayout2, 'push');
            app.prevEventButton.ButtonPushedFcn = createCallbackFcn(app, @prevEventButton_Callback, true);
            app.prevEventButton.Tag = 'prevEventButton';
            app.prevEventButton.Icon = 'left-arrow.png';
            app.prevEventButton.Tooltip = 'Save current event data and jump to previous event in the script.';
            app.prevEventButton.Text = '';
            app.nextEventButton = uibutton(app.GridLayout2, 'push');
            app.nextEventButton.ButtonPushedFcn = createCallbackFcn(app, @nextEventButton_Callback, true);
            app.nextEventButton.Tag = 'nextEventButton';
            app.nextEventButton.Icon = 'right-arrow.png';
            app.nextEventButton.Tooltip = 'Save current event data and jump to next event in the script.';
            app.nextEventButton.Text = '';
            app.saveAndCloseButton = uibutton(app.GridLayout2, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Icon = 'save-file.png';
            app.saveAndCloseButton.Text = 'Save & Close';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.text3 = uilabel(app.GridLayout4);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Event Name';
            app.eventNameText = uieditfield(app.GridLayout4, 'text');
            app.eventNameText.Tag = 'eventNameText';
            app.eventNameText.Tooltip = 'The name of this event as it appears in the mission script.';
            app.eventNameText.Value = 'Untitled Event';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.lineSpecCombo = uidropdown(app.GridLayout5);
            app.lineSpecCombo.Items = {'Pop-up Menu'};
            app.lineSpecCombo.Tag = 'lineSpecCombo';
            app.lineSpecCombo.Value = 'Pop-up Menu';
            app.text9 = uilabel(app.GridLayout5);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Width';
            app.lineWidthCombo = uidropdown(app.GridLayout5);
            app.lineWidthCombo.Items = {'0.25', '0.50', '0.75', '1.0', '1.5', '2.0', '2.5', '3.0', '3.5', '4.0', '4.5', '5.0'};
            app.lineWidthCombo.Tag = 'lineWidthCombo';
            app.lineWidthCombo.Value = '1.5';
            app.Image2 = uiimage(app.GridLayout5);
            app.Image2.Tooltip = {'Line Options'};
            app.Image2.ImageSource = 'line.png';
            app.GridLayout13 = uigridlayout(app.GridLayout4);
            app.markerTypeCombo = uidropdown(app.GridLayout13);
            app.markerTypeCombo.Tooltip = {'Places a marker of the specified shape and size at the various points at which the integrator returns data when propagating this event.  Color is the same as the line color. '};
            app.text9_2 = uilabel(app.GridLayout13);
            app.text9_2.Tag = 'text9';
            app.text9_2.Text = 'Size';
            app.markerSizeCombo = uidropdown(app.GridLayout13);
            app.markerSizeCombo.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.markerSizeCombo.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.markerSizeCombo.Value = '6';
            app.Image3 = uiimage(app.GridLayout13);
            app.Image3.Tooltip = {'Marker Options'};
            app.Image3.ImageSource = 'diamond.png';
            app.GridLayout14 = uigridlayout(app.GridLayout4);
            app.colorSpecCombo = uidropdown(app.GridLayout14);
            app.colorSpecCombo.Items = {'Pop-up Menu'};
            app.colorSpecCombo.Tag = 'colorSpecCombo';
            app.colorSpecCombo.Value = 'Pop-up Menu';
            app.Image = uiimage(app.GridLayout14);
            app.Image.Tooltip = {'Color Options'};
            app.Image.ImageSource = 'color_wheel.png';
            app.GridLayout15 = uigridlayout(app.GridLayout4);
            app.plotMethodCombo = uidropdown(app.GridLayout15);
            app.plotMethodCombo.Items = {'Pop-up Menu'};
            app.plotMethodCombo.Tag = 'plotMethodCombo';
            app.plotMethodCombo.Tooltip = 'Plot continuous will connect the final state of the previous event with the initials state of this event.  Skipping the first state will effectively only plot this event and could be discontinuous with the previous event.';
            app.plotMethodCombo.Value = 'Pop-up Menu';
            app.Image4 = uiimage(app.GridLayout15);
            app.Image4.ImageSource = 'settings.png';
            app.TerminationConditionPanel = uipanel(app.GridLayout3);
            app.TerminationConditionPanel.Title = 'Termination Condition';
            app.GridLayout6 = uigridlayout(app.TerminationConditionPanel);
            app.termCondNameLabel = uilabel(app.GridLayout6);
            app.termCondNameLabel.Tag = 'termCondNameLabel';
            app.termCondNameLabel.Text = 'Term Cond';
            app.editTermCondParamsButton = uibutton(app.GridLayout6, 'push');
            app.editTermCondParamsButton.ButtonPushedFcn = createCallbackFcn(app, @editTermCondParamsButton_Callback, true);
            app.editTermCondParamsButton.Tag = 'editTermCondParamsButton';
            app.editTermCondParamsButton.Icon = 'stop(1).png';
            app.editTermCondParamsButton.Text = 'Set Termination Condition Parameters';
            app.eventTermCondDirCombo = uidropdown(app.GridLayout6);
            app.eventTermCondDirCombo.Items = {'Pop-up Menu'};
            app.eventTermCondDirCombo.Tag = 'eventTermCondDirCombo';
            app.eventTermCondDirCombo.Tooltip = 'The direction the value of the termination condition must be moving in order for this condition to trigger.  Leave at "Either Increasing or Decreasing" to use either direction.';
            app.eventTermCondDirCombo.Value = 'Pop-up Menu';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.ActionsPanel = uipanel(app.GridLayout7);
            app.ActionsPanel.Title = 'Actions';
            app.GridLayout8 = uigridlayout(app.ActionsPanel);
            app.actionsListbox = uilistbox(app.GridLayout8);
            app.actionsListbox.Items = {'Listbox'};
            app.actionsListbox.ValueChangedFcn = createCallbackFcn(app, @actionsListbox_Callback, true);
            app.actionsListbox.Tag = 'actionsListbox';
            app.actionsListbox.Tooltip = 'Actions associated with this event.  Actions are operations carried out at the end of an event.';
            app.actionsListbox.Value = 'Listbox';
            app.actionExecNodeCombo = uidropdown(app.GridLayout8);
            app.actionExecNodeCombo.Items = {'Pop-up Menu'};
            app.actionExecNodeCombo.Tag = 'actionExecNodeCombo';
            app.actionExecNodeCombo.Tooltip = 'The selection here dictates when the above actions will be executed: either after the propagationu completes (the default) or before the propagation starts.';
            app.actionExecNodeCombo.Value = 'Pop-up Menu';
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.addActionButton = uibutton(app.GridLayout9, 'push');
            app.addActionButton.ButtonPushedFcn = createCallbackFcn(app, @addActionButton_Callback, true);
            app.addActionButton.Tag = 'addActionButton';
            app.addActionButton.Icon = 'add.png';
            app.addActionButton.Text = 'Add Action';
            app.removeActionButton = uibutton(app.GridLayout9, 'push');
            app.removeActionButton.ButtonPushedFcn = createCallbackFcn(app, @removeActionButton_Callback, true);
            app.removeActionButton.Tag = 'removeActionButton';
            app.removeActionButton.Icon = 'remove.png';
            app.removeActionButton.Text = 'Remove Action';
            app.EventSpecificPropagatorSettingsPanel = uipanel(app.GridLayout7);
            app.EventSpecificPropagatorSettingsPanel.Title = 'Event-Specific Propagator Settings';
            app.GridLayout10 = uigridlayout(app.EventSpecificPropagatorSettingsPanel);
            app.checkSoITransCheckbox = uicheckbox(app.GridLayout10);
            app.checkSoITransCheckbox.Tag = 'checkSoITransCheckbox';
            app.checkSoITransCheckbox.Tooltip = 'When checked, the propagator will detect and handle SoI transitions.  When not checked, SoI transitions are ignored unless the termination condition is Next SoI Transition.  Uncheck to improve integrator performance.';
            app.checkSoITransCheckbox.Text = 'Consider SoI Transitions';
            app.propDirCombo = uidropdown(app.GridLayout10);
            app.propDirCombo.Items = {'Pop-up Menu'};
            app.propDirCombo.Tag = 'propDirCombo';
            app.propDirCombo.Value = 'Pop-up Menu';
            app.integratorDetailLabel = uilabel(app.GridLayout10);
            app.integratorDetailLabel.Tag = 'integratorDetailLabel';
            app.integratorDetailLabel.Text = 'Static Text';
            app.propagatorDetailLabel = uilabel(app.GridLayout10);
            app.propagatorDetailLabel.Tag = 'propagatorDetailLabel';
            app.propagatorDetailLabel.Text = 'Static Text';
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.propagatorOptionsButton = uibutton(app.GridLayout11, 'push');
            app.propagatorOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @propagatorOptionsButton_Callback, true);
            app.propagatorOptionsButton.Tag = 'propagatorOptionsButton';
            app.propagatorOptionsButton.Tooltip = 'Sets the options for the selected propagator.';
            app.propagatorOptionsButton.Text = 'Propagator Options';
            app.propagatorCombo = uidropdown(app.GridLayout11);
            app.propagatorCombo.Items = {'Pop-up Menu'};
            app.propagatorCombo.ValueChangedFcn = createCallbackFcn(app, @propagatorCombo_Callback, true);
            app.propagatorCombo.Tag = 'propagatorCombo';
            app.propagatorCombo.Tooltip = 'Select the propagator that should be used to propagate this event.  This option dictates the type and complexity of the motion.  Use "force model" if unsure.';
            app.propagatorCombo.Value = 'Pop-up Menu';
            app.GridLayout12 = uigridlayout(app.GridLayout10);
            app.integratorOptionsButton = uibutton(app.GridLayout12, 'push');
            app.integratorOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @integratorOptionsButton_Callback, true);
            app.integratorOptionsButton.Tag = 'integratorOptionsButton';
            app.integratorOptionsButton.Tooltip = 'Sets the options for the selected integrator.';
            app.integratorOptionsButton.Text = 'Integrator Options';
            app.integratorCombo = uidropdown(app.GridLayout12);
            app.integratorCombo.Items = {'Pop-up Menu'};
            app.integratorCombo.ValueChangedFcn = createCallbackFcn(app, @integratorCombo_Callback, true);
            app.integratorCombo.Tag = 'integratorCombo';
            app.integratorCombo.Tooltip = 'Select the integrator algorithm that should be used to propagate this event.';
            app.integratorCombo.Value = 'Pop-up Menu';
            app.integratorContextMenu = uicontextmenu(app.lvd_editEventGUI);
            app.integratorContextMenu.Tag = 'integratorContextMenu';
            app.determineFastestIntegratorMenu = uimenu(app.integratorContextMenu);
            app.determineFastestIntegratorMenu.MenuSelectedFcn = createCallbackFcn(app, @determineFastestIntegratorMenu_Callback, true);
            app.determineFastestIntegratorMenu.Text = 'Determine Fastest Integrator';
            app.determineFastestIntegratorMenu.Tag = 'determineFastestIntegratorMenu';
            % Assign app.integratorContextMenu
            app.integratorCombo.ContextMenu = app.integratorContextMenu;
            app.initialStepSizeContextMenu = uicontextmenu(app.lvd_editEventGUI);
            app.initialStepSizeContextMenu.Tag = 'initialStepSizeContextMenu';
            app.optimizeInitialStepSizeMenu = uimenu(app.initialStepSizeContextMenu);
            app.optimizeInitialStepSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @optimizeInitialStepSizeMenu_Callback, true);
            app.optimizeInitialStepSizeMenu.Text = 'Optimize Initial Step Size';
            app.optimizeInitialStepSizeMenu.Tag = 'optimizeInitialStepSizeMenu';
            % Show the figure after all components are created
            app.lvd_editEventGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_editEventGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_editEventGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_editEventGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_editEventGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_editEventGUI)
        end
    end
end