classdef lvd_GraphicalAnalysisGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_GraphicalAnalysisGUI      matlab.ui.Figure
        genPlotsButton                matlab.ui.control.Button
        uipanel1                      matlab.ui.container.Panel
        IndependentVariablePanel      matlab.ui.container.Panel
        text26                        matlab.ui.control.Label
        indepVarTimeUnit              matlab.ui.control.DropDown
        text2                         matlab.ui.control.Label
        indepVarCombo                 matlab.ui.control.DropDown
        TimeIntervalPanel             matlab.ui.container.Panel
        text19                        matlab.ui.control.Label
        text18                        matlab.ui.control.Label
        endTimeText                   matlab.ui.control.EditField
        text17                        matlab.ui.control.Label
        startTimeText                 matlab.ui.control.EditField
        text16                        matlab.ui.control.Label
        TabularOutputPanel            matlab.ui.container.Panel
        generateTextOutputCheckbox    matlab.ui.control.CheckBox
        uipanel2                      matlab.ui.container.Panel
        useEvtLineColorsCheckbox      matlab.ui.control.CheckBox
        markerLinesButton             matlab.ui.control.Button
        showApoCheckbox               matlab.ui.control.CheckBox
        showPeriCheckbox              matlab.ui.control.CheckBox
        showSoITransCheckbox          matlab.ui.control.CheckBox
        showEventTerminationCheckbox  matlab.ui.control.CheckBox
        showGridCheckboxGA            matlab.ui.control.CheckBox
        text23                        matlab.ui.control.Label
        lineSpecCombo                 matlab.ui.control.DropDown
        text7                         matlab.ui.control.Label
        backgndColorCombo             matlab.ui.control.DropDown
        text6                         matlab.ui.control.Label
        lineColorCombo                matlab.ui.control.DropDown
        text5                         matlab.ui.control.Label
        lineWidthText                 matlab.ui.control.EditField
        text4                         matlab.ui.control.Label
        text3                         matlab.ui.control.Label
        subPlotSizeYText              matlab.ui.control.EditField
        subPlotSizeXText              matlab.ui.control.EditField
        useSubplotCheckbox            matlab.ui.control.CheckBox
        text1                         matlab.ui.control.Label
        GraphicalAnalysisTasksPanel   matlab.ui.container.Panel
        uipanel3                      matlab.ui.container.Panel
        SearchTaskText                matlab.ui.control.EditField
        depVarListbox                 matlab.ui.control.ListBox
        TaskReferenceFramePanel       matlab.ui.container.Panel
        currentFrameLabel             matlab.ui.control.Label
        setFrameOptionsButton         matlab.ui.control.Button
        refFrameTypeCombo             matlab.ui.control.DropDown
        FrameTypeDropDownLabel        matlab.ui.control.Label
        RemoveTasksButton             matlab.ui.control.Button
        AddTasksButton                matlab.ui.control.Button
        taskListbox                   matlab.ui.control.ListBox
        inputTimeMenus                matlab.ui.container.ContextMenu
        enterUTAsDateTime             matlab.ui.container.Menu
        setTimeFromScriptMenu         matlab.ui.container.Menu
    end


    methods (Access = private)
        function output_txt = dataTipFormatFunc(~, ~, event_obj)
            % Display the position of the data cursor
            % obj          Currently not used (empty)
            % event_obj    Handle to event object
            % output_txt   Data cursor text string (string or cell array of strings).
            pos = get(event_obj,'Position');
            output_txt = {['X: ',num2str(pos(1),'%12.5f')],...
                ['Y: ',num2str(pos(2),'%12.5f')]};

            % If there is a Z-coordinate in the position, display it as well
            if length(pos) > 2
                output_txt{end+1} = ['Z: ',num2str(pos(3),'%12.5f')];
            end
        end

        function plotData(app, hFig, indepVarValues, data, lineColor, useEvtLineColor, evts, dataEvtNums, lineType, lineWidth, indepVarStr, taskStr, bgColor, indepVarUnitStr, indepTimeUnitMult, depVarUnitStr, maStateLog, startTimeUT, endTimeUT, celBodyData, hGAFig)
            xLineValue = getappdata(app.lvd_GraphicalAnalysisGUI,'xLineValue');
            yLineValue = getappdata(app.lvd_GraphicalAnalysisGUI,'yLineValue');

            hold on;
            for(i=1:length(evts))
                evt = evts(i);
                eventNum = evt.getEventNum();

                bool = dataEvtNums == eventNum;

                subIndVal = indepVarValues(bool,1);
                subData = data(bool);

                switch(evt.plotMethod)
                    case EventPlottingMethodEnum.PlotContinuous
                        %nothing

                    case EventPlottingMethodEnum.SkipFirstState
                        subIndVal = subIndVal(2:end);
                        subData = subData(2:end);

                        %                 maStateLog =

                    case EventPlottingMethodEnum.DoNotPlot
                        maStateLog(maStateLog(:,13) == eventNum,:) = [];

                        continue;

                    otherwise
                        error('Unknown event plotting method: %s', EventPlottingMethodEnum.DoNotPlot.name);
                end

                if(useEvtLineColor)
                    lineColor = evt.colorLineSpec.color.color;
                    plot(subIndVal, subData, 'Color',lineColor, 'LineStyle',lineType, 'LineWidth',lineWidth);
                else
                    plot(subIndVal, subData, 'Color',lineColor, 'LineStyle',lineType, 'LineWidth',lineWidth);
                end
            end

            minData = min(data);
            maxData = max(data);
            if(minData == maxData)
                minData = minData - 0.75;
                maxData = maxData + 0.75;
            end
            onePercData = (maxData-minData)/100;

            if(~isempty(xLineValue))
                if(xLineValue >= min(indepVarValues(:,1)) && xLineValue <= max(indepVarValues(:,1)))
                    if(strcmpi(bgColor,'c'))
                        markerLineColor = 'w';
                    else
                        markerLineColor = 'c';
                    end
                    
                    figChildren = hFig.Children;
                    for(i=1:length(figChildren))
                        figChild = figChildren(i);
                        
                        if(isa(figChild,'matlab.graphics.axis.Axes'))
                            plot(figChild, [xLineValue xLineValue], [minData maxData],markerLineColor,'LineWidth',0.25);
                        end
                    end
                end
            end

            if(~isempty(yLineValue))
                if(yLineValue >= min(data) && yLineValue <= max(data))
                    if(strcmpi(bgColor,'c'))
                        markerLineColor = 'w';
                    else
                        markerLineColor = 'c';
                    end
                    
                    figChildren = hFig.Children;
                    for(i=1:length(figChildren))
                        figChild = figChildren(i);
                        
                        if(isa(figChild,'matlab.graphics.axis.Axes'))
                            plot(figChild, [min(indepVarValues(:,1)) max(indepVarValues(:,1))], [yLineValue yLineValue],markerLineColor,'LineWidth',0.25);
                        end
                    end
                end
            end

            subLog = maStateLog(maStateLog(:,1) >= startTimeUT & maStateLog(:,1) <= endTimeUT,:);
            subLogUnsorted = subLog;
            subLog = sortrows(subLog,1);
            subLog = unique(subLog,'rows');

            %             hShowMan = findobj('Tag','showEventTerminationCheckbox');
            hShowMan = app.showEventTerminationCheckbox;
            if(get(hShowMan,'Value') && strcmpi(get(hShowMan,'Enable'), 'on'))
                if(strcmpi(bgColor,'r'))
                    evtEndLineColor = 'w';
                else
                    evtEndLineColor = 'r';
                end

                allEventNums = subLogUnsorted(:,13);
                x = diff(allEventNums)~=0;
                inds = find(x);
                inds = [inds; length(allEventNums)];

                minData = min(data);
                maxData = max(data);
                if(minData == maxData)
                    minData = minData - 0.75;
                    maxData = maxData + 0.75;
                end

                for(i=1:length(inds))
                    ind = inds(i);

                    eventTime = subLogUnsorted(ind,1)*indepTimeUnitMult;
                    indepVarEventLoc = indepVarValues(indepVarValues(:,2)==eventTime,1);
                    indepVarEventLoc = indepVarEventLoc(1);

                    evtNum = subLogUnsorted(ind,13);

                    plot([indepVarEventLoc indepVarEventLoc], [minData, maxData],evtEndLineColor,'LineWidth',0.25);
                    text(indepVarEventLoc,maxData-8*onePercData,sprintf(' Event %u End ',evtNum),'Color',evtEndLineColor);

                end
            end

            %             hShowSoI = findobj('Tag','showSoITransCheckbox');
            hShowSoI = app.showSoITransCheckbox;
            if(get(hShowSoI,'Value') && strcmpi(get(hShowSoI,'Enable'), 'on'))
                if(strcmpi(bgColor,'m'))
                    soiLineColor = 'w';
                else
                    soiLineColor = 'm';
                end

                allBodyIDs = subLog(:,8);
                x = diff(allBodyIDs)~=0;
                inds = find(x);
                for(i=1:length(inds))
                    ind = inds(i)+1;

                    bodyLog = subLog(ind,:);
                    bodyID = bodyLog(1,8);
                    bodyInfo = getBodyInfoByNumber(bodyID, celBodyData);

                    if(bodyLog(1,1) == startTimeUT)
                        continue;
                    end
                    eventTime = bodyLog(1,1)*indepTimeUnitMult;
                    indepVarEventLoc = indepVarValues(indepVarValues(:,2)==eventTime,1);
                    indepVarEventLoc = indepVarEventLoc(1);

                    minData = min(data);
                    maxData = max(data);
                    if(minData == maxData)
                        minData = minData - 0.75;
                        maxData = maxData + 0.75;
                    end

                    plot([indepVarEventLoc indepVarEventLoc], [minData, maxData],soiLineColor,'LineWidth',0.25);
                    text(indepVarEventLoc,maxData-2*onePercData,[' To ',bodyInfo.name],'Color',soiLineColor);
                end
            end

            %             hShowPeri = findobj('Tag','showPeriCheckbox');
            hShowPeri = app.showPeriCheckbox;
            if(get(hShowPeri,'Value') && strcmpi(get(hShowPeri,'Enable'), 'on'))
                if(strcmpi(bgColor,'g'))
                    periLineColor = 'w';
                else
                    periLineColor = 'g';
                end

                periCnt = 1;
                allBodyIDs = subLogUnsorted(:,8);
                x = diff(allBodyIDs)~=0;
                inds = find(x)+1;
                inds = [1 inds' length(allBodyIDs)+1];
                for(i=1:length(inds)-1)
                    ind = inds(i);
                    nextInd = inds(i+1)-1;

                    bodyLog = subLogUnsorted(ind:nextInd,:);
                    bodyID = bodyLog(1,8);
                    bodyInfo = getBodyInfoByNumber(bodyID, celBodyData);

                    [~, ~, ~, ~, ~, tru] = vect_getKeplerFromState(bodyLog(:,2:4)',bodyLog(:,5:7)',bodyInfo.gm);
                    tru = angleNegPiToPi(tru);

                    for(j=2:length(tru))
                        tru1 = tru(j-1);
                        tru2 = tru(j);

                        %                 if(((tru1 > tru2 && abs(tru1-tru2)>pi) || (tru1 < 0 && tru2 >= 0)) && bodyLog(j-1,8)==bodyLog(j,8))
                        if(((tru1 < 0 && tru2 >= 0)) && bodyLog(j-1,8)==bodyLog(j,8))
                            eventTime = bodyLog(j,1)*indepTimeUnitMult;
                            indepVarEventLoc = indepVarValues(indepVarValues(:,2)==eventTime,1);
                            indepVarEventLoc = indepVarEventLoc(1);

                            minData = min(data);
                            maxData = max(data);
                            if(minData == maxData)
                                minData = minData - 0.75;
                                maxData = maxData + 0.75;
                            end

                            plot([indepVarEventLoc indepVarEventLoc], [minData, maxData],periLineColor,'LineWidth',0.25);
                            text(indepVarEventLoc,maxData-4*onePercData,[' P',num2str(periCnt)],'Color',periLineColor);
                            periCnt = periCnt + 1;
                        end
                    end
                end
            end

            %             hShowApo = findobj('Tag','showApoCheckbox');
            hShowApo = app.showApoCheckbox;
            if(get(hShowApo,'Value') && strcmpi(get(hShowApo,'Enable'), 'on'))
                if(strcmpi(bgColor,'b'))
                    apoLineColor = 'w';
                else
                    apoLineColor = 'b';
                end

                apoCnt = 1;
                allBodyIDs = subLogUnsorted(:,8);
                x = diff(allBodyIDs)~=0;
                inds = find(x)+1;
                inds = [1 inds' length(allBodyIDs)+1];
                for(i=1:length(inds)-1)
                    ind = inds(i);
                    nextInd = inds(i+1)-1;

                    bodyLog = subLogUnsorted(ind:nextInd,:);
                    bodyID = bodyLog(1,8);
                    bodyInfo = getBodyInfoByNumber(bodyID, celBodyData);

                    [~, ~, ~, ~, ~, tru] = vect_getKeplerFromState(bodyLog(:,2:4)',bodyLog(:,5:7)',bodyInfo.gm);

                    for(j=2:length(tru))
                        tru1 = tru(j-1);
                        tru2 = tru(j);

                        if(tru1 < pi && tru2 >= pi && bodyLog(j-1,8)==bodyLog(j,8))
                            eventTime = bodyLog(j,1)*indepTimeUnitMult;
                            indepVarEventLoc = indepVarValues(indepVarValues(:,2)==eventTime,1);
                            indepVarEventLoc = indepVarEventLoc(1);

                            minData = min(data);
                            maxData = max(data);
                            if(minData == maxData)
                                minData = minData - 0.75;
                                maxData = maxData + 0.75;
                            end

                            plot([indepVarEventLoc indepVarEventLoc], [minData, maxData],apoLineColor,'LineWidth',0.25);
                            text(indepVarEventLoc,maxData-6*onePercData,[' A',num2str(apoCnt)],'Color',apoLineColor);
                            apoCnt = apoCnt + 1;
                        end
                    end
                end
            end

            %             hGridOn = findobj('Tag','showGridCheckboxGA');
            hGridOn = app.showGridCheckboxGA;
            if(get(hGridOn,'Value'))
                grid on;
            else
                grid off;
            end

            if(~isempty(indepVarUnitStr))
                indepVarUnitStr = [' [',indepVarUnitStr,']'];
            else
                indepVarUnitStr = '';
            end

            if(~isempty(depVarUnitStr))
                depVarUnitStr = [' [',depVarUnitStr,']'];
            else
                depVarUnitStr = '';
            end

            xlabel([indepVarStr,indepVarUnitStr]);
            ylabel([taskStr,depVarUnitStr]);

            hold off;

            dcmObj = datacursormode;
            set(dcmObj,'UpdateFcn',@app.dataTipFormatFunc,'Enable','on');
        end

        function errMsg = validateInputs(~, handles)
            %     maData = getappdata(handles.ma_MainGUI,'ma_data');
            %     celBodyData = getappdata(handles.ma_MainGUI,'celBodyData');
            lvdData = getappdata(handles.lvd_GraphicalAnalysisGUI,'lvdData');
            %             celBodyData = lvdData.celBodyData;
            stateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(true);

            errMsg = {};

            stateLogMinUT = floor(min(stateLog(:,1))*10000)/10000;
            stateLogMaxUT = ceil(max(stateLog(:,1))*10000)/10000;

            value = str2double(get(handles.startTimeText,'String'));
            enteredStr = get(handles.startTimeText,'String');
            numberName = 'Start Time (UT)';
            lb = stateLogMinUT;
            ub = stateLogMaxUT;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            value = str2double(get(handles.endTimeText,'String'));
            enteredStr = get(handles.endTimeText,'String');
            numberName = 'End Time (UT)';
            lb = stateLogMinUT;
            ub = stateLogMaxUT;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

            if(isempty(errMsg))
                startUTEntered = str2double(get(handles.startTimeText,'String'));

                value = str2double(get(handles.endTimeText,'String'));
                enteredStr = get(handles.endTimeText,'String');
                numberName = 'End Time (UT)';
                lb = startUTEntered;
                ub = stateLogMaxUT;
                isInt = false;
                errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
            end

            if(get(handles.useSubplotCheckbox,'Value'))
                value = str2double(get(handles.subPlotSizeXText,'String'));
                enteredStr = get(handles.subPlotSizeXText,'String');
                numberName = 'Sub Plot X Size';
                lb = 1;
                ub = 10;
                isInt = true;
                errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);

                value = str2double(get(handles.subPlotSizeYText,'String'));
                enteredStr = get(handles.subPlotSizeYText,'String');
                numberName = 'Sub Plot Y Size';
                lb = 1;
                ub = 10;
                isInt = true;
                errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
            end

            value = str2double(get(handles.lineWidthText,'String'));
            enteredStr = get(handles.lineWidthText,'String');
            numberName = 'Line Width';
            lb = 0.25;
            ub = 5;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, errMsg, enteredStr);
        end


        function formatFigureColors(app, hFig, bgColor)
            children = hFig.Children;
            for(i=1:numel(children))
                child = children(i);
                if(isa(child,'matlab.graphics.axis.Axes'))
                    child.Color = bgColor;
                    bgColorRGB = child.Color;

                    if(mean(bgColorRGB) > 0.5)
                        oppoColor = 'k';
                        figBgColor = bgColorRGB * 0.75;
                    else
                        oppoColor = 'w';
                        figBgColor = (1-bgColorRGB) * 0.25;
                    end

                    child.GridColor = oppoColor;
                    child.XColor = oppoColor;
                    child.YColor = oppoColor;
                end
            end

            hFig.Color = figBgColor;
        end

        function updateFrameChange(app)
            lvdData = getappdata(app.lvd_GraphicalAnalysisGUI,'lvdData');
            celBodyData = lvdData.celBodyData;

            selFrameType = app.refFrameTypeCombo.Value;
            refFrameEnum = ReferenceFrameEnum.getEnumForListboxStr(selFrameType);

%             if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
%                 refFrameEnum = newFrame.typeEnum;
%                 app.refFrameTypeCombo.Value = ReferenceFrameEnum.getIndForName(refFrameEnum.name);
%             end
            
            switch refFrameEnum
                case ReferenceFrameEnum.BodyCenteredInertial
                    bodyInfo = getSelectedBodyInfo(app);            
                    
                    newFrame = bodyInfo.getBodyCenteredInertialFrame();
        
                case ReferenceFrameEnum.BodyFixedRotating
                    bodyInfo = getSelectedBodyInfo(app);
                    
                    newFrame = bodyInfo.getBodyFixedFrame();
                    
                case ReferenceFrameEnum.TwoBodyRotating            
                    bodyInfo = getSelectedBodyInfo(app);
                    if(not(isempty(bodyInfo.childrenBodyInfo)))
                        primaryBody = bodyInfo;
                        secondaryBody = bodyInfo.childrenBodyInfo(1);
                    else
                        primaryBody = bodyInfo.getParBodyInfo();
                        secondaryBody = bodyInfo;
                    end
                    
                    originPt = TwoBodyRotatingFrameOriginEnum.Primary;
                    
                    newFrame = TwoBodyRotatingFrame(primaryBody, secondaryBody, originPt, celBodyData);
                    
                case ReferenceFrameEnum.UserDefined
                    numFrames = lvdData.geometry.refFrames.getNumRefFrames();
                    if(numFrames >= 1)
                        geometricFrame = AbstractGeometricRefFrame.empty(1,0);
                        for(i=1:numFrames)
                            frame = lvdData.geometry.refFrames.getRefFrameAtInd(i);
                            if(frame.isVehDependent() == false)
                                geometricFrame = frame;
                                break;
                            end
                        end
    
                        if(not(isempty(geometricFrame)))
                            newFrame = UserDefinedGeometricFrame(geometricFrame, lvdData);
                        else
                            bodyInfo = getSelectedBodyInfo(app);
                            newFrame = bodyInfo.getBodyCenteredInertialFrame();
                            
                            warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                        end
                    else
                        bodyInfo = getSelectedBodyInfo(app);
                        newFrame = bodyInfo.getBodyCenteredInertialFrame();
                        
                        warndlg('There are no geometric frames available which are not dependent on vehicle properties.  A body-centered inertial frame will be selected instead.');
                    end
        
                otherwise
                    error('Unknown reference frame type: %s', string(refFrameEnum));                
            end

            if(not(isempty(newFrame)) && newFrame.typeEnum ~= refFrameEnum)
                refFrameEnum = newFrame.typeEnum;
                app.refFrameTypeCombo.Value = refFrameEnum.name;
            end
            
            setappdata(app.lvd_GraphicalAnalysisGUI,'frame', newFrame);
            app.setFrameOptionsButton.Tooltip = sprintf('Current Frame: %s', newFrame.getNameStr());
            app.currentFrameLabel.Text = sprintf('%s', newFrame.getNameStr());
            app.currentFrameLabel.Tooltip = sprintf('%s', newFrame.getNameStr());
        end

        function bodyInfo = getSelectedBodyInfo(app)
            curFrame = getappdata(app.lvd_GraphicalAnalysisGUI,'frame');
            bodyInfo = curFrame.getOriginBody();
        end
        
        function setRemoveTaskButtonEnable(app)
            lvdData = getappdata(app.lvd_GraphicalAnalysisGUI,'lvdData');
            [~, tasks] = lvdData.graphAnalysis.getListBoxStr();
            
            if(~isempty(tasks))
                app.RemoveTasksButton.Enable = 'on';
            else
                app.RemoveTasksButton.Enable = 'off';
            end
        end
        
        function addTaskButtonAction(app)
            lvdData = getappdata(app.lvd_GraphicalAnalysisGUI,'lvdData');
            curFrame = getappdata(app.lvd_GraphicalAnalysisGUI,'frame');
            taskStrs = app.depVarListbox.Value;
            
            for(i=1:length(taskStrs))
                taskStr = taskStrs{i};
                lvdData.graphAnalysis.addTask(GraphicalAnalysisTask(taskStr, curFrame));
            end
            
            [listboxStr, tasks] = lvdData.graphAnalysis.getListBoxStr();
            app.taskListbox.Items = listboxStr;
            app.taskListbox.ItemsData = tasks;
            
            setRemoveTaskButtonEnable(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_GraphicalAnalysisGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_GraphicalAnalysisGUI (see VARARGIN)

            % Choose default command line output for lvd_GraphicalAnalysisGUI
            app.lvd_GraphicalAnalysisGUI.Visible = 'off';
            handles.output = hObject;

            % Set up GUI
            centerUIFigure(app.lvd_GraphicalAnalysisGUI);
            
            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            handles.ma_LvdMainGUI = varargin{2};

            taskList = lvd_getGraphAnalysisTaskList(lvdData, getLvdGAExcludeList());
            set(handles.depVarListbox,'String',taskList);

            [~, tasks] = lvdData.graphAnalysis.getListBoxStr();
            for(i=1:length(tasks))
                if(not(ismember(tasks(i).taskStr, taskList)))
                    lvdData.graphAnalysis.removeTask(tasks(i));
                end
            end
            [listboxStr, tasks] = lvdData.graphAnalysis.getListBoxStr();
            
            app.taskListbox.Items = listboxStr;
            app.taskListbox.ItemsData = tasks;
            setRemoveTaskButtonEnable(app);

            frame = LvdData.getDefaultInitialBodyInfo(lvdData.celBodyData).getBodyCenteredInertialFrame();
            setappdata(hObject,'frame',frame);

            app.refFrameTypeCombo.Items = ReferenceFrameEnum.getListBoxStr();
            app.refFrameTypeCombo.Value = frame.typeEnum.name;
            app.setFrameOptionsButton.Tooltip = sprintf('Current Frame: %s', frame.getNameStr());
            app.currentFrameLabel.Text = sprintf('%s', frame.getNameStr());
            app.currentFrameLabel.Tooltip = sprintf('%s', frame.getNameStr());            

            propNames = lvdData.launchVehicle.tankTypes.getFirstThreeTypesCellArr();
            substituteDefaultPropNamesWithCustomNamesInDepVarListbox(handles.depVarListbox, propNames);

            evt =  matlab.ui.eventdata.ValueChangedData(0, 0);
            useSubplotCheckbox_Callback(app, evt);

            [tStart, tEnd] = lvdData.stateLog.getStartAndEndTimes();
            set(handles.startTimeText,'String',fullAccNum2Str(tStart));
            set(handles.endTimeText,'String',fullAccNum2Str(tEnd));

            evt = matlab.ui.eventdata.ValueChangedData(0, 0);
            indepVarCombo_Callback(app, evt);

            setappdata(hObject,'xLineValue',[]);
            setappdata(hObject,'yLineValue',[]);

            % Update handles structure
            guidata(hObject, handles);
            app.lvd_GraphicalAnalysisGUI.Visible = 'on';
        end

        % Value changed function: endTimeText
        function endTimeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to endTimeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of endTimeText as text
            %        str2double(get(hObject,'String')) returns contents of endTimeText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Menu selected function: enterUTAsDateTime
        function enterUTAsDateTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to enterUTAsDateTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             secUT = enterUTAsDateTimeGUI(str2double(get(gco, 'Value')));

            output2 = AppDesignerGUIOutput({-1});
            enterUTAsDateTimeGUI_App(str2double(get(gco, 'Value')), output2);
            secUT = output2.output{1};

            if(secUT >= 0)
                set(gco, 'Value', num2str(secUT));
            end
        end

        % Button pushed function: genPlotsButton
        function genPlotsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to genPlotsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            errMsg = validateInputs(app, handles);

            if(~isempty(errMsg))
                msgbox(errMsg,'Error','error');
                return;
            end

            lvdData = getappdata(handles.lvd_GraphicalAnalysisGUI,'lvdData');
            celBodyData = lvdData.celBodyData;
            maStateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(true);

            if(lvdData.graphAnalysis.getNumTasks() <= 0)
                errordlg('Error: No valid Graphical Analysis Tasks.  Add a task first.');
                return;
            end

            startTimeUT = str2double(get(handles.startTimeText,'String'));
            endTimeUT = str2double(get(handles.endTimeText,'String'));

            lineWidth = str2double(get(handles.lineWidthText,'String'));

            contents = cellstr(get(handles.lineColorCombo,'String'));
            lineColorStr = contents{get(handles.lineColorCombo,'Value')};
            lineColor = getLineSpecColorFromString(lineColorStr);

            contents = cellstr(get(handles.backgndColorCombo,'String'));
            bgColorStr = contents{get(handles.backgndColorCombo,'Value')};
            bgColor = getLineSpecColorFromString(bgColorStr);

            contents = cellstr(get(handles.lineSpecCombo,'String'));
            lineTypeStr = contents{get(handles.lineSpecCombo,'Value')};
            switch lineTypeStr
                case 'Solid Line'
                    lineType = '-';
                case 'Dashed Line'
                    lineType = '--';
                case 'Dotted Line'
                    lineType = ':';
                case 'Dash-dot Line'
                    lineType = '-.';
                otherwise
                    lineType = '-';
            end

%             propNames = lvdData.launchVehicle.tankTypes.getFirstThreeTypesCellArr();

            lvdSubLog = lvdData.stateLog.getStateLogEntriesBetweenTimes(startTimeUT, endTimeUT);
            maSubLog = maStateLog(maStateLog(:,1) >= startTimeUT & maStateLog(:,1) <= endTimeUT,:);

            contentsIndep = cellstr(get(handles.indepVarCombo,'String'));
            indepVarValues = zeros(size(maSubLog,1), 2);
            indepVarStr = deblank(contentsIndep{get(handles.indepVarCombo,'Value')});

            contentsIndepUnit = cellstr(get(handles.indepVarTimeUnit,'String'));
            indepVarUnitStr = deblank(contentsIndepUnit{get(handles.indepVarTimeUnit,'Value')});

            [secInMin, secInHr, secInDay, secInYear] = getSecondsInVariousTimeUnits();
            switch indepVarUnitStr
                case 'Seconds'
                    indepTimeUnit = 'sec';
                    indepTimeUnitMult = 1;
                case 'Minutes'
                    indepTimeUnit = 'min';
                    indepTimeUnitMult = 1/secInMin;
                case 'Hours'
                    indepTimeUnit = 'hr';
                    indepTimeUnitMult = 1/secInHr;
                case 'Days'
                    indepTimeUnit = 'day';
                    indepTimeUnitMult = 1/secInDay;
                case 'Years'
                    indepTimeUnit = 'year';
                    indepTimeUnitMult = 1/secInYear;
            end

            switch indepVarStr
                case 'Time'
                    indepVarValues = indepTimeUnitMult*[maSubLog(:,1), maSubLog(:,1)];
                    indepVarUnits = indepTimeUnit;

                case 'Mission Elapsed Time'
                    indepVarValues = indepTimeUnitMult*[maSubLog(:,1) - maStateLog(1,1), maSubLog(:,1)];
                    indepVarUnits = indepTimeUnit;

                case 'True Anomaly'
                    for(i=1:size(maSubLog,1))
                        indepVarValues(i,:) = [ma_GAKeplerElementsTask(maSubLog(i,:), 'tru', celBodyData), maSubLog(i,1)];
                    end
                    indepVarUnits = 'deg';

                case 'Longitude'
                    for(i=1:size(maSubLog,1))
                        indepVarValues(i,:) = [ma_GALongLatAltTasks(maSubLog(i,:), 'long', celBodyData), maSubLog(i,1)];
                    end
                    indepVarUnits = 'degE';

                case 'Altitude'
                    for(i=1:size(maSubLog,1))
                        indepVarValues(i,:) = [ma_GALongLatAltTasks(maSubLog(i,:), 'alt', celBodyData), maSubLog(i,1)];
                    end
                    indepVarUnits = 'km';
            end

            %             hWaitBar = waitbar(0,'Computing Dependent Variables...','WindowStyle','modal');
%             hWaitBar = uiprogressdlg(app.lvd_GraphicalAnalysisGUI,'Value',0, 'Message','Computing Dependent Variables...', 'Title','Computing Dependent Variables');
%             depVarValues = zeros(size(maSubLog,1), length(taskInds));
%             depVarUnits = cell(1,length(taskInds));
            contentsDep = strtrim(cellstr(get(handles.depVarListbox,'String')));
%             prevDistTraveled = 0;

%             hRefBodyCombo = handles.refBodyCombo;
%             contents = cellstr(get(hRefBodyCombo,'String'));
%             refBodyStr = contents{get(hRefBodyCombo,'Value')};
%             refBodyInfo = celBodyData.(strtrim(lower(refBodyStr)));
%             refBodyId = refBodyInfo.id;

            otherSCId = [];
            stationID = [];

            [depVarValues, depVarUnits, dataEvtNums] = lvdData.graphAnalysis.executeTasks(app.lvd_GraphicalAnalysisGUI, startTimeUT, endTimeUT, otherSCId, stationID);
            
%             dataEvtNums = [];
%             maTaskList = ma_getGraphAnalysisTaskList({});
%             for(i=1:size(maSubLog,1))
%                 for(j=1:length(taskInds))
%                     taskInd = taskInds(j);
%                     taskStr = contentsDep{taskInd};
% 
%                     if(isvalid(hWaitBar))
%                         %                         waitbar(i/size(maSubLog,1), hWaitBar, sprintf('Computing Dependent Variables...\n[%u of %u]', i, size(maSubLog,1)));
%                         hWaitBar.Value = i/size(maSubLog,1);
%                         hWaitBar.Message = sprintf('Computing Dependent Variables...\n[%u of %u]', i, size(maSubLog,1));
%                     end
% 
%                     if(ismember(taskStr,maTaskList))
%                         [depVarValues(i,j), depVarUnits{j}, prevDistTraveled] = ma_getDepVarValueUnit(i, maSubLog, taskStr, prevDistTraveled, refBodyId, otherSCId, stationID, propNames, [], celBodyData, false);
%                     else
%                         [depVarValues(i,j), depVarUnits{j}] = lvd_getDepVarValueUnit(i, lvdSubLog, taskStr, refBodyId, celBodyData, false);
%                     end
%                 end
% 
%                 dataEvtNums(i) = maSubLog(i,13);
%             end
%             close(hWaitBar);

            plotMethods = [lvdData.script.evts.plotMethod];
            if(any(plotMethods == EventPlottingMethodEnum.SkipFirstState))
                evtIndsToInsertNaNBetween = find(plotMethods == EventPlottingMethodEnum.SkipFirstState);

                stateInds = [];
                for(i=1:length(evtIndsToInsertNaNBetween))
                    evtInd = evtIndsToInsertNaNBetween(i);
                    stateInd = find(maSubLog(:,13) == evtInd,1,'first');
                    if(stateInd > 1)
                        stateInds(end+1) = stateInd;
                    end
                end

                numIndepVars = size(indepVarValues,2);
                numDepVars = size(depVarValues,2);
                for(i=1:length(stateInds))
                    indepVarValues = [indepVarValues(1:stateInds(i)-1,:); NaN(1,numIndepVars); indepVarValues(stateInds(i)+1:end,:)];
                    depVarValues = [depVarValues(1:stateInds(i)-1,:); NaN(1,numDepVars); depVarValues(stateInds(i)+1:end,:)];

                    %             if(i < length(stateInds))
                    %                 stateInds(i+1:end) = stateInds(i+1:end)+1;  %accounts for the fact that we're inserting data
                    %             end
                end
            end

            useEvtLineColor = logical(handles.useEvtLineColorsCheckbox.Value);
            evts = [lvdData.script.evts];

            tasks = lvdData.graphAnalysis.getTasks();
            
            figNum = 100;
            if(get(handles.useSubplotCheckbox,'Value'))
                subPlotMaxX = str2double(get(handles.subPlotSizeXText,'String'));
                subPlotMaxY = str2double(get(handles.subPlotSizeYText,'String'));

                hFig = figure(figNum);
                plotNum = 0;
                for(i = 1:length(tasks))
                    plotNum = plotNum  +1;
                    if(plotNum > subPlotMaxX*subPlotMaxY)
                        figNum = figNum + 1;
                        hFig = figure(figNum);
                        plotNum = 1;
                    end

                    data = depVarValues(:,i); 
                    
                    taskStr = tasks(i).getAxisLabel();

                    subplot(subPlotMaxX,subPlotMaxY,plotNum);
                    plotData(app, hFig, indepVarValues, data, lineColor, useEvtLineColor, evts, dataEvtNums, lineType, lineWidth, indepVarStr, taskStr, bgColor, indepVarUnits, indepTimeUnitMult, depVarUnits{i}, maStateLog, startTimeUT, endTimeUT, celBodyData, handles.lvd_GraphicalAnalysisGUI);
                    formatFigureColors(app, hFig, bgColor);
                end
                
            else
                for(i = 1:length(tasks)) %#ok<*NO4LP>
                    data = depVarValues(:,i);

                    taskStr = tasks(i).getAxisLabel();

                    hFig = figure(figNum+i);
                    plotData(app, hFig, indepVarValues, data, lineColor, useEvtLineColor, evts, dataEvtNums, lineType, lineWidth, indepVarStr, taskStr, bgColor, indepVarUnits, indepTimeUnitMult, depVarUnits{i}, maStateLog, startTimeUT, endTimeUT, celBodyData, handles.lvd_GraphicalAnalysisGUI);
                    formatFigureColors(app, hFig, bgColor);
                end
            end

            if(get(handles.generateTextOutputCheckbox,'Value'))
                matSave = getappdata(handles.ma_LvdMainGUI,'current_save_location');
                [pathstr,name,~] = fileparts(matSave);
                if(isempty(pathstr))
                    pathstr = uigetdir(pwd,'Select folder to save tabular text output');
                    name = 'UntitledMission';
                end
                csvFilename = [pathstr,'\',name,'_GraphicalAnalysis_',datestr(now,'yyyymmdd_HHMMSS'),'.csv'];

                indepVarUnitStr = indepVarUnits;
                if(~isempty(indepVarUnitStr))
                    indepVarUnitStr = [' [',indepVarUnitStr,']'];
                else
                    indepVarUnitStr = '';
                end

                indepVarWithUnit = sprintf('"%s"',[indepVarStr,indepVarUnitStr]);
                taskStrs = [indepVarWithUnit,','];
                for(i = 1:length(tasks)) %#ok<*NO4LP>
                    taskStr = tasks(i).getListBoxStr();

                    depVarUnitStr = depVarUnits{i};
                    if(~isempty(depVarUnitStr))
                        depVarUnitStr = [' [',depVarUnitStr,']']; %#ok<*AGROW>
                    else
                        depVarUnitStr = '';
                    end

                    taskStr = sprintf('"%s"',[taskStr,depVarUnitStr]);

                    taskStrs = [taskStrs,taskStr,','];
                end
                taskStrs = [taskStrs,'\n'];
                if(pathstr ~= 0)
                    fid = fopen(csvFilename,'wt+');
                    fprintf(fid,taskStrs);
                    fclose(fid);

                    M = [indepVarValues(:,1),depVarValues];
                    dlmwrite(csvFilename, M, '-append','precision', 15);

                    msgbox(sprintf('Tabular output written to file: \n%s',csvFilename), 'Tabular Output', 'help');
                end
            end
        end

        % Value changed function: indepVarCombo
        function indepVarCombo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = app.indepVarCombo;

            % hObject    handle to indepVarCombo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            selected = get(hObject,'Value');

            if(strcmpi(selected,'Time') || strcmpi(selected,'Mission Elapsed Time'))
                set(handles.showEventTerminationCheckbox,'Enable','on');
                %         set(handles.showSoITransCheckbox,'Enable','on');
                set(handles.showPeriCheckbox,'Enable','on');
                set(handles.showApoCheckbox,'Enable','on');
            else
                set(handles.showEventTerminationCheckbox,'Enable','off');
                %         set(handles.showSoITransCheckbox,'Enable','off');
                set(handles.showPeriCheckbox,'Enable','off');
                set(handles.showApoCheckbox,'Enable','off');
            end
        end

        % Window key press function: lvd_GraphicalAnalysisGUI
        function lvd_GraphicalAnalysisGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_GraphicalAnalysisGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'escape'
                    close(handles.lvd_GraphicalAnalysisGUI);
            end
        end

        % Button pushed function: markerLinesButton
        function markerLinesButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to markerLinesButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            xLineValue = getappdata(handles.lvd_GraphicalAnalysisGUI,'xLineValue');
            yLineValue = getappdata(handles.lvd_GraphicalAnalysisGUI,'yLineValue');

            defAns = cell(1,2);
            if(~isempty(xLineValue))
                defAns{1} = num2str(xLineValue);
            else
                defAns{1} = '';
            end
            if(~isempty(yLineValue))
                defAns{2} = num2str(yLineValue);
            else
                defAns{2} = '';
            end

            answer = inputdlg({'Draw a vertical line at X=? (Blank to clear)';
                'Draw a horizontal line at Y=? (Blank to clear)'},...
                'Marker Lines',...
                1,...
                defAns);
            answer = strtrim(answer);
            if(isempty(answer))
                return;
            end

            newX = str2double(answer{1});
            if(isnan(newX))
                newXStr = [];
            else
                newXStr = newX;
            end

            newY = str2double(answer{2});
            if(isnan(newY))
                newYStr = [];
            else
                newYStr = newY;
            end

            setappdata(handles.lvd_GraphicalAnalysisGUI,'xLineValue',newXStr);
            setappdata(handles.lvd_GraphicalAnalysisGUI,'yLineValue',newYStr);
        end

        % Menu selected function: setTimeFromScriptMenu
        function setTimeFromScriptMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setTimeFromScriptMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_GraphicalAnalysisGUI,'lvdData');
            stateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(false);

            h = gco;
            if(strcmpi(h.Tag,'startTimeText'))
                set(gco,'Value', num2str(min(stateLog(:,1)), '%1.15f'));
            elseif(strcmpi(h.Tag,'endTimeText'))
                set(gco,'Value', num2str(max(stateLog(:,1)), '%1.15f'));
            else
                return;
            end
        end

        % Value changed function: startTimeText
        function startTimeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to startTimeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of startTimeText as text
            %        str2double(get(hObject,'String')) returns contents of startTimeText as a double
            newInput = get(hObject,'String');
            newInput = attemptStrEval(newInput);
            set(hObject,'String', newInput);
        end

        % Value changed function: useEvtLineColorsCheckbox
        function useEvtLineColorsCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to useEvtLineColorsCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of useEvtLineColorsCheckbox
            if(hObject.Value)
                handles.lineColorCombo.Enable = 'off';
            else
                handles.lineColorCombo.Enable = 'on';
            end
        end

        % Value changed function: useSubplotCheckbox
        function useSubplotCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = app.useSubplotCheckbox;

            % hObject    handle to useSubplotCheckbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of useSubplotCheckbox
            if(get(hObject,'Value'))
                set(handles.subPlotSizeXText,'Enable','on');
                set(handles.subPlotSizeYText,'Enable','on');
            else
                set(handles.subPlotSizeXText,'Enable','off');
                set(handles.subPlotSizeYText,'Enable','off');
            end
        end

        % Value changed function: refFrameTypeCombo
        function refFrameTypeComboValueChanged(app, event)
            updateFrameChange(app);
        end

        % Button pushed function: AddTasksButton
        function AddTasksButtonPushed(app, event)
            app.addTaskButtonAction();
        end

        % Button pushed function: RemoveTasksButton
        function RemoveTasksButtonPushed(app, event)
            lvdData = getappdata(app.lvd_GraphicalAnalysisGUI,'lvdData');
            
            [~, tasks] = lvdData.graphAnalysis.getListBoxStr();
            if(~isempty(tasks))
                task = app.taskListbox.Value;
                taskInd = find(task == tasks,1,'first');
                lvdData.graphAnalysis.removeTask(task);
                
                [listboxStr, tasks] = lvdData.graphAnalysis.getListBoxStr();
                app.taskListbox.Items = listboxStr;
                app.taskListbox.ItemsData = tasks;
                
                if(length(tasks) >= 1 && not(isempty(taskInd)))
                    if(taskInd >= 1 && taskInd <= length(tasks))
                        app.taskListbox.Value = tasks(taskInd);
                    elseif(taskInd > length(tasks))
                        app.taskListbox.Value = tasks(end);
                    end
                end
                
                setRemoveTaskButtonEnable(app);
            end
        end

        % Button pushed function: setFrameOptionsButton
        function setFrameOptionsButtonPushed(app, event)
            curFrame = getappdata(app.lvd_GraphicalAnalysisGUI,'frame');
            curFrame = curFrame.editFrameDialogUI(EditReferenceFrameContextEnum.ForState);
            setappdata(app.lvd_GraphicalAnalysisGUI,'frame',curFrame);
            app.setFrameOptionsButton.Tooltip = sprintf('Current Frame: %s', curFrame.getNameStr());
            app.currentFrameLabel.Text = sprintf('%s', curFrame.getNameStr());
            app.currentFrameLabel.Tooltip = sprintf('%s', curFrame.getNameStr());
        end

        % Value changing function: SearchTaskText
        function SearchTaskTextValueChanging(app, event)
            lvdData = getappdata(app.lvd_GraphicalAnalysisGUI,'lvdData');
            
            changingValue = strtrim(event.Value);
            fullTaskList = lvd_getGraphAnalysisTaskList(lvdData, getLvdGAExcludeList());
            
            if(isempty(changingValue))
                taskList = fullTaskList;
            else
                k = strfind(lower(fullTaskList), lower(changingValue));
                tasksWithStrInds = not(cellfun(@isempty, k));
                taskList = fullTaskList(tasksWithStrInds);
            end
            
            app.depVarListbox.Items = taskList;
            
        end

        % Value changed function: SearchTaskText
        function SearchTaskTextValueChanged(app, event)

        end

        % Window button down function: lvd_GraphicalAnalysisGUI
        function lvd_GraphicalAnalysisGUIWindowButtonDown(app, event)
            selType = app.lvd_GraphicalAnalysisGUI.SelectionType;
            curObj = app.lvd_GraphicalAnalysisGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.depVarListbox)
                    app.addTaskButtonAction();
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_GraphicalAnalysisGUI = uifigure('Visible', 'off');
            colormap(app.lvd_GraphicalAnalysisGUI, 'parula');
            app.lvd_GraphicalAnalysisGUI.Name = 'Graphical Analysis';
            app.lvd_GraphicalAnalysisGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_GraphicalAnalysisGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_GraphicalAnalysisGUIWindowButtonDown, true);
            app.lvd_GraphicalAnalysisGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_GraphicalAnalysisGUI_WindowKeyPressFcn, true);
            app.lvd_GraphicalAnalysisGUI.Tag = 'lvd_GraphicalAnalysisGUI';
            app.GraphicalAnalysisTasksPanel = uipanel(app.lvd_GraphicalAnalysisGUI);
            app.GraphicalAnalysisTasksPanel.Title = 'Graphical Analysis Tasks';
            app.taskListbox = uilistbox(app.GraphicalAnalysisTasksPanel);
            app.taskListbox.Items = {};
            app.taskListbox.Multiselect = 'on';
            app.taskListbox.Value = {};
            app.AddTasksButton = uibutton(app.GraphicalAnalysisTasksPanel, 'push');
            app.AddTasksButton.ButtonPushedFcn = createCallbackFcn(app, @AddTasksButtonPushed, true);
            app.AddTasksButton.Icon = 'add.png';
            app.AddTasksButton.Tooltip = {'Creates tasks from the selected "available tasks" using the selected Task Reference Frame.'};
            app.AddTasksButton.Text = 'Add Task(s)';
            app.RemoveTasksButton = uibutton(app.GraphicalAnalysisTasksPanel, 'push');
            app.RemoveTasksButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveTasksButtonPushed, true);
            app.RemoveTasksButton.Icon = 'remove.png';
            app.RemoveTasksButton.Tooltip = {'Removes the selected tasks from the list.'};
            app.RemoveTasksButton.Text = 'Remove Task(s)';
            app.TaskReferenceFramePanel = uipanel(app.GraphicalAnalysisTasksPanel);
            app.TaskReferenceFramePanel.Title = 'Task Reference Frame';
            app.FrameTypeDropDownLabel = uilabel(app.TaskReferenceFramePanel);
            app.FrameTypeDropDownLabel.Text = 'Frame Type';
            app.refFrameTypeCombo = uidropdown(app.TaskReferenceFramePanel);
            app.refFrameTypeCombo.ValueChangedFcn = createCallbackFcn(app, @refFrameTypeComboValueChanged, true);
            app.refFrameTypeCombo.Tooltip = {'Tasks will be evaluated within their selected reference frame.'};
            app.setFrameOptionsButton = uibutton(app.TaskReferenceFramePanel, 'push');
            app.setFrameOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @setFrameOptionsButtonPushed, true);
            app.setFrameOptionsButton.Icon = 'reference_frame.png';
            app.setFrameOptionsButton.Text = 'Set Frame Options';
            app.currentFrameLabel = uilabel(app.TaskReferenceFramePanel);
            app.currentFrameLabel.Text = 'CURRENT FRAME';
            app.uipanel3 = uipanel(app.GraphicalAnalysisTasksPanel);
            app.uipanel3.Title = 'Available Tasks';
            app.uipanel3.Tag = 'uipanel3';
            app.depVarListbox = uilistbox(app.uipanel3);
            app.depVarListbox.Items = {};
            app.depVarListbox.Multiselect = 'on';
            app.depVarListbox.Tag = 'depVarListbox';
            app.depVarListbox.Value = {};
            app.SearchTaskText = uieditfield(app.uipanel3, 'text');
            app.SearchTaskText.ValueChangedFcn = createCallbackFcn(app, @SearchTaskTextValueChanged, true);
            app.SearchTaskText.ValueChangingFcn = createCallbackFcn(app, @SearchTaskTextValueChanging, true);
            app.SearchTaskText.Tooltip = {'Searches for text in the available task list.'};
            app.text1 = uilabel(app.lvd_GraphicalAnalysisGUI);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Graphical Analysis';
            app.uipanel1 = uipanel(app.lvd_GraphicalAnalysisGUI);
            app.uipanel1.Title = 'Options';
            app.uipanel1.Tag = 'uipanel1';
            app.uipanel2 = uipanel(app.uipanel1);
            app.uipanel2.Title = 'Plotting';
            app.uipanel2.Tag = 'uipanel2';
            app.useSubplotCheckbox = uicheckbox(app.uipanel2);
            app.useSubplotCheckbox.ValueChangedFcn = createCallbackFcn(app, @useSubplotCheckbox_Callback, true);
            app.useSubplotCheckbox.Tag = 'useSubplotCheckbox';
            app.useSubplotCheckbox.Text = 'Use Subplots';
            app.subPlotSizeXText = uieditfield(app.uipanel2, 'text');
            app.subPlotSizeXText.Tag = 'subPlotSizeXText';
            app.subPlotSizeXText.Value = '3';
            app.subPlotSizeYText = uieditfield(app.uipanel2, 'text');
            app.subPlotSizeYText.Tag = 'subPlotSizeYText';
            app.subPlotSizeYText.Value = '3';
            app.text3 = uilabel(app.uipanel2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'X';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Line Width';
            app.lineWidthText = uieditfield(app.uipanel2, 'text');
            app.lineWidthText.Tag = 'lineWidthText';
            app.lineWidthText.Value = '1.5';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Line Color';
            app.lineColorCombo = uidropdown(app.uipanel2);
            app.lineColorCombo.Items = {'Red', 'Magenta', 'Orange', 'Yellow', 'Green', 'Cyan', 'Blue', 'Black', 'White', 'Pink', 'Brown'};
            app.lineColorCombo.Tag = 'lineColorCombo';
            app.lineColorCombo.Value = 'White';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Background Color';
            app.backgndColorCombo = uidropdown(app.uipanel2);
            app.backgndColorCombo.Items = {'Red', 'Magenta', 'Orange', 'Yellow', 'Green', 'Cyan', 'Blue', 'Black', 'White', 'Pink', 'Brown'};
            app.backgndColorCombo.Tag = 'backgndColorCombo';
            app.backgndColorCombo.Value = 'Black';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Line Type';
            app.lineSpecCombo = uidropdown(app.uipanel2);
            app.lineSpecCombo.Items = {'Solid Line', 'Dashed Line', 'Dotted Line', 'Dash-dot Line'};
            app.lineSpecCombo.Tag = 'lineSpecCombo';
            app.lineSpecCombo.Value = 'Solid Line';
            app.text23 = uilabel(app.uipanel2);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Options';
            app.showGridCheckboxGA = uicheckbox(app.uipanel2);
            app.showGridCheckboxGA.Tag = 'showGridCheckboxGA';
            app.showGridCheckboxGA.Text = 'Show Grid';
            app.showGridCheckboxGA.Value = true;
            app.showEventTerminationCheckbox = uicheckbox(app.uipanel2);
            app.showEventTerminationCheckbox.Tag = 'showEventTerminationCheckbox';
            app.showEventTerminationCheckbox.Text = 'Show Event Ends';
            app.showSoITransCheckbox = uicheckbox(app.uipanel2);
            app.showSoITransCheckbox.Tag = 'showSoITransCheckbox';
            app.showSoITransCheckbox.Text = 'Show SoI Transitions';
            app.showPeriCheckbox = uicheckbox(app.uipanel2);
            app.showPeriCheckbox.Tag = 'showPeriCheckbox';
            app.showPeriCheckbox.Text = 'Show Periapsis Crossings';
            app.showApoCheckbox = uicheckbox(app.uipanel2);
            app.showApoCheckbox.Tag = 'showApoCheckbox';
            app.showApoCheckbox.Text = 'Show Apoapsis Crossings';
            app.markerLinesButton = uibutton(app.uipanel2, 'push');
            app.markerLinesButton.ButtonPushedFcn = createCallbackFcn(app, @markerLinesButton_Callback, true);
            app.markerLinesButton.Tag = 'markerLinesButton';
            app.markerLinesButton.Text = 'Marker Lines';
            app.useEvtLineColorsCheckbox = uicheckbox(app.uipanel2);
            app.useEvtLineColorsCheckbox.ValueChangedFcn = createCallbackFcn(app, @useEvtLineColorsCheckbox_Callback, true);
            app.useEvtLineColorsCheckbox.Tag = 'useEvtLineColorsCheckbox';
            app.useEvtLineColorsCheckbox.Tooltip = 'When this is checked, data will be plotted using the event line color selected.';
            app.useEvtLineColorsCheckbox.Text = 'Use Event Colors';
            app.TabularOutputPanel = uipanel(app.uipanel1);
            app.TabularOutputPanel.Title = 'Tabular Output';
            app.generateTextOutputCheckbox = uicheckbox(app.TabularOutputPanel);
            app.generateTextOutputCheckbox.Tag = 'generateTextOutputCheckbox';
            app.generateTextOutputCheckbox.Text = 'Generate Tabular Text Output';
            app.TimeIntervalPanel = uipanel(app.uipanel1);
            app.TimeIntervalPanel.Title = 'Time Interval';
            app.text16 = uilabel(app.TimeIntervalPanel);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Start Time (UT)';
            app.startTimeText = uieditfield(app.TimeIntervalPanel, 'text');
            app.startTimeText.ValueChangedFcn = createCallbackFcn(app, @startTimeText_Callback, true);
            app.startTimeText.Tag = 'startTimeText';
            app.startTimeText.Value = '0';
            app.text17 = uilabel(app.TimeIntervalPanel);
            app.text17.Tag = 'text17';
            app.text17.Text = 'End Time (UT)';
            app.endTimeText = uieditfield(app.TimeIntervalPanel, 'text');
            app.endTimeText.ValueChangedFcn = createCallbackFcn(app, @endTimeText_Callback, true);
            app.endTimeText.Tag = 'endTimeText';
            app.endTimeText.Value = '0';
            app.text18 = uilabel(app.TimeIntervalPanel);
            app.text18.Tag = 'text18';
            app.text18.Text = 'sec';
            app.text19 = uilabel(app.TimeIntervalPanel);
            app.text19.Tag = 'text19';
            app.text19.Text = 'sec';
            app.IndependentVariablePanel = uipanel(app.uipanel1);
            app.IndependentVariablePanel.Title = 'Independent Variable';
            app.indepVarCombo = uidropdown(app.IndependentVariablePanel);
            app.indepVarCombo.Items = {'Time', 'Mission Elapsed Time', 'True Anomaly', 'Longitude', 'Altitude'};
            app.indepVarCombo.ValueChangedFcn = createCallbackFcn(app, @indepVarCombo_Callback, true);
            app.indepVarCombo.Tag = 'indepVarCombo';
            app.indepVarCombo.Value = 'Mission Elapsed Time';
            app.text2 = uilabel(app.IndependentVariablePanel);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Independent Variable';
            app.indepVarTimeUnit = uidropdown(app.IndependentVariablePanel);
            app.indepVarTimeUnit.Items = {'Seconds', 'Minutes', 'Hours', 'Days', 'Years'};
            app.indepVarTimeUnit.Tag = 'indepVarTimeUnit';
            app.indepVarTimeUnit.Tooltip = 'Sets the unit of the independent variable in the output plots if that variable is a time unit, such as mission elapsed time.  Days and Years are relative to Kerbin/Earth time setting.';
            app.indepVarTimeUnit.Value = 'Seconds';
            app.text26 = uilabel(app.IndependentVariablePanel);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Independent Var. Time Unit';
            app.genPlotsButton = uibutton(app.lvd_GraphicalAnalysisGUI, 'push');
            app.genPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @genPlotsButton_Callback, true);
            app.genPlotsButton.Tag = 'genPlotsButton';
            app.genPlotsButton.Icon = 'line-chart.png';
            app.genPlotsButton.Text = 'Plot Graphical Analysis Tasks';
            app.inputTimeMenus = uicontextmenu(app.lvd_GraphicalAnalysisGUI);
            app.inputTimeMenus.Tag = 'inputTimeMenus';
            app.enterUTAsDateTime = uimenu(app.inputTimeMenus);
            app.enterUTAsDateTime.MenuSelectedFcn = createCallbackFcn(app, @enterUTAsDateTime_Callback, true);
            app.enterUTAsDateTime.Text = 'Enter UT As Date/Time';
            app.enterUTAsDateTime.Tag = 'enterUTAsDateTime';
            app.setTimeFromScriptMenu = uimenu(app.inputTimeMenus);
            app.setTimeFromScriptMenu.MenuSelectedFcn = createCallbackFcn(app, @setTimeFromScriptMenu_Callback, true);
            app.setTimeFromScriptMenu.Text = 'Set Time From Mission Script';
            app.setTimeFromScriptMenu.Tag = 'setTimeFromScriptMenu';
            % Assign app.inputTimeMenus
            app.startTimeText.ContextMenu = app.inputTimeMenus;
            app.endTimeText.ContextMenu = app.inputTimeMenus;
            % Show the figure after all components are created
            app.lvd_GraphicalAnalysisGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_GraphicalAnalysisGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_GraphicalAnalysisGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_GraphicalAnalysisGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_GraphicalAnalysisGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_GraphicalAnalysisGUI)
        end
    end
end