classdef lvd_runCaseMatrix_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RunCaseMatrixUIFigure        matlab.ui.Figure
        GridLayout8                  matlab.ui.container.GridLayout
        Panel_2                      matlab.ui.container.Panel
        GridLayout9                  matlab.ui.container.GridLayout
        RunTimeCounterLabel          matlab.ui.control.Label
        CancelButton                 matlab.ui.control.Button
        StatusLabel                  matlab.ui.control.Label
        SpinnerIcon                  matlab.ui.control.Image
        GridLayout                   matlab.ui.container.GridLayout
        Panel                        matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        RunCaseMatrixButton          matlab.ui.control.Button
        RunParametersPanel           matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        MaxRunAttemptsText           matlab.ui.control.EditField
        MaxRunAttemptsLabel          matlab.ui.control.Label
        NumOfWorkersText             matlab.ui.control.EditField
        NumberofWorkersLabel         matlab.ui.control.Label
        OutputLocationLabel          matlab.ui.control.Label
        RunLocationSelector          wt.FileSelector
        RunStatusPanel               matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        RunStatusTable               matlab.ui.control.Table
        ParameterstoSweepOverPanel   matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        ParameterOptionsPanel        matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        Label                        matlab.ui.control.Label
        SweepOverParametersCheckbox  matlab.ui.control.CheckBox
        StepText                     matlab.ui.control.EditField
        UpperBoundText               matlab.ui.control.EditField
        LowerBoundText               matlab.ui.control.EditField
        StepSizeLabel                matlab.ui.control.Label
        UpperBoundLabel              matlab.ui.control.Label
        LowerBoundLabel              matlab.ui.control.Label
        SweepOverParamLabel          matlab.ui.control.Label
        PluginVarListbox             matlab.ui.control.ListBox
        text2                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        lvdData LvdData
        
        caseMatrix LvdCaseMatrix
        pluginVarDataModels LvdCaseMatrixParameterUIDataModel
        numParWorkers(1,1) double = 1;
        numMaxRunAttempts(1,1) double = 2;
        runTimeTic(1,1) = 0;
    end
    
    methods (Access = private)
        function populateGUI(app)           
            if(not(isempty(app.pluginVarDataModels)))
                for(i=1:length(app.pluginVarDataModels))
                    dm = app.pluginVarDataModels(i);
                    
                    listboxStr{i} = dm.pluginVar.name;
                end
                
                app.PluginVarListbox.Items = listboxStr;
                app.PluginVarListbox.ItemsData = app.pluginVarDataModels;
                
            else %need some plugin vars, at least 1, so close if none
                uialert(app.RunCaseMatrixUIFigure, 'The Case Matrix Runner requires at least one Plugin Variable to work.  No plugin variables found in this mission.  Please create a plugin variable.', 'No Plugin Variables Found', "Icon","error", "Modal",true);
                close(app.RunCaseMatrixUIFigure);
                return;
            end
            
            app.updateParamItemsBySelection();
            
            app.RunLocationSelector.Value = pwd;

            pp = gcp('nocreate');
            if(not(isempty(pp)))
                app.numParWorkers = pp.NumWorkers;
            else
                app.numParWorkers = feature('numCores');
            end
            app.NumOfWorkersText.Value = fullAccNum2Str(app.numParWorkers);

            app.MaxRunAttemptsText.Value = fullAccNum2Str(app.numMaxRunAttempts);

            app.SpinnerIcon.Visible = false;
            app.CancelButton.Visible = false;
            app.StatusLabel.Text = 'Ready';
            app.RunTimeCounterLabel.Text = '';
            app.RunTimeCounterLabel.Visible = false;
        end
        
        function updateParamItemsBySelection(app)
            dm = app.PluginVarListbox.Value;
            
            app.SweepOverParametersCheckbox.Value = dm.useTf;
            app.LowerBoundText.Value = fullAccNum2Str(dm.lowerBnd);
            app.UpperBoundText.Value = fullAccNum2Str(dm.upperBnd);
            app.StepText.Value = fullAccNum2Str(dm.step);
            
            app.handleEnableCheckbox();
        end
        
        function createPluginVarDataModels(app)
            pluginVars = app.lvdData.pluginVars.getPluginVarsArray();
            
            for(i=1:length(pluginVars))
                pluginVar = pluginVars(i);
                [lb, ub] = pluginVar.getBounds();
                
                if(lb < ub)
                    step = (ub-lb)/10;
                else
                    step = 1;
                end
                
                dm = LvdCaseMatrixParameterUIDataModel(pluginVar, false, lb, ub, step);
                app.pluginVarDataModels(i) = dm;
            end
        end
        
        function handleEnableCheckbox(app)
            if(app.SweepOverParametersCheckbox.Value)
                app.LowerBoundText.Enable = true;
                app.UpperBoundText.Enable = true;
                app.StepText.Enable = true;
                
            else
                app.LowerBoundText.Enable = false;
                app.UpperBoundText.Enable = false;
                app.StepText.Enable = false;
            end
        end
        
        function handleTaskStatusChange(app, ~, evt)
            caseNum = evt.task.caseNumber;
            
            sStatus = evt.updatedData;
            oldStatus = sStatus.oldStatus;
            updatedStatus = sStatus.newStatus;
            
            data = app.RunStatusTable.Data;
            caseNums = cell2mat(data(:,1));
            bool = caseNum == caseNums;
            
            statusName = updatedStatus.name;
            data{bool, end-1} = statusName;
            app.RunStatusTable.Data = data;

            s1 = uistyle("Icon", updatedStatus.icon);
            s2 = uistyle("IconAlignment",'leftmargin');

            targetCellRow = find(bool, 1, 'first');
            targetCellCol = width(data) - 1;
            targetCell = [targetCellRow, targetCellCol];

            addStyle(app.RunStatusTable, s1, 'cell', targetCell);
            addStyle(app.RunStatusTable, s2, 'cell', targetCell);

            d = datetime();
            dStr = datestr(d, 'HH:MM:SS PM');
            if(oldStatus ~= updatedStatus)
                statusLabel = sprintf('(%s) Case %u status updated: %s -> %s', dStr, caseNum, oldStatus.name, statusName);
            else
                statusLabel = sprintf('(%s) Case %u status updated: %s', dStr, caseNum, statusName);
            end
            app.StatusLabel.Text = statusLabel;
            
            drawnow;
        end
        
        function handleTaskOutputMessageChange(app, ~, evt)
            caseNum = evt.task.caseNumber;
            updatedMessage = evt.updatedData;
            
            data = app.RunStatusTable.Data;
            caseNums = cell2mat(data(:,1));
            bool = caseNum == caseNums;
            
            data{bool, end} = updatedMessage;
            app.RunStatusTable.Data = data;
            
            drawnow;
        end
        
        function updateRuntimeDurationLabel(app, ~,~)
            t = toc(app.runTimeTic);
            
            s = seconds(t);
            s.Format = "hh:mm:ss";

            app.RunTimeCounterLabel.Text = string(s);
            drawnow;
        end
        
        function genCaseMatrixProgressBarUpdate(app, src, evt, hProg)
            hProg.Value = evt.taskNum / evt.totalTasks;
        end
        
        function runCaseMatrix(app)
            app.caseMatrix.maxNumAttempts = app.numMaxRunAttempts;

            app.ParameterstoSweepOverPanel.Enable = "off";
            app.RunParametersPanel.Enable ="off";
                        
            tableData = app.caseMatrix.getUITableData();
            app.RunStatusTable.Data = tableData;
            app.RunStatusTable.ColumnSortable = true;
            app.RunStatusTable.ColumnWidth = [repmat("fit",1,width(tableData)-1), "auto"];
            app.RunStatusTable.ColumnFormat = repmat({'char'},1,width(tableData));
            
            s1 = uistyle('HorizontalAlignment','center');
            s2 = uistyle("Icon","none");
            addStyle(app.RunStatusTable,s1);
            addStyle(app.RunStatusTable,s2);
            
            tasks = app.caseMatrix.tasks;
            for(i=1:length(tasks)) %#ok<*NO4LP> 
                task = tasks(i);
                
                callback = @(src, evt) app.handleTaskStatusChange(src, evt);
                addlistener(task,'StatusUpdated',callback);
                                
                callback = @(src, evt) app.handleTaskOutputMessageChange(src, evt);
                addlistener(task,'OutputMessageUpdated',callback);
                
                sStatus.oldStatus = task.status;
                sStatus.newStatus = task.status;
                data = LvdCaseMatrixTaskNotifyEventData(task, sStatus);
                app.handleTaskStatusChange(task, data);
            end
            drawnow;
            
            app.SpinnerIcon.Visible = true;
            app.CancelButton.Visible = true;
            app.CancelButton.Enable = true;
            app.RunTimeCounterLabel.Text = '00:00:00';
            app.RunTimeCounterLabel.Visible = true;
            app.runTimeTic = tic;

            app.runTimeTic = tic;
            timerFcn = @(src, evt) app.updateRuntimeDurationLabel(src, evt);
            tmr = timer("TimerFcn",timerFcn, "Period",0.5, "ExecutionMode","fixedRate");
            start(tmr);

            try
                app.caseMatrix.runAllTasks();
            catch ME
                app.RunCaseMatrixButton.Enable = "on";
                
                beep;
                warning(ME.message);
            end

            stop(tmr);

            app.SpinnerIcon.Visible = false;
            app.CancelButton.Visible = false;
            app.CancelButton.Enable = true;
            
            app.RunTimeCounterLabel.Text = '';
            app.RunTimeCounterLabel.Visible = false;
            
            t = toc(app.runTimeTic);
            s = seconds(t);
            s.Format = "hh:mm:ss";
            app.StatusLabel.Text = sprintf('Run finished in %s.  Ready.', string(s));

            app.ParameterstoSweepOverPanel.Enable = "on";
            app.RunParametersPanel.Enable ="on";
            
            app.runTimeTic = 0;

            app.RunCaseMatrixButton.Enable = "on";
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lvdData)
            app.lvdData = lvdData;
            
            centerUIFigure(app.RunCaseMatrixUIFigure);
            applySelectedThemeToApp(app);
            
            app.createPluginVarDataModels();
            app.populateGUI(); 

%             uiwait(app.RunCaseMatrixUIFigure);
        end

        % Value changed function: SweepOverParametersCheckbox
        function SweepOverParametersCheckboxValueChanged(app, event)
            dm = app.PluginVarListbox.Value;
            dm.useTf = app.SweepOverParametersCheckbox.Value;
            
            app.handleEnableCheckbox();
        end

        % Value changed function: LowerBoundText
        function LowerBoundTextValueChanged(app, event)
            newInput = app.LowerBoundText.Value;
            newInput = attemptStrEval(newInput);
            app.LowerBoundText.Value = newInput;
            
            dm = app.PluginVarListbox.Value;
            
            value = str2double(newInput);
            enteredStr = newInput;
            numberName = 'Lower Bound';
            lb = -Inf;
            ub = dm.upperBnd;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, {}, enteredStr);
            
            if(isempty(errMsg))
                dm.lowerBnd = value;
            else
                app.LowerBoundText.Value = fullAccNum2Str(dm.lowerBnd);

                uialert(app.RunCaseMatrixUIFigure,errMsg,'Input Error', 'Icon','error');
            end
        end

        % Value changed function: UpperBoundText
        function UpperBoundTextValueChanged(app, event)
            newInput = app.UpperBoundText.Value;
            newInput = attemptStrEval(newInput);
            app.UpperBoundText.Value = newInput;
            
            dm = app.PluginVarListbox.Value;
            
            value = str2double(newInput);
            enteredStr = newInput;
            numberName = 'Upper Bound';
            lb = dm.lowerBnd;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, {}, enteredStr);
            
            if(isempty(errMsg))
                dm.upperBnd = value;
            else
                app.UpperBoundText.Value = fullAccNum2Str(dm.upperBnd);

                uialert(app.RunCaseMatrixUIFigure,errMsg,'Input Error', 'Icon','error');
            end
        end

        % Value changed function: StepText
        function StepTextValueChanged(app, event)
            newInput = app.StepText.Value;
            newInput = attemptStrEval(newInput);
            app.StepText.Value = newInput;
            
            dm = app.PluginVarListbox.Value;
            
            value = str2double(newInput);
            enteredStr = newInput;
            numberName = 'Step Size';
            lb = 1E-8;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, {}, enteredStr);
            
            if(isempty(errMsg))
                dm.step = value;
            else
                app.StepText.Value = fullAccNum2Str(dm.step);

                uialert(app.RunCaseMatrixUIFigure,errMsg,'Input Error', 'Icon','error');
            end
        end

        % Value changed function: PluginVarListbox
        function PluginVarListboxValueChanged(app, event)
            app.updateParamItemsBySelection();
        end

        % Button pushed function: RunCaseMatrixButton
        function RunCaseMatrixButtonPushed(app, event)
            app.RunCaseMatrixButton.Enable = "off";
            app.StatusLabel.Text = "Building MAT files in output directory, please wait...";
            app.SpinnerIcon.Visible = true;
            drawnow;

            paramsAndRanges = {};
            pluginNames = {};
            for(i=1:length(app.pluginVarDataModels))
                dm = app.pluginVarDataModels(i);
                
                if(dm.useTf)
                    pluginVar = dm.pluginVar;
                    range = unique([dm.lowerBnd : dm.step : dm.upperBnd, dm.upperBnd]);
                    
                    paramsAndRanges(end+1, :) = {pluginVar, range}; %#ok<AGROW> 
                    pluginNames{end+1} = pluginVar.name; %#ok<AGROW> 
                end
            end
            
            if(isempty(paramsAndRanges))
                uialert(app.RunCaseMatrixUIFigure, 'The Case Matrix Runner requires at least one selected plugin variable.  No plugin variables have been selected.  Please select at least one plugin variable to sweep over and set a suitable lower, upper bound, and step size.', 'No Plugin Variables Selected', "Icon","error", "Modal",true);
                app.RunCaseMatrixButton.Enable = "on";

                return;
            end
            
            outputLocation = app.RunLocationSelector.FullPath;
            if(not(isfolder(outputLocation)))
                uialert(app.RunCaseMatrixUIFigure, 'The selected output folder does not exist or is an invalid path.', 'Invalid Output Location', "Icon","error", "Modal",true);
                app.RunCaseMatrixButton.Enable = "on";

                return;
            end
            
            writeOutput = @(varargin) disp('');
            numWorkers = app.numParWorkers;
            startParallelPool(app.RunCaseMatrixUIFigure, writeOutput, numWorkers);
                        
            app.caseMatrix = LvdCaseMatrix(app.lvdData, outputLocation);
            app.caseMatrix.createAllTaskParamCombos(paramsAndRanges)

            app.RunStatusTable.ColumnName = horzcat({'Case'}, pluginNames, {'Status', 'Message'});

            app.runCaseMatrix();
        end

        % Value changed function: NumOfWorkersText
        function NumOfWorkersTextValueChanged(app, event)
            newInput = app.NumOfWorkersText.Value;
            newInput = attemptStrEval(newInput);
            app.NumOfWorkersText.Value = newInput;
            
            value = str2double(newInput);
            enteredStr = newInput;
            numberName = 'Number of Parallel Workers';
            lb = 1;
            ub = 2*feature('numCores');
            isInt = true;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, {}, enteredStr);
            
            if(isempty(errMsg))
                app.numParWorkers = value;
            else
                app.NumOfWorkersText.Value = fullAccNum2Str(app.numParWorkers);

                uialert(app.RunCaseMatrixUIFigure,errMsg,'Input Error', 'Icon','error');
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CancelButton.Icon = "Spin-1s-200px_transparentBg_red.gif";
            app.CancelButton.Enable = false;
            drawnow;

            app.caseMatrix.cancelRun();

            app.CancelButton.Icon = "cancel.png";
            app.CancelButton.Enable = true;

            app.ParameterstoSweepOverPanel.Enable = "on";
            app.RunParametersPanel.Enable ="on";

            drawnow;
        end

        % Value changed function: MaxRunAttemptsText
        function MaxRunAttemptsTextValueChanged(app, event)
            newInput = app.MaxRunAttemptsText.Value;
            newInput = attemptStrEval(newInput);
            app.MaxRunAttemptsText.Value = newInput;
            
            value = str2double(newInput);
            enteredStr = newInput;
            numberName = 'Max Number of Runs Attempts';
            lb = 1;
            ub = Inf;
            isInt = true;
            errMsg = validateNumber(value, numberName, lb, ub, isInt, {}, enteredStr);
            
            if(isempty(errMsg))
                app.numMaxRunAttempts = value;
            else
                app.MaxRunAttemptsText.Value = fullAccNum2Str(app.numMaxRunAttempts);

                uialert(app.RunCaseMatrixUIFigure,errMsg,'Input Error', 'Icon','error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RunCaseMatrixUIFigure = uifigure('Visible', 'off');
            app.RunCaseMatrixUIFigure.Name = 'Run Case Matrix';
            app.RunCaseMatrixUIFigure.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout8 = uigridlayout(app.RunCaseMatrixUIFigure);
            app.GridLayout = uigridlayout(app.GridLayout8);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Run Case Matrix';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.ParameterstoSweepOverPanel = uipanel(app.GridLayout2);
            app.ParameterstoSweepOverPanel.Title = 'Parameters to Sweep Over';
            app.GridLayout4 = uigridlayout(app.ParameterstoSweepOverPanel);
            app.PluginVarListbox = uilistbox(app.GridLayout4);
            app.PluginVarListbox.ValueChangedFcn = createCallbackFcn(app, @PluginVarListboxValueChanged, true);
            app.ParameterOptionsPanel = uipanel(app.GridLayout4);
            app.ParameterOptionsPanel.Title = 'Parameter Options';
            app.GridLayout5 = uigridlayout(app.ParameterOptionsPanel);
            app.SweepOverParamLabel = uilabel(app.GridLayout5);
            app.SweepOverParamLabel.Text = 'Sweep Over Param?';
            app.LowerBoundLabel = uilabel(app.GridLayout5);
            app.LowerBoundLabel.Text = 'Lower Bound';
            app.UpperBoundLabel = uilabel(app.GridLayout5);
            app.UpperBoundLabel.Text = 'Upper Bound';
            app.StepSizeLabel = uilabel(app.GridLayout5);
            app.StepSizeLabel.Text = 'Step Size';
            app.LowerBoundText = uieditfield(app.GridLayout5, 'text');
            app.LowerBoundText.ValueChangedFcn = createCallbackFcn(app, @LowerBoundTextValueChanged, true);
            app.LowerBoundText.Tooltip = {'The lower bound of the range of the selected parameter to sweep over.'};
            app.UpperBoundText = uieditfield(app.GridLayout5, 'text');
            app.UpperBoundText.ValueChangedFcn = createCallbackFcn(app, @UpperBoundTextValueChanged, true);
            app.UpperBoundText.Tooltip = {'The upper bound of the range of the selected parameter to sweep over.'};
            app.StepText = uieditfield(app.GridLayout5, 'text');
            app.StepText.ValueChangedFcn = createCallbackFcn(app, @StepTextValueChanged, true);
            app.StepText.Tooltip = {'The step size of the range of the selected parameter to sweep over. '};
            app.SweepOverParametersCheckbox = uicheckbox(app.GridLayout5);
            app.SweepOverParametersCheckbox.ValueChangedFcn = createCallbackFcn(app, @SweepOverParametersCheckboxValueChanged, true);
            app.SweepOverParametersCheckbox.Tooltip = {'Checking this box turns on the parameter and will have the case matrix runner sweep over it.'};
            app.SweepOverParametersCheckbox.Text = '';
            app.Label = uilabel(app.GridLayout5);
            app.Label.Tooltip = {'Example: '; 'Lower Bound: 0'; 'Upper Bound: 10'; 'Step Size: 3'; ''; 'Range: 0, 3, 6, 9, 10'};
            app.Label.Text = 'The range over which parameters are sweep starts at the lower bound and steps upwards at the step size to the upper bound.  If the upper upper bound is not a multiple of the step size, it will be appended to the end of the range.';
            app.RunStatusPanel = uipanel(app.GridLayout2);
            app.RunStatusPanel.Title = 'Run Status';
            app.GridLayout3 = uigridlayout(app.RunStatusPanel);
            app.RunStatusTable = uitable(app.GridLayout3);
            app.RunStatusTable.ColumnName = {'Case'; 'Parameters'; 'Status'; 'Message'};
            app.RunStatusTable.RowName = {};
            app.RunParametersPanel = uipanel(app.GridLayout2);
            app.RunParametersPanel.Title = 'Run Parameters';
            app.GridLayout7 = uigridlayout(app.RunParametersPanel);
            app.RunLocationSelector = wt.FileSelector(app.GridLayout7);
            app.RunLocationSelector.SelectionType = 'folder';
            app.OutputLocationLabel = uilabel(app.GridLayout7);
            app.OutputLocationLabel.Text = 'Output Location';
            app.NumberofWorkersLabel = uilabel(app.GridLayout7);
            app.NumberofWorkersLabel.Text = 'Number of Workers';
            app.NumOfWorkersText = uieditfield(app.GridLayout7, 'text');
            app.NumOfWorkersText.ValueChangedFcn = createCallbackFcn(app, @NumOfWorkersTextValueChanged, true);
            app.NumOfWorkersText.Tooltip = {'The number of parallel workers to use to run the case matrix.  Should be between 1 and the number of physical cores on your PC.'};
            app.MaxRunAttemptsLabel = uilabel(app.GridLayout7);
            app.MaxRunAttemptsLabel.Text = 'Max Run Attempts';
            app.MaxRunAttemptsText = uieditfield(app.GridLayout7, 'text');
            app.MaxRunAttemptsText.ValueChangedFcn = createCallbackFcn(app, @MaxRunAttemptsTextValueChanged, true);
            app.MaxRunAttemptsText.Tooltip = {'The maximum number of attempts that will be made to successfully complete a case.  Anything over 1 means that failed cases will be rerun.  Failed runs will be rerun after all runs have been attempted at least once.'};
            app.RunCaseMatrixButton = uibutton(app.GridLayout2, 'push');
            app.RunCaseMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @RunCaseMatrixButtonPushed, true);
            app.RunCaseMatrixButton.Icon = 'gear_flow_chart.png';
            app.RunCaseMatrixButton.Text = 'Run Case Matrix';
            app.Panel_2 = uipanel(app.GridLayout8);
            app.GridLayout9 = uigridlayout(app.Panel_2);
            app.SpinnerIcon = uiimage(app.GridLayout9);
            app.SpinnerIcon.ImageSource = 'Spin-1s-200px_transparentBg.gif';
            app.StatusLabel = uilabel(app.GridLayout9);
            app.StatusLabel.Interpreter = 'html';
            app.StatusLabel.Text = 'Ready';
            app.CancelButton = uibutton(app.GridLayout9, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = 'cancel.png';
            app.CancelButton.Tooltip = {'Cancel the current case matrix run.'};
            app.CancelButton.Text = 'Cancel';
            app.RunTimeCounterLabel = uilabel(app.GridLayout9);
            app.RunTimeCounterLabel.Tooltip = {'Current case matrix run time (HH:MM:SS).'};
            app.RunTimeCounterLabel.Text = '00:00:00';
            % Show the figure after all components are created
            app.RunCaseMatrixUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_runCaseMatrix_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RunCaseMatrixUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.RunCaseMatrixUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RunCaseMatrixUIFigure)
        end
    end
end