classdef ma_LvdMainGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ma_LvdMainGUI                  matlab.ui.Figure
        fileMenu                       matlab.ui.container.Menu
        newMissionPlanMenu             matlab.ui.container.Menu
        openMissionPlanMenu            matlab.ui.container.Menu
        saveMissionPlanMenu            matlab.ui.container.Menu
        saveAsMissionPlanMenu          matlab.ui.container.Menu
        exitMenu                       matlab.ui.container.Menu
        openRecentMissionPlansMenu     matlab.ui.container.Menu
        editMenu                       matlab.ui.container.Menu
        undoMenu                       matlab.ui.container.Menu
        redoMenu                       matlab.ui.container.Menu
        editMissionNotesMenu           matlab.ui.container.Menu
        viewMenu                       matlab.ui.container.Menu
        editViewSettingsMenu           matlab.ui.container.Menu
        setActiveViewProfileMenu       matlab.ui.container.Menu
        popOutOrbitDisplayMenu         matlab.ui.container.Menu
        scenarioMenu                   matlab.ui.container.Menu
        editLaunchVehicleMenu          matlab.ui.container.Menu
        editInitialStateMenu           matlab.ui.container.Menu
        editStopwatchesMenu            matlab.ui.container.Menu
        editExtremaMenu                matlab.ui.container.Menu
        editCalculusObjectsMenu        matlab.ui.container.Menu
        editGroundObjsMenu             matlab.ui.container.Menu
        geometryMenu                   matlab.ui.container.Menu
        editPointsMenu                 matlab.ui.container.Menu
        editVectorsMenu                matlab.ui.container.Menu
        editAnglesMenu                 matlab.ui.container.Menu
        editPlanesMenu                 matlab.ui.container.Menu
        editCoordSysMenu               matlab.ui.container.Menu
        editRefRamesMenu               matlab.ui.container.Menu
        simulationMenu                 matlab.ui.container.Menu
        runScriptMenu                  matlab.ui.container.Menu
        createkOSExecCodeMenu          matlab.ui.container.Menu
        optimizationMenu               matlab.ui.container.Menu
        editObjFunctionMenu            matlab.ui.container.Menu
        editConstraintsMenu            matlab.ui.container.Menu
        perturbOptVarsMenu             matlab.ui.container.Menu
        adjustVariablesMenu            matlab.ui.container.Menu
        showConstrJacobianHeatMapMenu  matlab.ui.container.Menu
        optimizeMissionMenu            matlab.ui.container.Menu
        pluginsMenu                    matlab.ui.container.Menu
        managePluginsMenu              matlab.ui.container.Menu
        settingsMenu                   matlab.ui.container.Menu
        integrationSettingsMenu        matlab.ui.container.Menu
        intMinAltitudeMenu             matlab.ui.container.Menu
        intMaxSimTimeMenu              matlab.ui.container.Menu
        maxScriptPropTimeMenu          matlab.ui.container.Menu
        sparseIntegratorOutputMenu     matlab.ui.container.Menu
        setIntegrationStepSizeForAllEventsMenu  matlab.ui.container.Menu
        optimSettingsMenu              matlab.ui.container.Menu
        selectOptimizationAlgosMenu    matlab.ui.container.Menu
        autopropagateMenu              matlab.ui.container.Menu
        displayEvtPropTimesInLogFileMenu  matlab.ui.container.Menu
        configureCelestialBodyStateCacheMenu  matlab.ui.container.Menu
        toolsMenu                      matlab.ui.container.Menu
        astroCalculatorsMenu           matlab.ui.container.Menu
        haloOrbitConstructorMenu       matlab.ui.container.Menu
        graphicalAnalysisMenu          matlab.ui.container.Menu
        celBodyCatalogMenu             matlab.ui.container.Menu
        uitoolbar1                     matlab.ui.container.Toolbar
        newMissionPlanToolbar          matlab.ui.container.toolbar.PushTool
        openMissionPlanToolbar         matlab.ui.container.toolbar.PushTool
        saveMissionPlanToolbar         matlab.ui.container.toolbar.PushTool
        uitoggletool1                  matlab.ui.container.toolbar.ToggleTool
        uitoggletool2                  matlab.ui.container.toolbar.ToggleTool
        uitoggletool3                  matlab.ui.container.toolbar.ToggleTool
        uitoggletool4                  matlab.ui.container.toolbar.ToggleTool
        toggleCameraToolbar            matlab.ui.container.toolbar.ToggleTool
        DispAxesTimeSlider             matlab.ui.control.Slider
        uipanel5                       matlab.ui.container.Panel
        deleteNonSeqEventButton        matlab.ui.control.Button
        nonSeqEventsListbox            matlab.ui.control.ListBox
        insertNonSeqEventButton        matlab.ui.control.Button
        moveEventUp                    matlab.ui.control.Button
        deleteEvent                    matlab.ui.control.Button
        moveEventDown                  matlab.ui.control.Button
        scriptWorkingLbl               matlab.ui.control.Label
        scriptListbox                  matlab.ui.control.ListBox
        insertEventButton              matlab.ui.control.Button
        timeSliderValueLabel           matlab.ui.control.Label
        warningAlertPanel              matlab.ui.container.Panel
        warnAlertsSlider               matlab.ui.control.Slider
        warning6Lbl                    matlab.ui.control.Label
        warning5Lbl                    matlab.ui.control.Label
        warning4Lbl                    matlab.ui.control.Label
        warning3Lbl                    matlab.ui.control.Label
        warning2Lbl                    matlab.ui.control.Label
        warning1Lbl                    matlab.ui.control.Label
        incrOrbitToPlotNum             matlab.ui.control.Button
        decrOrbitToPlotNum             matlab.ui.control.Button
        dispAxisTitleLabel             matlab.ui.control.Label
        finalScStatePanel              matlab.ui.container.Panel
        finalStateReadoutLabel         matlab.ui.control.Label
        initScStatePanel               matlab.ui.container.Panel
        initialStateReadoutLabel       matlab.ui.control.Label
        uipanel1                       matlab.ui.container.Panel
        outputText                     matlab.ui.control.TextArea
        titleText                      matlab.ui.control.Label
        dispAxesPanel                  matlab.ui.container.Panel
        plotWorkingLbl                 matlab.ui.control.Label
        scriptResultsOutOfDateLbl      matlab.ui.control.Label
        dispAxes                       matlab.ui.control.UIAxes
        scriptListboxContextMenu       matlab.ui.container.ContextMenu
        toggleOptimForSelEventMenu     matlab.ui.container.Menu
        viewStateAfterSelectedEventMenu  matlab.ui.container.Menu
        createContConstraintsWithSelEvtMenu  matlab.ui.container.Menu
        copyUtAtStartOfSelEventMenu    matlab.ui.container.Menu
        copyUtAtEndOfSelEventMenu      matlab.ui.container.Menu
        copyDurationOfSelEventMenu     matlab.ui.container.Menu
        copyOrbitAfterSelectedEventMenu  matlab.ui.container.Menu
        advanceScriptToSelectedEventMenu  matlab.ui.container.Menu
        uploadImpDvActionToKspMenu     matlab.ui.container.Menu
        outputContextMenu              matlab.ui.container.ContextMenu
        clearOutputMenu                matlab.ui.container.Menu
    end

    
    methods (Access = private)
        function addUndoState(~, handles, actionName)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                undoRedo = getappdata(handles.ma_LvdMainGUI,'undoRedo');
            
                undoRedo.addState(handles.ma_LvdMainGUI, lvdData, actionName);
        end
        
        function c = computeJacNonlconFunc(~, x, nonlcon)
                [cML, ceqML] = nonlcon(x);
            
                c = [cML(:)', ceqML(:)'];
        end
        
        function enableDisableArrowButtons(~, lvdData, handles)
                type = lvdData.viewSettings.selViewProfile.trajEvtsViewType;
                switch type
                    case ViewEventsTypeEnum.All
                        handles.decrOrbitToPlotNum.Enable = 'off';
                        handles.incrOrbitToPlotNum.Enable = 'off';
            
                    case ViewEventsTypeEnum.SoIChunk
                        handles.decrOrbitToPlotNum.Enable = 'on';
                        handles.incrOrbitToPlotNum.Enable = 'on';
            
                    otherwise
                        error('Unknown event view type: %s', type.name);
                end
        end
        
        function celBodyData = getCelBodyDataFromMainGui(~, handles)
                hKsptotMainGUI = getappdata(handles.ma_LvdMainGUI,'ksptotMainGUI');
            
                mainGUIUserData = get(hKsptotMainGUI, 'UserData');
                celBodyData = mainGUIUserData{1,1};
        end
        
        function gravSystemUpdateCallback(~, ~, ~, handles)
                if(isvalid(handles.ma_LvdMainGUI))
                    lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                    lvdData.celBodyData.resetAllParentNeedsUpdateFlags();
                end
        end
        
        function initializeOutputWindowText(~, handles, hOutputText)
                write_to_output_func = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                set(hOutputText,'String',' ');
                statusBoxMsg = {['KSP TOT Launch Vehicle Designer v', getKSPTOTVersionNumStr(), sprintf(' (R%s)', version('-release'))], 'Written By Arrowstar (C) 2021', ...
                                getLVD_HR(hOutputText)};
            	for(i=1:size(statusBoxMsg,2)) %#ok<ALIGN,*NO4LP>
                    if(i==1)
                        write_to_output_func(statusBoxMsg{i},'overwrite');
                    else
                        write_to_output_func(statusBoxMsg{i},'appendNoDate');
                    end
                end
        end
        
        function integrationAbsTolMenu_Callback(app, ~, ~, handles)
            % hObject    handle to integrationAbsTolMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                input_str = sprintf(['Enter the desired integration absolute error tolerance:\n',...
                                     '(Minimum = 1E-14, Maximum = 1E-2)\n',...
                                     '(This will influence script execution speed.)']);
                str = inputdlg(input_str, 'Absolute Error Tolerance', [1 75], {num2str(lvdData.settings.intAbsTol,'%6.6E')});
                if(isempty(str))
                    return;
                end
            
                str = str{1};
            
                if(checkStrIsNumeric(str) && str2double(str) >= 1E-14 && str2double(str) <= 1E-2)
                    addUndoState(app, handles,'Edit Integrator Abs Tol');
            
                    writeOutput(sprintf('Setting integration absolute error tolerance to %s.', str),'append');
            
                    lvdData.settings.intAbsTol = str2double(str);
            
                    runScript(app, handles, lvdData, 1);
                    lvd_processData(handles);
                else
                    writeOutput(sprintf('Could not set the desired integration absolute error tolerance.  "%s" is an invalid entry.', str),'append');
                    beep;
                end
        end
        
        function integrationRelTolMenu_Callback(app, ~, ~, handles)
            % hObject    handle to integrationRelTolMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                input_str = sprintf(['Enter the desired integration relative error tolerance:\n',...
                                     '(Minimum = 1E-14, Maximum = 1E-2)\n',...
                                     '(This will influence script execution speed.)']);
                str = inputdlg(input_str, 'Relative Error Tolerance', [1 75], {num2str(lvdData.settings.intRelTol,'%6.6E')});
                if(isempty(str))
                    return;
                end
            
                str = str{1};
            
                if(checkStrIsNumeric(str) && str2double(str) >= 1E-14 && str2double(str) <= 1E-2)
                    addUndoState(app, handles,'Edit Integrator Rel Tol');
            
                    writeOutput(sprintf('Setting integration relative error tolerance to %s.', str),'append');
            
                    lvdData.settings.intRelTol = str2double(str);
            
                    runScript(app, handles, lvdData, 1);
                    lvd_processData(handles);
                else
                    writeOutput(sprintf('Could not set the desired integration relative error tolerance.  "%s" is an invalid entry.', str),'append');
                    beep;
                end
        end
        
        function saved = isMissionPlanSaved(~, handles)
                application_title = get(handles.ma_LvdMainGUI,'Name');
                if(application_title(end) == '*')
                    saved = false;
                else
                    saved = true;
                end
        end
        
        function openMissionPlanFile(app, filePath, write_to_output_func, application_title, celBodyData, FileName, handles)
                write_to_output_func(['Loading mission case from "', filePath,'"... '],'append');
            
                hMsg = helpdlg('Loading LVD Case File.  Please wait...','Launch Vehicle Designer');
            
                try
                    load(filePath); %#ok<LOAD>
                catch ME %#ok<NASGU> 
                    write_to_output_func(['There was a problem loading the case file from disk: ',filePath,'.  Case not loaded.'],'append');
            
                    if(isvalid(hMsg))
                        close(hMsg);
                    end
            
                    return;
                end
            
                if(isvalid(hMsg))
                    close(hMsg);
                end
            
                if(exist('lvdData','var'))
                    setappdata(handles.ma_LvdMainGUI,'undoRedo',LVD_UndoRedoStateSet());
            
                    appOptions = getAppOptionsFromFile();
                    recentMsnCaseFilesStr = appOptions.ksptot.lvdrecentmsncasefiles;
                    recentMsnCaseFilesStr = [recentMsnCaseFilesStr,';',filePath];
            
                    cases = strsplit(recentMsnCaseFilesStr,';');
                    cases = cases(~cellfun('isempty',cases));
                    if(numel(cases) > 5)
                        cases = cases(1:5);
                    end
                    cases = unique(cases);
                    recentMsnCaseFilesStr = strjoin(cases,';');
            
                    hKsptotMainGUI = getappdata(handles.ma_LvdMainGUI,'ksptotMainGUI');
                    updateAppOptions(hKsptotMainGUI, 'ksptot', 'lvdrecentmsncasefiles', recentMsnCaseFilesStr);
            
                    topLevelBodyInfo = getTopLevelCentralBody(celBodyData);
                    ldvDataTopLevelBodyInfo = getTopLevelCentralBody(lvdData.celBodyData); %#ok<NODEF> 
                    if(isprop(lvdData,'celBodyData') && ...
                       length(fields(topLevelBodyInfo)) == length(fields(ldvDataTopLevelBodyInfo)))
                        celBodyData = lvdData.celBodyData;
                        setappdata(handles.ma_LvdMainGUI,'celBodyData',celBodyData);
                    else
                        lvdData.celBodyData = celBodyData;
                    end
            
                    if(isprop(lvdData,'celBodyData'))
                        if(not(isa(lvdData.celBodyData,'CelestialBodyData')))
                            lvdData.celBodyData = CelestialBodyData(lvdData.celBodyData);
                        end
            
                        names = fieldnames(lvdData.celBodyData);
                        for(i=1:length(names))
                            name = names{i};
                            lvdData.celBodyData.(name).celBodyData = celBodyData;
                            lvdData.celBodyData.(name).getParBodyInfo(lvdData.celBodyData);
                        end
                    end
            
                    set(handles.ma_LvdMainGUI,'Name',[application_title, ' - ', FileName]);
                    write_to_output_func(['Done.'],'appendSameLine');
            
                    setappdata(handles.ma_LvdMainGUI,'lvdData',lvdData);
                    setappdata(handles.ma_LvdMainGUI,'current_save_location',filePath);
            
                    setDeleteButtonEnable(app, lvdData, handles);
                    setNonSeqDeleteButtonEnable(app, lvdData, handles)
            
                    setappdata(handles.hDispAxesTimeSlider,'lastTime',NaN);
                    timeSliderCb = @(src,evt) timeSliderStateChanged(src,evt, lvdData, handles);
                    set(handles.hDispAxesTimeSlider, 'StateChangedCallback', timeSliderCb);
            
                    enableDisableArrowButtons(app, lvdData, handles);
            
                    if(lvdData.optimizer.usesParallel())
                        numWorkers = lvdData.optimizer.getSelectedOptimizer().getNumParaWorkers();
                        startParallelPool(app, write_to_output_func, numWorkers);
                    end
            
                    propagateScript(app, handles, lvdData, 1);
                    lvd_processData(handles);
            
            
                else
                    write_to_output_func(['There was a problem loading the case file from disk: ',filePath,'.  Case not loaded.'],'append');
                end
        end
        
        function popOutOrbitDispDelFcn(~, ~, ~, inlineDispAxes, handles)
                handles.dispAxes = inlineDispAxes;
            
                lvd_processData(handles);
        end
        
        function propagateScript(app, handles, lvdData, evtStartNum)
                writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                handles.scriptResultsOutOfDateLbl.Visible = 'off';
                handles.scriptWorkingLbl.Visible = 'on';
                drawnow;
            
                if(not(isdeployed))
            %         profile off; profile('on','-detail','builtin', '-remove_overhead','on');
                end
            
                lvdData.validation.clearOutputs();
            
                isSparseOutput = lvdData.settings.isSparseOutput;
                dispEvtPropTimes = lvdData.settings.disEvtPropTimes;
            
                evt = lvdData.script.getEventForInd(evtStartNum);
            
                t = tic;
                lvdData.script.executeScript(isSparseOutput, evt, true, false, dispEvtPropTimes);
                execTime = toc(t);
            
                if(not(isdeployed))
            %         profile viewer;
                end
            
                handles.scriptWorkingLbl.Visible = 'off';
                handles.warnAlertsSlider.Value = 0;
            
                lvdData.validation.validate();
                updateWarnErrorLabels(app, handles, true);
            
                setappdata(handles.hDispAxesTimeSlider,'lastTime',NaN);
            
                writeOutput(sprintf('Executed mission script in %.3f seconds.',execTime),'append');
                drawnow;
        end
        
        function recentMissionCallback(app, ~, ~, missionFileName, handles)
                celBodyData = getappdata(handles.ma_LvdMainGUI,'celBodyData');
                write_to_output_func = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
                application_title = getappdata(handles.ma_LvdMainGUI,'application_title');
            
                [~,name,ext] = fileparts(missionFileName);
                FileName = [name,ext];
            
                openMissionPlanFile(app, missionFileName, write_to_output_func, application_title, celBodyData, FileName, handles);
        end
        
        function recordFinalAxesPanZoomAfterRotation(~, ~, ~, handles)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                lvdData.viewSettings.selViewProfile.viewZoomAxLims = [handles.dispAxes.XLim;
                                                                      handles.dispAxes.YLim;
                                                                      handles.dispAxes.ZLim];
            
                [az,el] = view(handles.dispAxes);
                lvdData.viewSettings.selViewProfile.viewAzEl = [az,el];
            
                lvdData.viewSettings.selViewProfile.viewCameraPosition = handles.dispAxes.CameraPosition;
            	lvdData.viewSettings.selViewProfile.viewCameraTarget = handles.dispAxes.CameraTarget;
            	lvdData.viewSettings.selViewProfile.viewCameraUpVector = handles.dispAxes.CameraUpVector;
            	lvdData.viewSettings.selViewProfile.viewCameraViewAngle = handles.dispAxes.CameraViewAngle;
        end
        
        function recordFinalAxesViewAfterRotation(~, ~, ~, handles)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                lvdData.viewSettings.selViewProfile.viewZoomAxLims = [handles.dispAxes.XLim;
                                                                      handles.dispAxes.YLim;
                                                                      handles.dispAxes.ZLim];
            
                [az,el] = view(handles.dispAxes);
                lvdData.viewSettings.selViewProfile.viewAzEl = [az,el];
        end
        
        function runScript(app, handles, lvdData, evtStartNum)
                if(lvdData.settings.autoPropScript)
                    propagateScript(app, handles, lvdData, evtStartNum);
                else
                    handles.scriptResultsOutOfDateLbl.Visible = 'on';
                end
                setappdata(handles.hDispAxesTimeSlider,'lastTime',NaN);
        end
        
        function saveMission(~, handles, varargin)
                if(length(varargin) == 1)
                    filePath = varargin{1};
                    [~,name,ext] = fileparts(filePath);
                    FileName = [name,ext];
                else
                    current_save_location = getappdata(handles.ma_LvdMainGUI,'current_save_location');
            
                    if(isempty(current_save_location))
                        fileNameToDefaultTo = 'mission.mat';
                    else
                        [current_save_location_path,~,~] = fileparts(current_save_location);
                        fileNameToDefaultTo = [current_save_location_path,filesep,'mission.mat'];
                    end
            
                    [FileName,PathName] = uiputfile({'*.mat','KSP TOT Launch Vehicle Designer Case File (*.mat)'},...
                                                                'Save Launch Vehicle Designer Case',...
                                                                fileNameToDefaultTo);
                    if(ischar(FileName) && ischar(PathName))
                        filePath = [PathName,FileName];
                    else
                        return;
                    end
                end
            
                write_to_output_func = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
                write_to_output_func(['Saving mission case as "', filePath,'"... '],'append');
            
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                lvdData.ksptotVer = getKSPTOTVersionNumStr();
            
                save(filePath,'lvdData');
                application_title = getappdata(handles.ma_LvdMainGUI,'application_title');
            
                set(handles.ma_LvdMainGUI,'Name',[application_title, ' - ', FileName]);
                setappdata(handles.ma_LvdMainGUI,'current_save_location',filePath);
            
                write_to_output_func(['Done.'],'appendSameLine');
        end
        
        function saveMissionAs(app, handles)
                current_save_location = getappdata(handles.ma_LvdMainGUI,'current_save_location');
            
                if(isempty(current_save_location))
                    fileNameToDefaultTo = 'mission.mat'; %#ok<NASGU> 
                else
                    [current_save_location_path,~,~] = fileparts(current_save_location);
                    fileNameToDefaultTo = [current_save_location_path,filesep,'mission.mat']; %#ok<NASGU> 
                end
            
                [FileName,PathName] = uiputfile({'*.mat','KSP TOT Launch Vehicle Designer Case File (*.mat)'},...
                                                            'Save Launch Vehicle Designer Case',...
                                                            current_save_location);
                if(ischar(FileName) && ischar(PathName))
                    saveMission(app, handles, [PathName,FileName]);
                end
        end
        
        function setDeleteButtonEnable(~, lvdData, handles)
                numEvents = lvdData.script.getTotalNumOfEvents();
                if(numEvents <= 1)
                    handles.deleteEvent.Enable = 'off';
                else
                    handles.deleteEvent.Enable = 'on';
                end
        end
        
        function setNonSeqDeleteButtonEnable(~, lvdData, handles)
                numEvents = lvdData.script.nonSeqEvts.getTotalNumOfEvents();
                if(numEvents <= 0)
                    handles.deleteNonSeqEventButton.Enable = 'off';
                else
                    handles.deleteNonSeqEventButton.Enable = 'on';
                end
        end
        
        function setResizeData(~, hObject, handles)
                pos = hObject.Position;
                origWidth = pos(3);
                origHgt = pos(4);
                setappdata(hObject,'OriginalWidth',origWidth);
                setappdata(hObject,'OriginalHeight',origHgt);
            
                %title label
                titlePos = handles.titleText.Position;
                titleOffsetFromTop = origHgt - (titlePos(2) + titlePos(4));
                setappdata(hObject,'titleOffsetFromTop',titleOffsetFromTop);
            
                %initial state panel
                initScStatePanelPos = handles.initScStatePanel.Position;
                initScStatePanelOffsetFromTop = origHgt - (initScStatePanelPos(2) + initScStatePanelPos(4));
                setappdata(hObject,'initScStatePanelOffsetFromTop',initScStatePanelOffsetFromTop);
            
                %final state panel
                finalScStatePanelPos = handles.finalScStatePanel.Position;
                finalScStatePanelOffsetFromTop = origHgt - (finalScStatePanelPos(2) + finalScStatePanelPos(4));
                setappdata(hObject,'finalScStatePanelOffsetFromTop',finalScStatePanelOffsetFromTop);
            
                %display axes label
                dispAxisTitleLabelPos = handles.dispAxisTitleLabel.Position;
                dispAxisTitleLabelOffsetFromTop = origHgt - (dispAxisTitleLabelPos(2) + dispAxisTitleLabelPos(4));
                setappdata(hObject,'dispAxisTitleLabelOffsetFromTop',dispAxisTitleLabelOffsetFromTop);
            
                %display axes
            %     dispAxesPos = handles.dispAxes.Position;
            %     dispAxesOffsetFromTop = origHgt - (dispAxesPos(2) + dispAxesPos(4));
            %     setappdata(hObject,'dispAxesOffsetFromTop',dispAxesOffsetFromTop);
            
                %display axes panel
                dispAxesPanelPos = handles.dispAxesPanel.Position;
                dispAxesPanelOffsetFromTop = origHgt - (dispAxesPanelPos(2) + dispAxesPanelPos(4));
                setappdata(hObject,'dispAxesPanelOffsetFromTop',dispAxesPanelOffsetFromTop);
            
                %insert seq event button
                insertEventButtonPos = handles.insertEventButton.Position;
                insertEventButtonOffsetFromTop = origHgt - (insertEventButtonPos(2) + insertEventButtonPos(4));
                setappdata(hObject,'insertEventButtonOffsetFromTop',insertEventButtonOffsetFromTop);
            
                %script listbox
                scriptListboxPos = handles.scriptListbox.Position;
                scriptListboxOffsetFromTop = origHgt - (scriptListboxPos(2) + scriptListboxPos(4));
                setappdata(hObject,'scriptListboxOffsetFromTop',scriptListboxOffsetFromTop);
            
                %incrOrbitToPlotNum Button
                incrOrbitToPlotNumPos = handles.incrOrbitToPlotNum.Position;
                incrOrbitToPlotNumOffsetFromRight = origWidth - (incrOrbitToPlotNumPos(1) + incrOrbitToPlotNumPos(3));
                setappdata(hObject,'incrOrbitToPlotNumOffsetFromRight',incrOrbitToPlotNumOffsetFromRight);
            
                %timeSliderValueLabel
                timeSliderValueLabelPos = handles.timeSliderValueLabel.Position;
                timeSliderValueLabelOffsetFromRight = origWidth - (timeSliderValueLabelPos(1) + timeSliderValueLabelPos(3));
                setappdata(hObject,'timeSliderValueLabelOffsetFromRight',timeSliderValueLabelOffsetFromRight);
            
                %timeSliderPanel
                timeSliderPanelPos = handles.timeSliderPanel.Position;
                timeSliderPanelOffsetFromRight = origWidth - (timeSliderPanelPos(1) + timeSliderPanelPos(3));
                setappdata(hObject,'timeSliderPanelOffsetFromRight',timeSliderPanelOffsetFromRight);
            
                %warningAlertPanel
                warningAlertPanelPos = handles.warningAlertPanel.Position;
                warningAlertPanelOffsetFromRight = origWidth - (warningAlertPanelPos(1) + warningAlertPanelPos(3));
                setappdata(hObject,'warningAlertPanelOffsetFromRight',warningAlertPanelOffsetFromRight);
            
                %warnAlertsSlider
                warnAlertsSliderPos = handles.warnAlertsSlider.Position;
                warnAlertsSliderOffsetFromRight = warningAlertPanelPos(3) - (warnAlertsSliderPos(1) + warnAlertsSliderPos(3));
                setappdata(handles.warningAlertPanel,'warnAlertsSliderOffsetFromRight',warnAlertsSliderOffsetFromRight);
            
                %warning1Lbl (and others
                warningLblPos = handles.warning1Lbl.Position;
                warningLblOffsetFromRight = warningAlertPanelPos(3) - (warningLblPos(1) + warningLblPos(3));
                setappdata(handles.warningAlertPanel,'warningLblOffsetFromRight',warningLblOffsetFromRight);
        end
        
        function showChildBodyMarker_Callback(~, ~, ~, handles)
            % hObject    handle to showChildBodyMarker (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of showChildBodyMarker
                lvd_processData(handles);
        end
        
        function showChildrenCheckBox_Callback(~, ~, ~, handles)
            % hObject    handle to showChildrenCheckBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of showChildrenCheckBox
                lvd_processData(handles);
        end
        
        function showSoICheckBox_Callback(~, ~, ~, handles)
            % hObject    handle to showSoICheckBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of showSoICheckBox
                lvd_processData(handles);
        end
        
        function startParallelPool(~, writeOutput, numWorkers)
                p = gcp('nocreate');
                if(isempty(p) || p.NumWorkers ~= numWorkers)
                    try
                        beep off;
                        h = msgbox('Attempting to start parallel computing workers.  Please wait...','modal');
            
                        if(not(isempty(p)))
                            delete(p);
                        end
            
                        pp=parpool('local',numWorkers);
                        pp.IdleTimeout = 99999; %we don't want the pool to shutdown
                        if(isvalid(h))
                            close(h);
                        end
                        writeOutput('Parallel optimization mode enabled.','append');
                        beep on;
                    catch ME
                        if(ishandle(h))
                            close(h);
                        end
                        msgbox(sprintf('Parallel mode start failed.  Optimization will run in serial.  Message:\n\n%s',ME.message));
                        disp(ME.message);
                        beep on;
                    end
                else
                    writeOutput('Parallel optimization mode enabled.','append');
                end
        end
        
        function timeSliderKeyPressCallback(~, src, evt, ~)
                if(javaMethodEDT('getKeyCode',evt) == java.awt.event.KeyEvent.VK_RIGHT || ...
                   javaMethodEDT('getKeyCode',evt) == java.awt.event.KeyEvent.VK_LEFT || ...
                   javaMethodEDT('getKeyCode',evt) == java.awt.event.KeyEvent.VK_KP_RIGHT || ...
                   javaMethodEDT('getKeyCode',evt) == java.awt.event.KeyEvent.VK_KP_LEFT)
            
                    src.StateChangedCallback(src,[]);
                end
        end
        
        function updateWarnErrorLabels(~, handles, updateSlider)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                hLabels = [handles.warning1Lbl, handles.warning2Lbl, handles.warning3Lbl, ...
                          handles.warning4Lbl, handles.warning5Lbl, handles.warning6Lbl];
            
                lvdData.validation.writeOutputsToUI(handles.warnAlertsSlider, hLabels, updateSlider);
        end
        
        function viewProfileMenuSelectedCallback(~, src, ~, profile, viewSettings, handles)
                viewSettings.setProfileAsActive(profile);
            
                menus = src.Parent.Children;
                for(i=1:length(menus))
                    delete(menus(i));
                end
            
                setappdata(handles.hDispAxesTimeSlider,'lastTime',NaN);
                lvd_processData(handles);
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3, app.uitoggletool4];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.ma_LvdMainGUI, 'off')
            rotate3d(app.ma_LvdMainGUI, 'off');
            pan(app.ma_LvdMainGUI, 'off');
            zoom(app.ma_LvdMainGUI,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ma_LvdMainGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to ma_LvdMainGUI (see VARARGIN)
            
                % Choose default command line output for ma_LvdMainGUI
                try
                    handles.output = hObject;
                    hObject.Visible = 'off';
            
                    hManager = uigetmodemanager(hObject);
                    [hManager.WindowListenerHandles.Enabled] = deal(false);
            
                    celBodyData = varargin{1};
                    if(not(isa(celBodyData,'CelestialBodyData')))
                        celBodyData = CelestialBodyData(celBodyData);
                    end
                    setappdata(hObject,'celBodyData',celBodyData);
            
                    hKsptotMainGUI = varargin{2};
                    setappdata(hObject,'ksptotMainGUI',hKsptotMainGUI);
            
                    setappdata(hObject,'current_save_location','');
                    setappdata(hObject,'application_title','KSP TOT Launch Vehicle Designer');
                    setappdata(hObject,'undoRedo',LVD_UndoRedoStateSet());
            
                    lvdData = LvdData.getDefaultLvdData(celBodyData);
                    setappdata(handles.ma_LvdMainGUI,'lvdData',lvdData);
            
                    output_text_max_line_length = length(getLVD_HR(handles.outputText));
                    setappdata(hObject,'output_text_max_line_length',output_text_max_line_length);
                    writeOutput = @(str,type) writeToMAOutput(handles.outputText, str, type, output_text_max_line_length);
                    setappdata(hObject,'write_to_output_func',writeOutput);
            
%                         jDispAxesTimeSlider = javaObjectEDT('javax.swing.JSlider');
%                         jDispAxesTimeSlider.setSnapToTicks(false);
%                         jDispAxesTimeSlider.setMinimum(0);
%                         jDispAxesTimeSlider.setMaximum(1);
%                         jDispAxesTimeSlider.setValue(0);
%                         sliderPnlPos = handles.timeSliderPanel.Position;
%                         [~,hDispAxesTimeSliderJavaWrapper] = javacomponent(jDispAxesTimeSlider,[1, 1, sliderPnlPos(3), sliderPnlPos(4)], handles.timeSliderPanel);
%                         handles.jDispAxesTimeSlider = jDispAxesTimeSlider;
%                         jDispAxesTimeSlider.setToolTipText('Adjust slider to view the location of vehicles and selected celestial bodies at a given time.');
                
                    timeSliderCb = @(src,evt) timeSliderStateChanged(src,evt, lvdData, handles);
                    app.DispAxesTimeSlider.ValueChangingFcn = timeSliderCb;
            
                    setappdata(handles.hDispAxesTimeSlider,'lastTime',NaN);
            
                    initializeOutputWindowText(app, handles, handles.outputText);
                    view(handles.dispAxes,3);
            
                    hRot3D = rotate3d(handles.ma_LvdMainGUI);
                    hRot3D.ActionPostCallback = @(src,evt) recordFinalAxesViewAfterRotation(app, src,evt, handles);
            
                    hZoom = zoom(handles.ma_LvdMainGUI);
                    hZoom.ActionPostCallback = @(src,evt) recordFinalAxesPanZoomAfterRotation(app, src,evt, handles);
            
                    hPan = pan(handles.ma_LvdMainGUI);
                    hPan.ActionPostCallback = @(src,evt) recordFinalAxesPanZoomAfterRotation(app, src,evt, handles);
            
                    addlistener(handles.dispAxes,'CameraPosition','PostSet', @(src,evt) recordFinalAxesPanZoomAfterRotation(app, src,evt, handles));
                    addlistener(handles.dispAxes,'CameraTarget','PostSet', @(src,evt) recordFinalAxesPanZoomAfterRotation(app, src,evt, handles));
                    addlistener(handles.dispAxes,'CameraUpVector','PostSet', @(src,evt) recordFinalAxesPanZoomAfterRotation(app, src,evt, handles));
                    addlistener(handles.dispAxes,'CameraViewAngle','PostSet', @(src,evt) recordFinalAxesPanZoomAfterRotation(app, src,evt, handles));
            
                    setDeleteButtonEnable(app, lvdData, handles);
                    setNonSeqDeleteButtonEnable(app, lvdData, handles);
            
                    gravSystemUpdateCbFh = @(src,evt) gravSystemUpdateCallback(app, src,evt, handles);
                    appOptions = getappdata(hKsptotMainGUI,'appOptions');
                    addlistener(appOptions.ksptot,'GravParamTypeUpdated',gravSystemUpdateCbFh);
            
                    rotate3d(handles.dispAxes,'on');
            
                    enableDisableArrowButtons(app, lvdData, handles);
            
                    setResizeData(app, hObject, handles);
            
                    runScript(app, handles, lvdData, 1);
                    lvd_processData(handles);
            
                    % Update handles structure
                    guidata(hObject, handles);
                    hObject.Visible = 'on';
                    drawnow;
            
                    % UIWAIT makes ma_LvdMainGUI wait for user response (see UIRESUME)
                    % uiwait(handles.ma_LvdMainGUI);
                catch ME
                    beep;
                    errordlg('An error occured while opening Launch Vehicle Designer.  Please see the ksptot.log for more details.','Error');
                    disp(ME.message);
                    close(handles.ma_LvdMainGUI);
                end
        end

        % Menu selected function: adjustVariablesMenu
        function adjustVariablesMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to adjustVariablesMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                lvdOpt = lvdData.optimizer;
                varSet = lvdOpt.vars;
                varSet.sortVarsByEvtNum();
                [x, ~, ~, ~] = varSet.getTotalScaledXVector();
            
                if(~isempty(x))
                    addUndoState(app, handles,'Adjust Optimization Variables');
            
                    propScriptFcn = @() propagateScript(app, handles, lvdData, 1);
                    procDataFcn = @() lvd_processData(handles);
            
                    lvd_adjustOptVarGUI(lvdData, propScriptFcn, procDataFcn);
            
                    runScript(app, handles, lvdData, 1);
                    lvd_processData(handles);
                else
                    warndlg('Cannot display adjustment dialog: no optimization variables are enabled on this script.');
                end
        end

        % Menu selected function: advanceScriptToSelectedEventMenu
        function advanceScriptToSelectedEventMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to advanceScriptToSelectedEventMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                eventNum = handles.scriptListbox.Value;
                event = lvdData.script.getEventForInd(eventNum);
                eventNum = event.getEventNum();
                eventFirstStateLogEntry = lvdData.stateLog.getFirstStateLogForEvent(event);
            
                tf = false;
                badEvtStrs = [];
                for(i=eventNum+1:lvdData.script.getTotalNumOfEvents())
                    evt = lvdData.script.getEventForInd(i);
            
                    for(j=1:eventNum)
                        evtToBeDel = lvdData.script.getEventForInd(j);
            
                        tf = tf || evt.usesEvent(evtToBeDel);
            
                        if(evt.usesEvent(evtToBeDel))
                            badEvtStrs{end+1} = sprintf('Event %u uses Event %u in an action or event termination condition.', evt.getEventNum(), evtToBeDel.getEventNum()); %#ok<AGROW> 
                        end
                    end
                end
            
                if(tf == false)
                    addUndoState(app, handles,'Advance Script to Event');
            
                    oldAutoPropSetting = lvdData.settings.autoPropScript;
                    if(oldAutoPropSetting == false)
            %             lvdData.settings.autoPropScript = true;
            
                        propagateScript(app, handles, lvdData, 1);
            %             runScript(handles, lvdData, 1);
                %         lvd_processData(handles);
                    end
            
                    lvdData.initStateModel.setInitialStateFromStateLogEntry(eventFirstStateLogEntry);
            
                    for(i=eventNum-1:-1:1)
                        evt = lvdData.script.getEventForInd(i);
                        lvdData.optimizer.constraints.removeConstraintsThatUseEvent(evt);
                        lvdData.optimizer.objFcn.removeObjFcnsThatUseEvent(evt);
                        lvdData.script.removeEvent(evt);
                    end
            
                    handles.scriptListbox.Value = 1;
                    runScript(app, handles, lvdData, 1);
                    lvd_processData(handles);
            %         lvdData.settings.autoPropScript = oldAutoPropSetting;
                else
                    badEvtStrJoin = strjoin(badEvtStrs,'\n');
                    msgStr = sprintf('Cannot advance to event because one or more of the events that would be deleted are in use elsewhere:\n\n%s', badEvtStrJoin);
                    errordlg(msgStr,'Cannot Advance to Event');
                end
        end

        % Menu selected function: astroCalculatorsMenu
        function astroCalculatorsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to astroCalculatorsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                ma_AstroCalculatorsGUI(lvdData.celBodyData);
        end

        % Menu selected function: autopropagateMenu
        function autopropagateMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to autopropagateMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                addUndoState(app, handles,'Edit Script Auto-Propagation');
            
                if(strcmp(get(gcbo, 'Checked'),'on'))
                    lvdData.settings.autoPropScript = false;
            
                    writeOutput('Script auto-propagation is off.  Run script manually with ctrl-p.','append');
                else
                    lvdData.settings.autoPropScript = true;
            
                    writeOutput('Script auto-propagation is on.','append');
                end
        end

        % Menu selected function: celBodyCatalogMenu
        function celBodyCatalogMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to celBodyCatalogMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            %     ma_CelBodyCatalogGUI(lvdData.celBodyData);
                ma_CelBodyCatalogGUI_App(lvdData.celBodyData);
        end

        % Menu selected function: clearOutputMenu
        function clearOutputMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to clearOutputMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                initializeOutputWindowText(app, handles, handles.outputText);
        end

        % Menu selected function: 
        % configureCelestialBodyStateCacheMenu
        function configureCelestialBodyStateCacheMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to configureCelestialBodyStateCacheMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                configureCelBodyStateCacheGUI_App(lvdData.celBodyData);
        end

        % Menu selected function: copyDurationOfSelEventMenu
        function copyDurationOfSelEventMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to copyDurationOfSelEventMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                eventNum = handles.scriptListbox.Value;
                stateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(false);
            
                eventLog = stateLog(stateLog(:,13)==eventNum,:);
                duration = max(eventLog(:,1)) - min(eventLog(:,1));
            
                clipboard('copy', fullAccNum2Str(duration));
        end

        % Menu selected function: copyOrbitAfterSelectedEventMenu
        function copyOrbitAfterSelectedEventMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to copyOrbitAfterSelectedEventMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                celBodyData = getappdata(handles.ma_LvdMainGUI,'celBodyData');
            
                eventNum = handles.scriptListbox.Value;
                stateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(false);
            
                state = stateLog(stateLog(:,13)==eventNum,:);
                state = state(end,:);
            
                bodyID = state(8);
            
                bodyInfo = getBodyInfoByNumber(bodyID, celBodyData);
                gmu = bodyInfo.gm;
                utSec = state(1);
                rVect = state(2:4)';
                vVect = state(5:7)';
            
                [sma, ecc, inc, raan, arg, tru] = getKeplerFromState(rVect,vVect,gmu);
                clipboardData = [utSec, sma, ecc, inc, raan, arg, tru, bodyID];
                set(handles.copyOrbitAfterSelectedEventMenu,'UserData',clipboardData);
                copyOrbitToClipboardFromStateLog([],[],handles.copyOrbitAfterSelectedEventMenu);
        end

        % Menu selected function: copyUtAtEndOfSelEventMenu
        function copyUtAtEndOfSelEventMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to copyUtAtEndOfSelEventMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                eventNum = handles.scriptListbox.Value;
                stateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(false);
            
                state = stateLog(stateLog(:,13)==eventNum,:);
                state = state(end,:);
            
                clipboard('copy', fullAccNum2Str(state(1,1)));
        end

        % Menu selected function: copyUtAtStartOfSelEventMenu
        function copyUtAtStartOfSelEventMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to copyUtAtStartOfSelEventMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                eventNum = handles.scriptListbox.Value;
                stateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(false);
            
                state = stateLog(stateLog(:,13)==eventNum,:);
                state = state(1,:);
            
                clipboard('copy', fullAccNum2Str(state(1,1)));
        end

        % Menu selected function: 
        % createContConstraintsWithSelEvtMenu
        function createContConstraintsWithSelEvtMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to createContConstraintsWithSelEvtMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                totNumEvts = lvdData.script.getTotalNumOfEvents();
                eventNum = get(handles.scriptListbox,'Value');
                evt = lvdData.script.getEventForInd(eventNum);
            
                prevEvts = LaunchVehicleEvent.empty(1,0);
                prevEvtsNames = {};
                for(i=1:totNumEvts)
                    if(i == eventNum)
                        continue;
                    end
            
                    prevEvt = lvdData.script.getEventForInd(i);
                    prevEvts(end+1) = prevEvt; %#ok<AGROW>
                    prevEvtsNames{end+1} = prevEvt.getListboxStr(); %#ok<AGROW>
                end
            
                if(not(isempty(prevEvts)))
                    [Selection,ok] = listdlgARH('ListString',prevEvtsNames, ...
                                                'SelectionMode','single', ...
                                                'ListSize', [300 300], ...
                                                'Name','Select Constraint Event', ...
                                                'PromptString', 'Select event to create continuity constraints with:');
            
                    if(ok == 1)
                        selEvt = prevEvts(Selection);
                        selEvtNum = selEvt.getEventNum();
            
                        if(eventNum > selEvtNum)
                            refEvt = evt;
                            constrEvt = selEvt;
                        else
                            refEvt = selEvt;
                            constrEvt = evt;
                        end
            
                        stateLogEntry = lvdData.stateLog.getLastStateLogForEvent(refEvt);
                        refBodyInfo = stateLogEntry.centralBody;
                        frame = refBodyInfo.getBodyCenteredInertialFrame();
            
                        cs = AbstractConstraint.empty(1,0);
            
                        %position
            %             c(end+1) = PositionContinuityConstraintX(refEvt, constrEvt);
            %             c(end+1) = PositionContinuityConstraintY(refEvt, constrEvt);
            %             c(end+1) = PositionContinuityConstraintZ(refEvt, constrEvt);
                        cs(end+1) = GenericMAConstraint('Position Vector (X)', refEvt, 0, 0, [], [], refBodyInfo);
                        cs(end+1) = GenericMAConstraint('Position Vector (Y)', refEvt, 0, 0, [], [], refBodyInfo);
                        cs(end+1) = GenericMAConstraint('Position Vector (Z)', refEvt, 0, 0, [], [], refBodyInfo);
            
                        %velocity
            %             c(end+1) = VelocityContinuityConstraintX(refEvt, constrEvt);
            %             c(end+1) = VelocityContinuityConstraintY(refEvt, constrEvt);
            %             c(end+1) = VelocityContinuityConstraintZ(refEvt, constrEvt);
                        cs(end+1) = GenericMAConstraint('Velocity Vector (X)', refEvt, 0, 0, [], [], refBodyInfo);
                        cs(end+1) = GenericMAConstraint('Velocity Vector (Y)', refEvt, 0, 0, [], [], refBodyInfo);
                        cs(end+1) = GenericMAConstraint('Velocity Vector (Z)', refEvt, 0, 0, [], [], refBodyInfo);
            
                        %time
            %             c(end+1) = TimeContinuityConstraint(refEvt, constrEvt);
                        cs(end+1) = GenericMAConstraint('Universal Time', refEvt, 0, 0, [], [], refBodyInfo);
            
                        for(i=1:length(cs))
                            c = cs(i);
                            c.evalType = ConstraintEvalTypeEnum.StateComparison;
                            c.stateCompType = ConstraintStateComparisonTypeEnum.Equals;
                            c.stateCompEvent = constrEvt;
                            c.frame = frame;
            
                            lvdData.optimizer.constraints.addConstraint(c);
                        end
            
                        msgbox('Position, velocity, and time continuity constraints added successfully.');
                    else
                        return;
                    end
                else
                    warndlg('There must be at least one other event in the missions script to create continuity constraints with.');
                end
        end

        % Menu selected function: createkOSExecCodeMenu
        function createkOSExecCodeMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to createkOSExecCodeMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                [FileName,PathName,FilterIndex] = uiputfile({'*.csv','CSV Files'},'Save LVD Data to CSV.');
            
                if(FilterIndex == 0)
                    return;
                else
                    csvFilePath = fullfile(PathName,FileName);
                end
            
                eventsListboxStr = lvdData.script.getListboxStr();
                evtInds = 1:length(eventsListboxStr);
                [Selection,ok] = listdlgARH('ListString', eventsListboxStr, ...
                                             'ListSize', [300, 300], ...
                                             'InitialValue', evtInds, ...
                                             'Name', 'Select Events for Export', ...
                                             'PromptString', 'Select the events to export for kOS:');
            
                 if(ok == 0 || isempty(Selection))
                     return;
                 end
            
                oldAutoPropSetting = lvdData.settings.autoPropScript;
                if(oldAutoPropSetting == false)
                    propagateScript(app, handles, lvdData, 1);
                end
            
                stateLog = lvdData.stateLog;
                stateLogEntries = LaunchVehicleStateLogEntry.empty(1,0);
            	for(i=1:length(Selection))
                    evt = lvdData.script.getEventForInd(Selection(i));
                    stateLogEntries = [stateLogEntries, stateLog.getAllStateLogEntriesForEvent(evt)]; %#ok<AGROW>
            	end
            
            %     stateLogEntries = stateLog.getAllEntries();
            
                %sort state log entries by time ascending so that they show up in the
                %right order.
                stateLogTimes = [stateLogEntries.time];
                [~,I] = sort(stateLogTimes);
                stateLogEntries = stateLogEntries(I);
            
                numEntries = length(stateLogEntries);
            
                time = NaN(1,numEntries);
                yaw = NaN(1,numEntries);
                pitch = NaN(1,numEntries);
                roll = NaN(1,numEntries);
                throttle = NaN(1,numEntries);
                timeToNextEvt = NaN(1,numEntries);
                evtNames = cell(1,numEntries);
                nextEvtNames = cell(1,numEntries);
            
                hWaitbar = waitbar(0,'Parsing State Log Data, Please Wait...');
                numStateLogEntries = length(stateLogEntries);
                for(i=1:numStateLogEntries)
                    stateLogEntry = stateLogEntries(i);
            
                    time(i) = stateLogEntry.time;
                    yaw(i) = lvd_SteeringAngleTask(stateLogEntry, 'yaw');
                    pitch(i) = lvd_SteeringAngleTask(stateLogEntry, 'pitch');
                    roll(i) = lvd_SteeringAngleTask(stateLogEntry, 'roll');
                    throttle(i) = stateLogEntry.throttle;
            
                    evt = stateLogEntry.event;
                    evtNames{i} = evt.name;
            
                    nextEvt = LaunchVehicleEvent.empty(0,1);
                    nextEvtStartTime = [];
                    for(j=i:numStateLogEntries)
                        testEntry = stateLogEntries(j);
                        if(evt ~= testEntry.event)
                            nextEvt = testEntry.event;
                            nextEvtStartTime = testEntry.time;
                            break;
                        end
                    end
            
                    if(not(isempty(nextEvt)))
                        timeToNextEvt(i) = nextEvtStartTime - stateLogEntry.time;
                        nextEvtNames{i} = nextEvt.name;
                    else
                        timeToNextEvt(i) = 0;
                        nextEvtNames{i} = '';
                    end
            
                    waitbar(i/numStateLogEntries, hWaitbar);
                end
            
                if(isvalid(hWaitbar))
                    close(hWaitbar);
                end
            
                M = [time(:)'; ...
                     yaw(:)'; ...
                     pitch(:)'; ...
                     roll(:)'; ...
                     throttle(:)'; ...
                     timeToNextEvt(:)'];
                dlmwrite(csvFilePath,M,'precision','%0.13f');
            
                fid = fopen(csvFilePath,'a');
                fprintf(fid, '%s\n', strjoin(evtNames,','));
                fprintf(fid, '%s', strjoin(nextEvtNames,','));
                fclose(fid);
        end

        % Button pushed function: decrOrbitToPlotNum
        function decrOrbitToPlotNum_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to decrOrbitToPlotNum (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %     orbitNumToPlot = get(handles.dispAxes,'UserData');
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                orbitNumToPlot = lvdData.viewSettings.selViewProfile.orbitNumToPlot;
            
                if(orbitNumToPlot > 1)
                    orbitNumToPlot = orbitNumToPlot - 1;
            
                elseif(orbitNumToPlot == 1)
                    maStateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(false);
                    chunkedStateLog = breakStateLogIntoSoIChunks(maStateLog);
                    orbitNumToPlot = size(chunkedStateLog,1);
                end
            
                lvdData.viewSettings.selViewProfile.orbitNumToPlot = orbitNumToPlot;
                set(handles.dispAxes,'UserData',orbitNumToPlot);
                lvd_processData(handles);
        end

        % Button pushed function: deleteEvent
        function deleteEvent_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to deleteEvent (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Delete Event');
            
                eventNum = get(handles.scriptListbox,'Value');
                evt = lvdData.script.getEventForInd(eventNum);
            
                tfEvt = evt.usesEvent(evt);
                tfObj = lvdData.optimizer.objFcn.usesEvent(evt);
                if(tfEvt || tfObj)
                    warndlg(sprintf('Could not delete the event "%s" because it is in use as part of an event action or objective function.  Remove the event dependencies before attempting to delete the event.', evt.getListboxStr()),'Cannot Delete Event','modal');
                else
                    lvdData.optimizer.vars.removeVariablesThatUseEvent(evt, lvdData);
                    lvdData.optimizer.constraints.removeConstraintsThatUseEvent(evt);
            
                    lvdData.script.removeEventFromIndex(eventNum);
            
                    if(eventNum > length(lvdData.script.evts))
                        set(handles.scriptListbox,'Value',length(lvdData.script.evts));
                    end
            
                    setDeleteButtonEnable(app, lvdData, handles);
            
                    runScript(app, handles, lvdData, 1);
                    lvd_processData(handles);
                end
        end

        % Button pushed function: deleteNonSeqEventButton
        function deleteNonSeqEventButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to deleteNonSeqEventButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Delete Non-Sequential Event');
            
                eventNum = get(handles.nonSeqEventsListbox,'Value');
            %     evt = lvdData.script.nonSeqEvts.getEventForInd(eventNum);
            
            %     lvdData.optimizer.constraints.removeConstraintsThatUseEvent(evt);
            %
            %     if(lvdData.optimizer.objFcn.usesEvent(evt))
            %         lvdData.optimizer.objFcn = NoOptimizationObjectiveFcn(lvdData.optimizer, lvdData);
            %
            %         warndlg(sprintf('The existing objective function referenced the deleted event.  The objective function has been replaced with a "%s" objective function.',ObjectiveFunctionEnum.NoObjectiveFunction.name),'Objective Function Reset','modal');
            %     end
            
                lvdData.script.nonSeqEvts.removeEventFromIndex(eventNum);
            
                totNumNonSeqEvents = lvdData.script.nonSeqEvts.getTotalNumOfEvents();
                if(eventNum > totNumNonSeqEvents)
                    set(handles.scriptListbox,'Value',totNumNonSeqEvents);
                end
            
                setNonSeqDeleteButtonEnable(app, lvdData, handles)
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Size changed function: dispAxesPanel
        function dispAxesPanel_SizeChangedFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to dispAxesPanel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                if(not(isempty(handles)))
                    dispAxesPanelPos = hObject.Position;
            
                    %disp axes
                    dispAxesPos = [10, 5, dispAxesPanelPos(3)-10, dispAxesPanelPos(4)-10];
                    handles.dispAxes.Position = dispAxesPos;
            
                    %axes working label
                    plotWorkingLblPos = handles.plotWorkingLbl.Position;
                    plotWorkingLblPos(1:2) = [1 1];
                    handles.plotWorkingLbl.Position = plotWorkingLblPos;
            
                    %disp axes results out of date label
                    plotOutOfDataLblPos = handles.scriptResultsOutOfDateLbl.Position;
                    plotOutOfDataLblPos(1:2) = [1 1];
                    plotOutOfDataLblPos(3) = dispAxesPanelPos(3);
                    handles.scriptResultsOutOfDateLbl.Position = plotOutOfDataLblPos;
                end
        end

        % Menu selected function: displayEvtPropTimesInLogFileMenu
        function displayEvtPropTimesInLogFileMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to displayEvtPropTimesInLogFileMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                addUndoState(app, handles,'Edit Display Event Prop Times');
            
                if(strcmp(get(gcbo, 'Checked'),'on'))
                    lvdData.settings.disEvtPropTimes = false;
            
                    writeOutput('Display of event propagation times in KSPTOT log file is off.','append');
                else
                    lvdData.settings.disEvtPropTimes = true;
            
                    writeOutput('Event propagation times will be displayed in the ksptot.log file.','append');
                end
        end

        % Menu selected function: editAnglesMenu
        function editAnglesMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editAnglesMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Edit Angles');
                lvd_EditGeometricAnglesGUI(lvdData);
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Menu selected function: editCalculusObjectsMenu
        function editCalculusObjectsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editCalculusObjectsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Edit Calculus Calculations');
                lvd_EditCalculusObjsGUI(lvdData);
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Menu selected function: editConstraintsMenu
        function editConstraintsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editConstraintsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Edit Constraints');
            
                lvd_EditConstraintsGUI(lvdData);
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Menu selected function: editCoordSysMenu
        function editCoordSysMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editCoordSysMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Edit Coordinate Systems');
                lvd_EditGeometricCoordSysGUI(lvdData);
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Menu selected function: editExtremaMenu
        function editExtremaMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editExtremaMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Edit Extrema');
                lvd_EditExtremasGUI(lvdData);
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Menu selected function: editGroundObjsMenu
        function editGroundObjsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editGroundObjsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Edit Ground Objects');
            
                lvd_EditGroundObjectsGUI(lvdData.groundObjs);
            
            %     if(result == false)
            %         undoMenu_Callback(handles.undoMenu, [], handles);
            %     else
            %         runScript(handles, lvdData, 1);
            %     end
            
                lvd_processData(handles);
        end

        % Menu selected function: editInitialStateMenu
        function editInitialStateMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editInitialStateMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                hKsptotMainGUI = getappdata(handles.ma_LvdMainGUI,'ksptotMainGUI');
            
                addUndoState(app, handles,'Edit Initial State');
            
                lvd_EditInitialStateGUI(lvdData, hKsptotMainGUI);
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Menu selected function: editLaunchVehicleMenu
        function editLaunchVehicleMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editLaunchVehicleMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Edit Launch Vehicle');
            
                lvd_editLaunchVehicle(lvdData);
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Menu selected function: editMenu
        function editMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                undoRedo = getappdata(handles.ma_LvdMainGUI,'undoRedo');
                [undoTf, undoActionName] = undoRedo.shouldUndoMenuBeEnabled();
                [redoTf, redoActionName] = undoRedo.shouldRedoMenuBeEnabled();
            
                if(undoTf)
                    set(handles.undoMenu,'Enable','on');
                else
                    set(handles.undoMenu,'Enable','off');
                end
                set(handles.undoMenu,'Label',['Undo ',undoActionName]);
            
                if(redoTf)
                    set(handles.redoMenu,'Enable','on');
                else
                    set(handles.redoMenu,'Enable','off');
                end
                set(handles.redoMenu,'Label',['Redo ',redoActionName]);
        end

        % Menu selected function: editMissionNotesMenu
        function editMissionNotesMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editMissionNotesMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Edit Mission Notes');
            
                lvd_MissionNotesGUI(lvdData);
        end

        % Menu selected function: editObjFunctionMenu
        function editObjFunctionMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editObjFunctionMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Edit Objective Function');
            
            %     lvd_EditObjectiveFunctionGUI(lvdData);
                lvd_EditCompositeObjectiveFunctionGUI(lvdData);
        end

        % Menu selected function: editPlanesMenu
        function editPlanesMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editPlanesMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Edit Planes');
                lvd_EditGeometricPlanesGUI(lvdData);
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Menu selected function: editPointsMenu
        function editPointsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editPointsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                hKsptotMainGUI = getappdata(hObject,'ksptotMainGUI');
            
                addUndoState(app, handles,'Edit Points');
                lvd_EditGeometricPointsGUI(lvdData, hKsptotMainGUI);
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Menu selected function: editRefRamesMenu
        function editRefRamesMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editRefRamesMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Edit Reference Frames');
                lvd_EditGeometricRefFramesGUI(lvdData);
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Menu selected function: editStopwatchesMenu
        function editStopwatchesMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editStopwatchesMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Edit Stopwatches');
            
                lvd_EditStopwatchesGUI(lvdData);
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Menu selected function: editVectorsMenu
        function editVectorsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editVectorsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Edit Vectors');
                lvd_EditGeometricVectorsGUI(lvdData);
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Menu selected function: editViewSettingsMenu
        function editViewSettingsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editViewSettingsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Edit View Settings');
            
            %     lvd_viewSettingsGUI(lvdData.viewSettings);
                hFig = lvd_viewSettingsGUI_App(lvdData.viewSettings);
                delete(hFig);
            
                setappdata(handles.hDispAxesTimeSlider,'lastTime',NaN);
            
                enableDisableArrowButtons(app, lvdData, handles);
                lvd_processData(handles);
        end

        % Menu selected function: exitMenu
        function exitMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to exitMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                close(handles.ma_LvdMainGUI);
        end

        % Menu selected function: fileMenu
        function fileMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to fileMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                appOptions = getAppOptionsFromFile();
                recentMsnCaseFilesStr = appOptions.ksptot.lvdrecentmsncasefiles;
                recentMissions = strsplit(recentMsnCaseFilesStr, ';');
            
                delete(handles.openRecentMissionPlansMenu.Children);
            
                if(numel(recentMsnCaseFilesStr) > 0)
                    for(i=1:numel(recentMissions))
                        missionFileName = recentMissions{i};
                        if(isempty(missionFileName))
                            continue;
                        end
                        [path,name,ext] = fileparts(missionFileName);
            
                        if(length(path) > 33)
                            subPath = sprintf('%s...%s',path(1:30),filesep);
                        else
                            subPath = spritnf('%s%s', path, filesep);
                        end
            
                        menuName = sprintf('%s%s%s', subPath, name, ext);
            
                        callback = @(src,evt) recentMissionCallback(app, src,evt, missionFileName, handles);
                        uimenu(handles.openRecentMissionPlansMenu, 'Text',menuName, 'MenuSelectedFcn', callback);
                    end
            
                else
                    uimenu(handles.openRecentMissionPlansMenu, 'Text','No Recent Missions', 'Enable','off');
                end
        end

        % Menu selected function: graphicalAnalysisMenu
        function graphicalAnalysisMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to graphicalAnalysisMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            %     lvd_GraphicalAnalysisGUI(lvdData, handles.ma_LvdMainGUI);
            
                hFig = msgbox('Starting Graphical Analysis, please wait...', 'Graphical Analysis', 'help');
                lvd_GraphicalAnalysisGUI_App(lvdData, handles.ma_LvdMainGUI);
                close(hFig);
        end

        % Menu selected function: haloOrbitConstructorMenu
        function haloOrbitConstructorMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to haloOrbitConstructorMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                lvd_HaloOrbitConstructor_App(lvdData.celBodyData);
        end

        % Button pushed function: incrOrbitToPlotNum
        function incrOrbitToPlotNum_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to incrOrbitToPlotNum (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %     orbitNumToPlot = get(handles.dispAxes,'UserData');
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                maStateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(false);
                chunkedStateLog = breakStateLogIntoSoIChunks(maStateLog);
                orbitNumToPlot = lvdData.viewSettings.selViewProfile.orbitNumToPlot;
            
                if(orbitNumToPlot < size(chunkedStateLog,1))
                    orbitNumToPlot = orbitNumToPlot + 1;
            
                elseif(orbitNumToPlot == size(chunkedStateLog,1))
                    orbitNumToPlot = 1;
            
                end
            
                lvdData.viewSettings.selViewProfile.orbitNumToPlot = orbitNumToPlot;
            	set(handles.dispAxes,'UserData',orbitNumToPlot);
                lvd_processData(handles);
        end

        % Button pushed function: insertEventButton
        function insertEventButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to insertEventButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Insert Event');
            
                selEvtNum = get(handles.scriptListbox,'Value');
                event = LaunchVehicleEvent.getDefaultEvent(lvdData.script);
                lvdData.script.addEventAtInd(event,selEvtNum);
                lvd_editEventGUI(event, false);
            
            	setDeleteButtonEnable(app, lvdData, handles);
            
                runScript(app, handles, lvdData, 1);
            	lvd_processData(handles);
        end

        % Button pushed function: insertNonSeqEventButton
        function insertNonSeqEventButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to insertNonSeqEventButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Insert Non-Sequential Event');
            
                selEvtNum = get(handles.nonSeqEventsListbox,'Value');
                event = LaunchVehicleEvent.getDefaultEvent(lvdData.script);
                nonSeqEvt = LaunchVehicleNonSeqEvent(event);
                lvdData.script.nonSeqEvts.addEventAtInd(nonSeqEvt,selEvtNum);
                lvd_editNonSeqEventGUI(nonSeqEvt, lvdData);
            
            	setNonSeqDeleteButtonEnable(app, lvdData, handles)
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Menu selected function: intMaxSimTimeMenu
        function intMaxSimTimeMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to intMaxSimTimeMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                input_str = sprintf(['Enter the desired maximum simulation time (sec):\n',...
                                     '(Minimum = 0.0 sec, Maximum = Inf sec)\n', ...
                                     '(Script execution stops if the duration of the mission exceeds this value.)']);
                str = inputdlg(input_str, 'Maximum Simulation Time', [1 75], {fullAccNum2Str(lvdData.settings.simMaxDur)});
                if(isempty(str))
                    return;
                end
            
                str = str{1};
            
                if(checkStrIsNumeric(str) && str2double(str) >= 0 && str2double(str) <= Inf)
                    addUndoState(app, handles,'Set Max Simulation Time');
            
                    writeOutput(sprintf('Setting maximum simulation time to %s sec.', str),'append');
            
                    lvdData.settings.simMaxDur = str2double(str);
            
                    runScript(app, handles, lvdData, 1);
                    lvd_processData(handles);
                else
                    writeOutput(sprintf('Could not set the desired maximum simulation time.  "%s" is an invalid entry.', str),'append');
                    beep;
                end
        end

        % Menu selected function: intMinAltitudeMenu
        function intMinAltitudeMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to intMinAltitudeMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                input_str = sprintf(['Enter the desired minimum integration altitude (km):\n',...
                                     '(Minimum = -Inf km, Maximum = 0 km)\n', ...
                                     '(Script execution stops if the vehicle reaches this altitude.)']);
                str = inputdlg(input_str, 'Minimum Integration Altitude', [1 75], {fullAccNum2Str(lvdData.settings.minAltitude)});
                if(isempty(str))
                    return;
                end
            
                str = str{1};
            
                if(checkStrIsNumeric(str) && str2double(str) >= -Inf && str2double(str) <= 0.0)
                    addUndoState(app, handles,'Edit Minimum Altitude');
            
                    writeOutput(sprintf('Setting minimum integration altitude to %s km.', str),'append');
            
                    lvdData.settings.minAltitude = str2double(str);
            
                    runScript(app, handles, lvdData, 1);
                    lvd_processData(handles);
                else
                    writeOutput(sprintf('Could not set the desired minimum integration altitude.  "%s" is an invalid entry.', str),'append');
                    beep;
                end
        end

        % Menu selected function: integrationSettingsMenu
        function integrationSettingsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to integrationSettingsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
            	isSparseOutput = lvdData.settings.isSparseOutput;
                if(isSparseOutput==true)
                    set(handles.sparseIntegratorOutputMenu, 'Checked', 'on');
                else
                    set(handles.sparseIntegratorOutputMenu, 'Checked', 'off');
                end
        end

        % Close request function: ma_LvdMainGUI
        function ma_LvdMainGUI_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ma_LvdMainGUI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
                if(strcmpi(handles.scriptWorkingLbl.Visible,'on') || ...
                   strcmpi(handles.plotWorkingLbl.Visible,'on'))
                    askToQuit = true;
                else
                    askToQuit = false;
                end
            
                yesStr = 'Yes';
                if(askToQuit)
                    response = questdlg(['KSPTOT is still processing.  Any in-work data will be lost upon closing.  Continue?'],'Close Launch Vehicle Designer?',yesStr,'No','No');
                else
                    response = yesStr;
                end
            
                if(~strcmpi(response,yesStr))
                    return;
                else
                    if(isMissionPlanSaved(app, handles))
                        askToClear = false;
                    else
                        askToClear = true;
                    end
            
                    if(askToClear)
                        response = questdlg(['All unsaved work will be lost.  Continue?'],'Close Launch Vehicle Designer?',yesStr,'No','No');
                    else
                        response = yesStr;
                    end
            
                    if(~strcmpi(response,yesStr))
                        return;
                    end
            
                    delete(hObject);
                end
        end

        % Size changed function: ma_LvdMainGUI
        function ma_LvdMainGUI_SizeChangedFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ma_LvdMainGUI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                origWidth = getappdata(hObject,'OriginalWidth');
                origHeight = getappdata(hObject,'OriginalHeight');
            
                newPos = hObject.Position;
                newWidth = newPos(3);
                newHeight = newPos(4);
            
                if(newWidth <= origWidth && newHeight <= origHeight)
                    try
                        LimitFigSize(hObject, 'min', [origWidth, origHeight]);
                    catch
                        %do nothing
                    end
                end
            
            %     %update figure to not get smaller than original size
                updatedFigPos = newPos;
            %     updatedFigPos(3) = max([origWidth,newWidth]);
            %     updatedFigPos(4) = max([origHeight,newHeight]);
            %     hObject.Position = updatedFigPos;
            %     drawnow;
            
                %resize title text banner
                titleOffsetFromTop = getappdata(hObject,'titleOffsetFromTop');
                titlePos = handles.titleText.Position;
                titlePos(3) = updatedFigPos(3);
                titlePos(2) = updatedFigPos(4) - (titlePos(4) + titleOffsetFromTop);
                handles.titleText.Position = titlePos;
            
                %initial state panel
                initScStatePanelOffsetFromTop = getappdata(hObject,'initScStatePanelOffsetFromTop');
                initScStatePanelPos = handles.initScStatePanel.Position;
                initScStatePanelPos(2) = updatedFigPos(4) - (initScStatePanelPos(4) + initScStatePanelOffsetFromTop);
                handles.initScStatePanel.Position = initScStatePanelPos;
            
                %final state panel
                finalScStatePanelOffsetFromTop = getappdata(hObject,'finalScStatePanelOffsetFromTop');
                finalScStatePanelPos = handles.finalScStatePanel.Position;
                finalScStatePanelPos(2) = updatedFigPos(4) - (finalScStatePanelPos(4) + finalScStatePanelOffsetFromTop);
                handles.finalScStatePanel.Position = finalScStatePanelPos;
            
                %display axes title label
                dispAxisTitleLabelOffsetFromTop = getappdata(hObject,'dispAxisTitleLabelOffsetFromTop');
                dispAxisTitleLabelPos = handles.dispAxisTitleLabel.Position;
                dispAxisTitleLabelPos(2) = updatedFigPos(4) - (dispAxisTitleLabelPos(4) + dispAxisTitleLabelOffsetFromTop);
                dispAxisTitleLabelPos(3) = updatedFigPos(3) - dispAxisTitleLabelPos(1);
                handles.dispAxisTitleLabel.Position = dispAxisTitleLabelPos;
            
                %display axes
                dispAxesPanelOffsetFromTop = getappdata(hObject,'dispAxesPanelOffsetFromTop');
                dispAxesPanelPos = handles.dispAxesPanel.Position;
                dispAxesPanelPos(3) = updatedFigPos(3) - dispAxesPanelPos(1);
                dispAxesPanelPos(4) = newHeight - (dispAxesPanelPos(2) + dispAxesPanelOffsetFromTop);
                handles.dispAxesPanel.Position = dispAxesPanelPos;
            
                %insert seq event button
                insertEventButtonOffsetFromTop = getappdata(hObject,'insertEventButtonOffsetFromTop');
                insertEventButtonPos = handles.insertEventButton.Position;
                insertEventButtonPos(2) = updatedFigPos(4) - (insertEventButtonPos(4) + insertEventButtonOffsetFromTop);
                handles.insertEventButton.Position = insertEventButtonPos;
            
                %timeSliderValueLabel
                timeSliderValueLabelOffsetFromRight = getappdata(hObject,'timeSliderValueLabelOffsetFromRight');
                timeSliderValueLabelPos = handles.timeSliderValueLabel.Position;
                timeSliderValueLabelPos(3) = (updatedFigPos(3) - timeSliderValueLabelOffsetFromRight) - timeSliderValueLabelPos(1);
                handles.timeSliderValueLabel.Position = timeSliderValueLabelPos;
            
                %script listbox
                scriptListboxOffsetFromTop = getappdata(hObject,'scriptListboxOffsetFromTop');
                scriptListboxPos = handles.scriptListbox.Position;
                scriptListboxPos(4) = newHeight - (scriptListboxPos(2) + scriptListboxOffsetFromTop);
                handles.scriptListbox.Position = scriptListboxPos;
            
                %incrOrbitToPlotNum Button
                incrOrbitToPlotNumOffsetFromRight = getappdata(hObject,'incrOrbitToPlotNumOffsetFromRight');
                incrOrbitToPlotNumPos = handles.incrOrbitToPlotNum.Position;
                incrOrbitToPlotNumPos(1) = newWidth - incrOrbitToPlotNumOffsetFromRight - incrOrbitToPlotNumPos(3);
                handles.incrOrbitToPlotNum.Position = incrOrbitToPlotNumPos;
            
                %timeSliderPanel
                timeSliderPanelOffsetFromRight = getappdata(hObject,'timeSliderPanelOffsetFromRight');
                timeSliderPanelPos = handles.timeSliderPanel.Position;
                timeSliderPanelPos(3) = newWidth - (timeSliderPanelPos(1) + timeSliderPanelOffsetFromRight);
                handles.timeSliderPanel.Position = timeSliderPanelPos;
            
                %warningAlertPanel
                warningAlertPanelOffsetFromRight = getappdata(hObject,'warningAlertPanelOffsetFromRight');
                warningAlertPanelPos = handles.warningAlertPanel.Position;
                warningAlertPanelPos(3) = newWidth - (warningAlertPanelPos(1) + warningAlertPanelOffsetFromRight);
                handles.warningAlertPanel.Position = warningAlertPanelPos;
            
                drawnow limitrate nocallbacks;
        end

        % Window key press function: ma_LvdMainGUI
        function ma_LvdMainGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            
            % hObject    handle to ma_LvdMainGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
                key = eventdata.Key;
                mod = eventdata.Modifier;
            
                if(hObject == handles.scriptListbox && ...
                   strcmpi(key,'f') && ...
                   length(mod) == 1 && ...
                   strcmpi(mod{1},'control'))
            
                    toggleOptimForSelEventMenu_Callback(handles.scriptListbox, [], handles);
                end
        end

        % Menu selected function: managePluginsMenu
        function managePluginsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to managePluginsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Manage Plugins');
            
                result = ldv_editPluginGUI(lvdData);
            
                if(result == false)
                    undoMenu_Callback(handles.undoMenu, [], handles);
                else
                    runScript(app, handles, lvdData, 1);
                end
            
                lvd_processData(handles);
        end

        % Menu selected function: maxScriptPropTimeMenu
        function maxScriptPropTimeMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to maxScriptPropTimeMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                input_str = sprintf(['Enter the desired maximum script propagation time (sec):\n',...
                                     '(Minimum = 0.001 sec, Maximum = 3600 sec)\n', ...
                                     '(Script execution stops if the amount of time required to propagate the script exceeds this.)']);
                str = inputdlg(input_str, 'Maximum Script Execution Time', [1 75], {fullAccNum2Str(lvdData.settings.maxScriptPropTime)});
                if(isempty(str))
                    return;
                end
            
                str = str{1};
            
                if(checkStrIsNumeric(str) && str2double(str) >= 0.001 && str2double(str) <= 3600)
                    addUndoState(app, handles,'Edit Max Script Propagation Time');
            
                    writeOutput(sprintf('Setting maximum script execution time to %s sec.', str),'append');
            
                    lvdData.settings.maxScriptPropTime = str2double(str);
            
                    runScript(app, handles, lvdData, 1);
                    lvd_processData(handles);
                else
                    writeOutput(sprintf('Could not set the desired maximum script execution time.  "%s" is an invalid entry.', str),'append');
                    beep;
                end
        end

        % Button pushed function: moveEventDown
        function moveEventDown_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to moveEventDown (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Move Event Down');
            
                eventNum = get(handles.scriptListbox,'Value');
                lvdData.script.moveEvtAtIndexDown(eventNum);
            
                if(eventNum < length(lvdData.script.evts))
                    set(handles.scriptListbox,'Value',eventNum+1);
                end
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Button pushed function: moveEventUp
        function moveEventUp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to moveEventUp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                addUndoState(app, handles,'Move Event Up');
            
                eventNum = get(handles.scriptListbox,'Value');
                lvdData.script.moveEvtAtIndexUp(eventNum);
            
                if(eventNum > 1)
                    set(handles.scriptListbox,'Value',eventNum-1);
                end
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Menu selected function: newMissionPlanMenu
        function newMissionPlanMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to newMissionPlanMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                if(isMissionPlanSaved(app, handles))
                    askToClear = false;
                else
                    askToClear = true;
                end
                
                if(askToClear)
                    response = questdlg(['All unsaved work will be lost.  Continue?'],'Create new mission plan?','Yes','No','No');
                else
                    response = 'Yes';
                end
            
                if(~strcmpi(response,'Yes'))
                    return;
                end
            
                setappdata(handles.ma_LvdMainGUI,'undoRedo',LVD_UndoRedoStateSet());
            
                celBodyData = getCelBodyDataFromMainGui(app, handles);
                setappdata(handles.ma_LvdMainGUI,'celBodyData', celBodyData);
            
            %     celBodyData = getappdata(handles.ma_LvdMainGUI,'celBodyData');
                write_to_output_func = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
                application_title = getappdata(handles.ma_LvdMainGUI,'application_title');
                if(askToClear)
                    write_to_output_func('Creating new mission plan... ','append');
                end
            
                lvdData = LvdData.getDefaultLvdData(celBodyData);
                setappdata(handles.ma_LvdMainGUI,'lvdData',lvdData);
                setappdata(handles.ma_LvdMainGUI,'current_save_location','');
            
                setDeleteButtonEnable(app, lvdData, handles);
                setNonSeqDeleteButtonEnable(app, lvdData, handles);
            
                setappdata(handles.hDispAxesTimeSlider,'lastTime',NaN);
                timeSliderCb = @(src,evt) timeSliderStateChanged(src,evt, lvdData, handles);
                set(handles.hDispAxesTimeSlider, 'StateChangedCallback', timeSliderCb);
            
                enableDisableArrowButtons(app, lvdData, handles);
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
            
                set(handles.ma_LvdMainGUI,'Name', application_title);
            
                if(askToClear)
                    write_to_output_func(['Done.'],'appendSameLine'); %#ok<*NBRAK>
                end
            
                set(handles.newMissionPlanToolbar,'Enable','on');
                set(handles.openMissionPlanToolbar,'Enable','on');
                set(handles.saveMissionPlanToolbar,'Enable','on');
        end

        % Clicked callback: newMissionPlanToolbar
        function newMissionPlanToolbar_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to newMissionPlanToolbar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                set(hObject,'Enable','off');
                drawnow;
                newMissionPlanMenu_Callback(handles.newMissionPlanMenu, [], handles);
            
                set(handles.newMissionPlanToolbar,'Enable','on');
                set(handles.openMissionPlanToolbar,'Enable','on');
                set(handles.saveMissionPlanToolbar,'Enable','on');
        end

        % Value changed function: nonSeqEventsListbox
        function nonSeqEventsListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to nonSeqEventsListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns nonSeqEventsListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from nonSeqEventsListbox
                if(strcmpi(get(handles.ma_LvdMainGUI,'SelectionType'),'open'))
                    addUndoState(app, handles,'Edit Non-Sequential Event');
            
                    eventNum = get(hObject,'Value');
                    lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                    nonSeqEvt = lvdData.script.nonSeqEvts.getEventForInd(eventNum);
                    lvd_editNonSeqEventGUI(nonSeqEvt, lvdData);
            
                    runScript(app, handles, lvdData, 1);
                    lvd_processData(handles);
                end
        end

        % Menu selected function: openMissionPlanMenu
        function openMissionPlanMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to openMissionPlanMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                if(isMissionPlanSaved(app, handles))
                    askToClear = false;
                else
                    askToClear = true;
                end
            
                if(askToClear)
                    response = questdlg(['All unsaved work will be lost.  Continue?'],'Open mission plan?','Yes','No','No');
                else
                    response = 'Yes';
                end
            
                if(~strcmpi(response,'Yes'))
                    return;
                end
            
                celBodyData = getappdata(handles.ma_LvdMainGUI,'celBodyData');
                write_to_output_func = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
                application_title = getappdata(handles.ma_LvdMainGUI,'application_title');
                current_save_location = getappdata(handles.ma_LvdMainGUI,'current_save_location');
            
                if(isempty(current_save_location))
                    fileNameToDefaultTo = 'mission.mat';
                else
                    [current_save_location_path,~,~] = fileparts(current_save_location);
                    fileNameToDefaultTo = [current_save_location_path,filesep,'mission.mat'];
                end
            
                [FileName,PathName] = uigetfile({'*.mat','KSP TOT Launch Vehicle Designer Case File (*.mat)'},...
                                                            'Open Launch Vehicle Designer Case',...
                                                            fileNameToDefaultTo);
            	filePath = [PathName,FileName];
            
                if(ischar(filePath))
                    openMissionPlanFile(app, filePath, write_to_output_func, application_title, celBodyData, FileName, handles);
                end
            
                set(handles.newMissionPlanToolbar,'Enable','on');
                set(handles.openMissionPlanToolbar,'Enable','on');
                set(handles.saveMissionPlanToolbar,'Enable','on');
        end

        % Clicked callback: openMissionPlanToolbar
        function openMissionPlanToolbar_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to openMissionPlanToolbar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                set(hObject,'Enable','off');
                drawnow;
                openMissionPlanMenu_Callback(handles.openMissionPlanMenu, [], handles);
            
                set(handles.newMissionPlanToolbar,'Enable','on');
                set(handles.openMissionPlanToolbar,'Enable','on');
                set(handles.saveMissionPlanToolbar,'Enable','on');
        end

        % Menu selected function: optimizeMissionMenu
        function optimizeMissionMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to optimizeMissionMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                addUndoState(app, handles,'Optimize Mission');
            
                lvdData.optimizer.optimize(writeOutput, true);
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Menu selected function: perturbOptVarsMenu
        function perturbOptVarsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to perturbOptVarsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                %questdlg
                input_str = sprintf(['Enter the percentage amount to perturb the current solution by:\n',...
                                     '(Minimum = 0%%, Maximum = 100%%)\n',...
                                     '(Values greater than 5%%-10%% will likely cause the solution to become hard to recover with the optimizer.)']);
                str = inputdlg(input_str, 'Perturb Optimization Variables', [1 75], {num2str(0.01,'%4.2f')});
                if(isempty(str))
                    return;
                end
            
                str = str{1};
            
                if(checkStrIsNumeric(str) && str2double(str) >= 0 && str2double(str) <= 100)
                    addUndoState(app, handles,'Perturb Optim Vars');
            
                    writeOutput(sprintf('Perturbing optimization variables by %s%%.', str),'append');
                    pPct = str2double(str);
            
                    lvdData.optimizer.vars.perturbVarsAndUpdate(pPct);
            
                    runScript(app, handles, lvdData, 1);
                    lvd_processData(handles);
                else
                    writeOutput(sprintf('Could not perturb optimization variables.  "%s" is an invalid entry.', str),'append');
                    beep;
                end
        end

        % Menu selected function: popOutOrbitDisplayMenu
        function popOutOrbitDisplayMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popOutOrbitDisplayMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                hFig = figure('Visible','off');
                hAx = axes(hFig);
                inlineDispAxes = handles.dispAxes;
                handles.dispAxes = hAx;
                set(hAx,'UserData',get(handles.dispAxes,'UserData'));
            
                hFig.DeleteFcn = @(src,evt) popOutOrbitDispDelFcn(app, src,evt, inlineDispAxes, handles);
            
                wb = waitbar(0,'Popping out orbit display...');
                wbch = allchild(wb);
                jp = wbch(1).JavaPeer;
                jp.setIndeterminate(1);
            
                try
                    lvd_processData(handles);
                catch ME %#ok<NASGU> 
                    %nothing
                end
            
                title(hAx, handles.dispAxisTitleLabel.String);
            
                delete(wb);
                hFig.Visible = 'on';
            
                tf = cameratoolbar(handles.ma_LvdMainGUI, 'GetVisible');
                if(tf)
                    cameratoolbar(hFig, 'Show');
                end
        end

        % Menu selected function: redoMenu
        function redoMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to redoMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                undoRedo = getappdata(handles.ma_LvdMainGUI,'undoRedo');
                writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                [~, undoActionName] = undoRedo.shouldRedoMenuBeEnabled();
            
                writeOutput(sprintf('Redoing %s...',undoActionName),'append');
                lvdData = undoRedo.redo();
            
                if(not(isempty(lvdData)))
                    setappdata(handles.ma_LvdMainGUI,'lvdData',lvdData);
            
                    runScript(app, handles, lvdData, 1);
                    lvd_processData(handles);
                end
            
                curName = get(handles.ma_LvdMainGUI,'Name');
                if(~strcmpi(curName(end),'*'))
                    set(handles.ma_LvdMainGUI,'Name',[curName,'*']);
                end
            
                editMenu_Callback([], [], handles);
        end

        % Menu selected function: runScriptMenu
        function runScriptMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to runScriptMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                if(not(isdeployed))
            %         profile off; profile on;
                end
            
                propagateScript(app, handles, lvdData, 1);
                lvd_processData(handles);
            
                if(not(isdeployed))
            %         profile viewer;
                end
        end

        % Menu selected function: saveAsMissionPlanMenu
        function saveAsMissionPlanMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to saveAsMissionPlanMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                saveMissionAs(app, handles);
            
                set(handles.newMissionPlanToolbar,'Enable','on');
                set(handles.openMissionPlanToolbar,'Enable','on');
                set(handles.saveMissionPlanToolbar,'Enable','on');
        end

        % Menu selected function: saveMissionPlanMenu
        function saveMissionPlanMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to saveMissionPlanMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                current_save_location = getappdata(handles.ma_LvdMainGUI,'current_save_location');
                if(isempty(current_save_location))
                    saveMissionAs(app, handles);
                else
                    saveMission(app, handles,current_save_location);
                end
            
                set(handles.newMissionPlanToolbar,'Enable','on');
                set(handles.openMissionPlanToolbar,'Enable','on');
                set(handles.saveMissionPlanToolbar,'Enable','on');
        end

        % Clicked callback: saveMissionPlanToolbar
        function saveMissionPlanToolbar_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to saveMissionPlanToolbar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                set(hObject,'Enable','off');
                drawnow;
                saveMissionPlanMenu_Callback(handles.saveMissionPlanMenu, [], handles);
            
                set(handles.newMissionPlanToolbar,'Enable','on');
                set(handles.openMissionPlanToolbar,'Enable','on');
                set(handles.saveMissionPlanToolbar,'Enable','on');
        end

        % Value changed function: scriptListbox
        function scriptListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to scriptListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns scriptListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from scriptListbox
                if(strcmpi(get(handles.ma_LvdMainGUI,'SelectionType'),'open'))
                    addUndoState(app, handles,'Edit Event');
            
                    eventNum = get(hObject,'Value');
                    lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                    event = lvdData.script.getEventForInd(eventNum);
                    lvd_editEventGUI(event, false);
            
                    runScript(app, handles, lvdData, 1);
                    lvd_processData(handles);
                end
        end

        % Menu selected function: selectOptimizationAlgosMenu
        function selectOptimizationAlgosMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selectOptimizationAlgosMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                addUndoState(app, handles,'Edit Optimizer Algorithm');
            
                lvd_OptimizerSelectionGUI(lvdData);
            
                if(lvdData.optimizer.getSelectedOptimizer().usesParallel())
                    numWorkers = lvdData.optimizer.getSelectedOptimizer().getNumParaWorkers();
                    startParallelPool(app, writeOutput, numWorkers);
                end
        end

        % Menu selected function: 
        % setIntegrationStepSizeForAllEventsMenu
        function setIntegrationStepSizeForAllEventsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to setIntegrationStepSizeForAllEventsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                input_str = sprintf(['Enter the desired integration step size for all events:\n',...
                                     '(Minimum = 1E-6, Maximum = Inf)\n',...
                                     '(This will influence script execution speed.)']);
                str = inputdlg(input_str, 'Global Integrator Step Size', [1 75], {'1.0'});
                if(isempty(str))
                    return;
                end
            
                str = str{1};
            
                if(checkStrIsNumeric(str) && str2double(str) >= 1E-6 && str2double(str) <= Inf)
                    addUndoState(app, handles,'Set Global Integrator Step Size');
            
                    writeOutput(sprintf('Setting integrator step size on all events to %s.', str),'append');
            
                    stepSize = str2double(str);
                    totNumEvts = lvdData.script.getTotalNumOfEvents();
                    for(i=1:totNumEvts)
                        evt = lvdData.script.getEventForInd(i);
            
                        evt.integratorObj.getOptions().setIntegratorStepSize(stepSize);
                    end
            
                    runScript(app, handles, lvdData, 1);
                    lvd_processData(handles);
                else
                    writeOutput(sprintf('Could not set the desired integration step size on all events.  "%s" is an invalid entry.', str),'append');
                    beep;
                end
        end

        % Menu selected function: settingsMenu
        function settingsMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to settingsMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
            	isAutoProp = lvdData.settings.autoPropScript;
                if(isAutoProp)
                    set(handles.autopropagateMenu, 'Checked', 'on');
                else
                    set(handles.autopropagateMenu, 'Checked', 'off');
                end
            
            	disEvtPropTimes = lvdData.settings.disEvtPropTimes;
                if(disEvtPropTimes)
                    set(handles.displayEvtPropTimesInLogFileMenu, 'Checked', 'on');
                else
                    set(handles.displayEvtPropTimesInLogFileMenu, 'Checked', 'off');
                end
        end

        % Menu selected function: showConstrJacobianHeatMapMenu
        function showConstrJacobianHeatMapMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to showConstrJacobianHeatMapMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                constrLbls = lvdData.optimizer.constraints.getListboxStr();
                [x0All, ~, varLbls] = lvdData.optimizer.vars.getTotalScaledXVector();
            
                if(~isempty(constrLbls) && ~isempty(varLbls))
                    hHlpDlg = helpdlg('Computing constraint Jacobian with current trajectory.  Please wait...','Computing Jacobian');
            
                    try
                        evtToStartScriptExecAt = lvdData.script.getEventForInd(1);
                        nonlcon = @(x) lvdData.optimizer.constraints.evalConstraints(x, true, evtToStartScriptExecAt, false);
                        jacNonlcon = @(x) computeJacNonlconFunc(app, x, nonlcon);
            
            %             jac = abs(mklJac(jacNonlcon, x0All));
            
                        cAtX0 = jacNonlcon(x0All);
            
                        if(not(isempty(gcp('nocreate'))))
                            useParallel = true;
                        else
                            useParallel = false;
                        end
            %             useParallel = false;
            
                        finiteDiffMeth = CustomFiniteDiffsCalculationMethod();
                        jac = abs(finiteDiffMeth.computeJacobian(jacNonlcon, x0All, cAtX0, useParallel));
            
                        zeroColInds = find(all(jac < eps));
                        totalColInds = 1:1:size(jac,2);
                        nonZeroColInds = setdiff(totalColInds,zeroColInds);
            
                        filteredJac = jac(:,nonZeroColInds);
                        filteredVarLbls = varLbls(nonZeroColInds);
            
                        [c, ceq, ~, ~, ~, ~, ~, ~, ~, ~] = nonlcon(x0All);
                        heatMapConstrLbls = {};
                        totNumIneqConstr = length(c)/2;
                        totNumConstr = totNumIneqConstr + length(ceq);
                        for(i=1:totNumIneqConstr)
                            heatMapConstrLbls{end+1} = sprintf('%s (Lwr Bnd)', constrLbls{i}); %#ok<AGROW>
                            heatMapConstrLbls{end+1} = sprintf('%s (Upr Bnd)', constrLbls{i}); %#ok<AGROW>
                        end
            
                        heatMapConstrLbls = horzcat(heatMapConstrLbls, constrLbls(totNumIneqConstr+1:totNumConstr));
            
                        hFig = figure();
                        hHeatMap = heatmap(hFig, filteredVarLbls,heatMapConstrLbls,filteredJac);
            
                        hHeatMap.Title = {'Increase the scale factor of','any constraint with values >> 1'};
                        hHeatMap.XLabel = 'Variables';
                        hHeatMap.YLabel = 'Constraints';
                    catch ME
                        errordlg(sprintf('The following error was encountered while trying to compute the Jacobian:\n\n %s',ME.message));
                    end
            
                    if(ishandle(hHlpDlg) && isvalid(hHlpDlg))
                        close(hHlpDlg);
                    end
                end
        end

        % Menu selected function: sparseIntegratorOutputMenu
        function sparseIntegratorOutputMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sparseIntegratorOutputMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                addUndoState(app, handles,'Toggle Sparse Output');
            
                if strcmp(get(gcbo, 'Checked'),'on')
                    set(gcbo, 'Checked', 'off');
                    lvdData.settings.isSparseOutput = false;
                    writeOutput('Sparse output mode disabled.  Full integrator output will be provided and plotted.','append');
                else
                    set(gcbo, 'Checked', 'on');
                    lvdData.settings.isSparseOutput = true;
                    writeOutput('Sparse output mode enabled.  Only first and last states from each event will be provided and plotted.','append');
                end
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
        end

        % Callback function
        function timeSliderPanel_SizeChangedFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to timeSliderPanel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                if(not(isempty(handles)))
                    panelPos = hObject.Position;
                    hDispAxesTimeSliderJavaWrapperPos = handles.hDispAxesTimeSliderJavaWrapper.Position;
                    hDispAxesTimeSliderJavaWrapperPos(3:4) = panelPos(3:4);
                    handles.hDispAxesTimeSliderJavaWrapper.Position = hDispAxesTimeSliderJavaWrapperPos;
                end
        end

        % Clicked callback: toggleCameraToolbar
        function toggleCameraToolbar_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to toggleCameraToolbar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                cameratoolbar(handles.ma_LvdMainGUI, 'Toggle');
        end

        % Menu selected function: toggleOptimForSelEventMenu
        function toggleOptimForSelEventMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to toggleOptimForSelEventMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                eventNum = get(handles.scriptListbox,'Value');
                event = lvdData.script.getEventForInd(eventNum);
            
                if(not(isempty(event)))
                    addUndoState(app, handles,sprintf('Toggle Optimization on Event %u', eventNum));
                    event.toggleOptimDisable(lvdData);
            
                    lvd_processData(handles);
                end
        end

        % Menu selected function: undoMenu
        function undoMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to undoMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                undoRedo = getappdata(handles.ma_LvdMainGUI,'undoRedo');
                writeOutput = getappdata(handles.ma_LvdMainGUI,'write_to_output_func');
            
                [~, undoActionName] = undoRedo.shouldUndoMenuBeEnabled();
            
                writeOutput(sprintf('Undoing %s...',undoActionName),'append');
                lvdData = undoRedo.undo(lvdData);
            
                setappdata(handles.ma_LvdMainGUI,'lvdData',lvdData);
            
                runScript(app, handles, lvdData, 1);
                lvd_processData(handles);
            
                curName = get(handles.ma_LvdMainGUI,'Name');
                if(~strcmpi(curName(end),'*'))
                    set(handles.ma_LvdMainGUI,'Name',[curName,'*']);
                end
            
                editMenu_Callback([], [], handles);
        end

        % Menu selected function: uploadImpDvActionToKspMenu
        function uploadImpDvActionToKspMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to uploadImpDvActionToKspMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                eventNum = handles.scriptListbox.Value;
                event = lvdData.script.getEventForInd(eventNum);
                actions = event.actions;
            
                bool = arrayfun(@(x) isa(x,'AddDeltaVAction'), actions);
                if(any(bool)) %there's at least one delta-v action
                    if(sum(bool) == 1) %there's one delta-v action
                        deltaVAction = actions(bool);
                    else               %there's more than one delta-v action
                        actionsListBoxStr = event.getActionsListboxStr();
                        actionsListBoxStr = actionsListBoxStr(bool);
                        dvActions = actions(bool);
            
                        [Selection,ok] = listdlgARH('ListString',actionsListBoxStr, ...
                                                    'SelectionMode', 'single', ...
                                                    'ListSize', [300 300], ...
                                                    'Name', 'Select Delta-V Action', ...
                                                    'PromptString', 'Select Delta-V Action to Upload:');
                        if(ok)
                            deltaVAction = dvActions(Selection);
                        else
                            return;
                        end
                    end
            
                else
                    errordlg('Error: Selected event does not have an impulsive delta-v action.','Upload Failed!');
                    return;
                end
            
                subStateLog = lvdData.stateLog.getAllStateLogEntriesForEvent(event);
                preActionsStateLogEntry = subStateLog(end-1);
            
                data = deltaVAction.getUploadDvToKspData(preActionsStateLogEntry);
                uploadManeuverToKSP(data);
        end

        % Menu selected function: viewMenu
        function viewMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to viewMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
                viewProfiles = lvdData.viewSettings.getProfilesArray();
            
                oldMenus = handles.setActiveViewProfileMenu.Children;
                for(i=1:length(oldMenus))
                    delete(oldMenus(i));
                end
            
                for(i=1:length(viewProfiles))
                    profile = viewProfiles(i);
            
                    if(lvdData.viewSettings.isProfileActive(profile))
                        checkedTxt = 'on';
                    else
                        checkedTxt = 'off';
                    end
            
                    cbFh = @(src,evt) viewProfileMenuSelectedCallback(app, src,evt, profile, lvdData.viewSettings, handles);
            
                    menus(i) = uimenu(handles.setActiveViewProfileMenu, 'Text',profile.name, ...
                                                                        'Checked',checkedTxt, ...
                                                                        'MenuSelectedFcn',cbFh); %#ok<AGROW,NASGU>
                end
        end

        % Menu selected function: viewStateAfterSelectedEventMenu
        function viewStateAfterSelectedEventMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to viewStateAfterSelectedEventMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                lvdData = getappdata(handles.ma_LvdMainGUI,'lvdData');
            
                eventNum = handles.scriptListbox.Value;
                stateLog = lvdData.stateLog.getMAFormattedStateLogMatrix(true);
            
                state = stateLog(stateLog(:,13)==eventNum,:);
                state = state(end,:);
            
            %     propNames = {'Liquid Fuel/Ox','Monopropellant','Xenon'};
                propNames = lvdData.launchVehicle.tankTypes.getFirstThreeTypesCellArr();
                viewSpacecraftStatePopupGUI(propNames, state, eventNum, lvdData.celBodyData);
        end

        % Value changed function: warnAlertsSlider
        function warnAlertsSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to warnAlertsSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
                updateWarnErrorLabels(app, handles, false);
        end

        % Size changed function: warningAlertPanel
        function warningAlertPanel_SizeChangedFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to warningAlertPanel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                if(not(isempty(handles)))
                    panelPos = hObject.Position;
            
                    warnAlertsSliderOffsetFromRight = getappdata(hObject,'warnAlertsSliderOffsetFromRight');
                    warnAlertsSliderPos = handles.warnAlertsSlider.Position;
                    warnAlertsSliderPos(1) = panelPos(3) - warnAlertsSliderPos(3) - warnAlertsSliderOffsetFromRight;
                    handles.warnAlertsSlider.Position = warnAlertsSliderPos;
            
                    warningLblOffsetFromRight = getappdata(hObject,'warningLblOffsetFromRight');
                    warningLblPos = handles.warning1Lbl.Position;
                    newWarnLblWidth = panelPos(3) - warningLblPos(1) - warningLblOffsetFromRight;
                    warningLblPos(3) = newWarnLblWidth;
                    handles.warning1Lbl.Position = warningLblPos;
                    handles.warning1Lbl.String = strWrapForLabel(handles.warning1Lbl,  handles.warning1Lbl.TooltipString);
            
                    warningLblPos = handles.warning2Lbl.Position;
                    warningLblPos(3) = newWarnLblWidth;
                    handles.warning2Lbl.Position = warningLblPos;
                    handles.warning2Lbl.String = strWrapForLabel(handles.warning2Lbl,  handles.warning2Lbl.TooltipString);
            
                    warningLblPos = handles.warning3Lbl.Position;
                    warningLblPos(3) = newWarnLblWidth;
                    handles.warning3Lbl.Position = warningLblPos;
                    handles.warning3Lbl.String = strWrapForLabel(handles.warning3Lbl,  handles.warning3Lbl.TooltipString);
            
                    warningLblPos = handles.warning4Lbl.Position;
                    warningLblPos(3) = newWarnLblWidth;
                    handles.warning4Lbl.Position = warningLblPos;
                    handles.warning4Lbl.String = strWrapForLabel(handles.warning4Lbl,  handles.warning4Lbl.TooltipString);
            
                    warningLblPos = handles.warning5Lbl.Position;
                    warningLblPos(3) = newWarnLblWidth;
                    handles.warning5Lbl.Position = warningLblPos;
                    handles.warning5Lbl.String = strWrapForLabel(handles.warning5Lbl,  handles.warning5Lbl.TooltipString);
            
                    warningLblPos = handles.warning6Lbl.Position;
                    warningLblPos(3) = newWarnLblWidth;
                    handles.warning6Lbl.Position = warningLblPos;
                    handles.warning6Lbl.String = strWrapForLabel(handles.warning6Lbl,  handles.warning6Lbl.TooltipString);
                end
        end

        % Clicked callback: uitoggletool4
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool4.State;
            zoomModeObject = zoom(app.ma_LvdMainGUI);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: uitoggletool3
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            zoomModeObject = zoom(app.ma_LvdMainGUI);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            pan(app.ma_LvdMainGUI, char(state));
        end

        % Clicked callback: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable rotation based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            rotate3d(app.ma_LvdMainGUI, char(state));
        end

        % Callback function
        function DispAxesTimeSliderValueChanging(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ma_LvdMainGUI = uifigure('Visible', 'off');
            app.ma_LvdMainGUI.Name = 'KSP TOT Launch Vehicle Designer';
            app.ma_LvdMainGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.ma_LvdMainGUI.CloseRequestFcn = createCallbackFcn(app, @ma_LvdMainGUI_CloseRequestFcn, true);
            app.ma_LvdMainGUI.SizeChangedFcn = createCallbackFcn(app, @ma_LvdMainGUI_SizeChangedFcn, true);
            app.ma_LvdMainGUI.WindowKeyPressFcn = createCallbackFcn(app, @ma_LvdMainGUI_WindowKeyPressFcn, true);
            app.ma_LvdMainGUI.Tag = 'ma_LvdMainGUI';
            app.fileMenu = uimenu(app.ma_LvdMainGUI);
            app.fileMenu.MenuSelectedFcn = createCallbackFcn(app, @fileMenu_Callback, true);
            app.fileMenu.Text = 'File';
            app.fileMenu.Tag = 'fileMenu';
            app.newMissionPlanMenu = uimenu(app.fileMenu);
            app.newMissionPlanMenu.MenuSelectedFcn = createCallbackFcn(app, @newMissionPlanMenu_Callback, true);
            app.newMissionPlanMenu.Accelerator = 'N';
            app.newMissionPlanMenu.Text = 'New Mission Plan';
            app.newMissionPlanMenu.Tag = 'newMissionPlanMenu';
            app.openMissionPlanMenu = uimenu(app.fileMenu);
            app.openMissionPlanMenu.MenuSelectedFcn = createCallbackFcn(app, @openMissionPlanMenu_Callback, true);
            app.openMissionPlanMenu.Accelerator = 'L';
            app.openMissionPlanMenu.Text = 'Open Mission Plan';
            app.openMissionPlanMenu.Tag = 'openMissionPlanMenu';
            app.saveMissionPlanMenu = uimenu(app.fileMenu);
            app.saveMissionPlanMenu.MenuSelectedFcn = createCallbackFcn(app, @saveMissionPlanMenu_Callback, true);
            app.saveMissionPlanMenu.Accelerator = 'S';
            app.saveMissionPlanMenu.Text = 'Save Mission Plan';
            app.saveMissionPlanMenu.Tag = 'saveMissionPlanMenu';
            app.saveAsMissionPlanMenu = uimenu(app.fileMenu);
            app.saveAsMissionPlanMenu.MenuSelectedFcn = createCallbackFcn(app, @saveAsMissionPlanMenu_Callback, true);
            app.saveAsMissionPlanMenu.Text = 'Save Mission Plan As...';
            app.saveAsMissionPlanMenu.Tag = 'saveAsMissionPlanMenu';
            app.exitMenu = uimenu(app.fileMenu);
            app.exitMenu.MenuSelectedFcn = createCallbackFcn(app, @exitMenu_Callback, true);
            app.exitMenu.Separator = 'on';
            app.exitMenu.Text = 'Exit Launch Vehicle Designer';
            app.exitMenu.Tag = 'exitMenu';
            app.openRecentMissionPlansMenu = uimenu(app.fileMenu);
            app.openRecentMissionPlansMenu.Separator = 'on';
            app.openRecentMissionPlansMenu.Text = 'Open Recent Mission Plans';
            app.openRecentMissionPlansMenu.Tag = 'openRecentMissionPlansMenu';
            app.editMenu = uimenu(app.ma_LvdMainGUI);
            app.editMenu.MenuSelectedFcn = createCallbackFcn(app, @editMenu_Callback, true);
            app.editMenu.Text = 'Edit';
            app.editMenu.Tag = 'editMenu';
            app.undoMenu = uimenu(app.editMenu);
            app.undoMenu.MenuSelectedFcn = createCallbackFcn(app, @undoMenu_Callback, true);
            app.undoMenu.Accelerator = 'Z';
            app.undoMenu.Text = 'Undo';
            app.undoMenu.Tag = 'undoMenu';
            app.redoMenu = uimenu(app.editMenu);
            app.redoMenu.MenuSelectedFcn = createCallbackFcn(app, @redoMenu_Callback, true);
            app.redoMenu.Accelerator = 'Y';
            app.redoMenu.Text = 'Redo';
            app.redoMenu.Tag = 'redoMenu';
            app.editMissionNotesMenu = uimenu(app.editMenu);
            app.editMissionNotesMenu.MenuSelectedFcn = createCallbackFcn(app, @editMissionNotesMenu_Callback, true);
            app.editMissionNotesMenu.Separator = 'on';
            app.editMissionNotesMenu.Text = 'Mission Notes';
            app.editMissionNotesMenu.Tag = 'editMissionNotesMenu';
            app.viewMenu = uimenu(app.ma_LvdMainGUI);
            app.viewMenu.MenuSelectedFcn = createCallbackFcn(app, @viewMenu_Callback, true);
            app.viewMenu.Text = 'View';
            app.viewMenu.Tag = 'viewMenu';
            app.editViewSettingsMenu = uimenu(app.viewMenu);
            app.editViewSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @editViewSettingsMenu_Callback, true);
            app.editViewSettingsMenu.Text = 'Edit View Settings';
            app.editViewSettingsMenu.Tag = 'editViewSettingsMenu';
            app.setActiveViewProfileMenu = uimenu(app.viewMenu);
            app.setActiveViewProfileMenu.Text = 'Select Active View Profile';
            app.setActiveViewProfileMenu.Tag = 'setActiveViewProfileMenu';
            app.popOutOrbitDisplayMenu = uimenu(app.viewMenu);
            app.popOutOrbitDisplayMenu.MenuSelectedFcn = createCallbackFcn(app, @popOutOrbitDisplayMenu_Callback, true);
            app.popOutOrbitDisplayMenu.Separator = 'on';
            app.popOutOrbitDisplayMenu.Text = 'Pop Out Orbit Display';
            app.popOutOrbitDisplayMenu.Tag = 'popOutOrbitDisplayMenu';
            app.scenarioMenu = uimenu(app.ma_LvdMainGUI);
            app.scenarioMenu.Text = 'Scenario';
            app.scenarioMenu.Tag = 'scenarioMenu';
            app.editLaunchVehicleMenu = uimenu(app.scenarioMenu);
            app.editLaunchVehicleMenu.MenuSelectedFcn = createCallbackFcn(app, @editLaunchVehicleMenu_Callback, true);
            app.editLaunchVehicleMenu.Text = 'Edit Vehicle Configuration';
            app.editLaunchVehicleMenu.Tag = 'editLaunchVehicleMenu';
            app.editInitialStateMenu = uimenu(app.scenarioMenu);
            app.editInitialStateMenu.MenuSelectedFcn = createCallbackFcn(app, @editInitialStateMenu_Callback, true);
            app.editInitialStateMenu.Text = 'Edit Initial State';
            app.editInitialStateMenu.Tag = 'editInitialStateMenu';
            app.editStopwatchesMenu = uimenu(app.scenarioMenu);
            app.editStopwatchesMenu.MenuSelectedFcn = createCallbackFcn(app, @editStopwatchesMenu_Callback, true);
            app.editStopwatchesMenu.Separator = 'on';
            app.editStopwatchesMenu.Text = 'Edit Stopwatches';
            app.editStopwatchesMenu.Tag = 'editStopwatchesMenu';
            app.editExtremaMenu = uimenu(app.scenarioMenu);
            app.editExtremaMenu.MenuSelectedFcn = createCallbackFcn(app, @editExtremaMenu_Callback, true);
            app.editExtremaMenu.Text = 'Edit Extrema';
            app.editExtremaMenu.Tag = 'editExtremaMenu';
            app.editCalculusObjectsMenu = uimenu(app.scenarioMenu);
            app.editCalculusObjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @editCalculusObjectsMenu_Callback, true);
            app.editCalculusObjectsMenu.Text = 'Edit Calculus Calculations';
            app.editCalculusObjectsMenu.Tag = 'editCalculusObjectsMenu';
            app.editGroundObjsMenu = uimenu(app.scenarioMenu);
            app.editGroundObjsMenu.MenuSelectedFcn = createCallbackFcn(app, @editGroundObjsMenu_Callback, true);
            app.editGroundObjsMenu.Separator = 'on';
            app.editGroundObjsMenu.Text = 'Edit Ground Objects';
            app.editGroundObjsMenu.Tag = 'editGroundObjsMenu';
            app.geometryMenu = uimenu(app.scenarioMenu);
            app.geometryMenu.Separator = 'on';
            app.geometryMenu.Text = 'Geometry';
            app.geometryMenu.Tag = 'geometryMenu';
            app.editPointsMenu = uimenu(app.geometryMenu);
            app.editPointsMenu.MenuSelectedFcn = createCallbackFcn(app, @editPointsMenu_Callback, true);
            app.editPointsMenu.Text = 'Edit Points';
            app.editPointsMenu.Tag = 'editPointsMenu';
            app.editVectorsMenu = uimenu(app.geometryMenu);
            app.editVectorsMenu.MenuSelectedFcn = createCallbackFcn(app, @editVectorsMenu_Callback, true);
            app.editVectorsMenu.Text = 'Edit Vectors';
            app.editVectorsMenu.Tag = 'editVectorsMenu';
            app.editAnglesMenu = uimenu(app.geometryMenu);
            app.editAnglesMenu.MenuSelectedFcn = createCallbackFcn(app, @editAnglesMenu_Callback, true);
            app.editAnglesMenu.Text = 'Edit Angles';
            app.editAnglesMenu.Tag = 'editAnglesMenu';
            app.editPlanesMenu = uimenu(app.geometryMenu);
            app.editPlanesMenu.MenuSelectedFcn = createCallbackFcn(app, @editPlanesMenu_Callback, true);
            app.editPlanesMenu.Text = 'Edit Planes';
            app.editPlanesMenu.Tag = 'editPlanesMenu';
            app.editCoordSysMenu = uimenu(app.geometryMenu);
            app.editCoordSysMenu.MenuSelectedFcn = createCallbackFcn(app, @editCoordSysMenu_Callback, true);
            app.editCoordSysMenu.Text = 'Edit Coordinate Systems';
            app.editCoordSysMenu.Tag = 'editCoordSysMenu';
            app.editRefRamesMenu = uimenu(app.geometryMenu);
            app.editRefRamesMenu.MenuSelectedFcn = createCallbackFcn(app, @editRefRamesMenu_Callback, true);
            app.editRefRamesMenu.Text = 'Edit Reference Frames';
            app.editRefRamesMenu.Tag = 'editRefRamesMenu';
            app.simulationMenu = uimenu(app.ma_LvdMainGUI);
            app.simulationMenu.Text = 'Simulation';
            app.simulationMenu.Tag = 'simulationMenu';
            app.runScriptMenu = uimenu(app.simulationMenu);
            app.runScriptMenu.MenuSelectedFcn = createCallbackFcn(app, @runScriptMenu_Callback, true);
            app.runScriptMenu.Accelerator = 'P';
            app.runScriptMenu.Text = 'Run Script';
            app.runScriptMenu.Tag = 'runScriptMenu';
            app.createkOSExecCodeMenu = uimenu(app.simulationMenu);
            app.createkOSExecCodeMenu.MenuSelectedFcn = createCallbackFcn(app, @createkOSExecCodeMenu_Callback, true);
            app.createkOSExecCodeMenu.Separator = 'on';
            app.createkOSExecCodeMenu.Text = 'Create kOS Control CSV File';
            app.createkOSExecCodeMenu.Tag = 'createkOSExecCodeMenu';
            app.optimizationMenu = uimenu(app.ma_LvdMainGUI);
            app.optimizationMenu.Text = 'Optimization';
            app.optimizationMenu.Tag = 'optimizationMenu';
            app.editObjFunctionMenu = uimenu(app.optimizationMenu);
            app.editObjFunctionMenu.MenuSelectedFcn = createCallbackFcn(app, @editObjFunctionMenu_Callback, true);
            app.editObjFunctionMenu.Text = 'Edit Objective Function';
            app.editObjFunctionMenu.Tag = 'editObjFunctionMenu';
            app.editConstraintsMenu = uimenu(app.optimizationMenu);
            app.editConstraintsMenu.MenuSelectedFcn = createCallbackFcn(app, @editConstraintsMenu_Callback, true);
            app.editConstraintsMenu.Text = 'Edit Constraints';
            app.editConstraintsMenu.Tag = 'editConstraintsMenu';
            app.perturbOptVarsMenu = uimenu(app.optimizationMenu);
            app.perturbOptVarsMenu.MenuSelectedFcn = createCallbackFcn(app, @perturbOptVarsMenu_Callback, true);
            app.perturbOptVarsMenu.Separator = 'on';
            app.perturbOptVarsMenu.Text = 'Perturb Optimization Variables';
            app.perturbOptVarsMenu.Tag = 'perturbOptVarsMenu';
            app.adjustVariablesMenu = uimenu(app.optimizationMenu);
            app.adjustVariablesMenu.MenuSelectedFcn = createCallbackFcn(app, @adjustVariablesMenu_Callback, true);
            app.adjustVariablesMenu.Text = 'Adjust Variables';
            app.adjustVariablesMenu.Tag = 'adjustVariablesMenu';
            app.showConstrJacobianHeatMapMenu = uimenu(app.optimizationMenu);
            app.showConstrJacobianHeatMapMenu.MenuSelectedFcn = createCallbackFcn(app, @showConstrJacobianHeatMapMenu_Callback, true);
            app.showConstrJacobianHeatMapMenu.Separator = 'on';
            app.showConstrJacobianHeatMapMenu.Text = 'Show Constraint Jacobian Heat Map';
            app.showConstrJacobianHeatMapMenu.Tag = 'showConstrJacobianHeatMapMenu';
            app.optimizeMissionMenu = uimenu(app.optimizationMenu);
            app.optimizeMissionMenu.MenuSelectedFcn = createCallbackFcn(app, @optimizeMissionMenu_Callback, true);
            app.optimizeMissionMenu.Separator = 'on';
            app.optimizeMissionMenu.Accelerator = 'O';
            app.optimizeMissionMenu.Text = 'Optimize Mission';
            app.optimizeMissionMenu.Tag = 'optimizeMissionMenu';
            app.pluginsMenu = uimenu(app.ma_LvdMainGUI);
            app.pluginsMenu.Text = 'Plugins';
            app.pluginsMenu.Tag = 'pluginsMenu';
            app.managePluginsMenu = uimenu(app.pluginsMenu);
            app.managePluginsMenu.MenuSelectedFcn = createCallbackFcn(app, @managePluginsMenu_Callback, true);
            app.managePluginsMenu.Text = 'Manage Plugins';
            app.managePluginsMenu.Tag = 'managePluginsMenu';
            app.settingsMenu = uimenu(app.ma_LvdMainGUI);
            app.settingsMenu.MenuSelectedFcn = createCallbackFcn(app, @settingsMenu_Callback, true);
            app.settingsMenu.Text = 'Settings';
            app.settingsMenu.Tag = 'settingsMenu';
            app.integrationSettingsMenu = uimenu(app.settingsMenu);
            app.integrationSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @integrationSettingsMenu_Callback, true);
            app.integrationSettingsMenu.Text = 'Integration Settings';
            app.integrationSettingsMenu.Tag = 'integrationSettingsMenu';
            app.intMinAltitudeMenu = uimenu(app.integrationSettingsMenu);
            app.intMinAltitudeMenu.MenuSelectedFcn = createCallbackFcn(app, @intMinAltitudeMenu_Callback, true);
            app.intMinAltitudeMenu.Text = 'Minimum Altitude';
            app.intMinAltitudeMenu.Tag = 'intMinAltitudeMenu';
            app.intMaxSimTimeMenu = uimenu(app.integrationSettingsMenu);
            app.intMaxSimTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @intMaxSimTimeMenu_Callback, true);
            app.intMaxSimTimeMenu.Text = 'Max. Simulation Time';
            app.intMaxSimTimeMenu.Tag = 'intMaxSimTimeMenu';
            app.maxScriptPropTimeMenu = uimenu(app.integrationSettingsMenu);
            app.maxScriptPropTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @maxScriptPropTimeMenu_Callback, true);
            app.maxScriptPropTimeMenu.Text = 'Max. Script Propagation Time';
            app.maxScriptPropTimeMenu.Tag = 'maxScriptPropTimeMenu';
            app.sparseIntegratorOutputMenu = uimenu(app.integrationSettingsMenu);
            app.sparseIntegratorOutputMenu.MenuSelectedFcn = createCallbackFcn(app, @sparseIntegratorOutputMenu_Callback, true);
            app.sparseIntegratorOutputMenu.Text = 'Sparse Integrator Output';
            app.sparseIntegratorOutputMenu.Tag = 'sparseIntegratorOutputMenu';
            app.setIntegrationStepSizeForAllEventsMenu = uimenu(app.integrationSettingsMenu);
            app.setIntegrationStepSizeForAllEventsMenu.MenuSelectedFcn = createCallbackFcn(app, @setIntegrationStepSizeForAllEventsMenu_Callback, true);
            app.setIntegrationStepSizeForAllEventsMenu.Separator = 'on';
            app.setIntegrationStepSizeForAllEventsMenu.Text = 'Set Integration Step Size for All Events';
            app.setIntegrationStepSizeForAllEventsMenu.Tag = 'setIntegrationStepSizeForAllEventsMenu';
            app.optimSettingsMenu = uimenu(app.settingsMenu);
            app.optimSettingsMenu.Text = 'Optimization Settings';
            app.optimSettingsMenu.Tag = 'optimSettingsMenu';
            app.selectOptimizationAlgosMenu = uimenu(app.optimSettingsMenu);
            app.selectOptimizationAlgosMenu.MenuSelectedFcn = createCallbackFcn(app, @selectOptimizationAlgosMenu_Callback, true);
            app.selectOptimizationAlgosMenu.Text = 'Select Optimization Algorithms';
            app.selectOptimizationAlgosMenu.Tag = 'selectOptimizationAlgosMenu';
            app.autopropagateMenu = uimenu(app.settingsMenu);
            app.autopropagateMenu.MenuSelectedFcn = createCallbackFcn(app, @autopropagateMenu_Callback, true);
            app.autopropagateMenu.Separator = 'on';
            app.autopropagateMenu.Checked = 'on';
            app.autopropagateMenu.Text = 'Auto-Propagate Script';
            app.autopropagateMenu.Tag = 'autopropagateMenu';
            app.displayEvtPropTimesInLogFileMenu = uimenu(app.settingsMenu);
            app.displayEvtPropTimesInLogFileMenu.MenuSelectedFcn = createCallbackFcn(app, @displayEvtPropTimesInLogFileMenu_Callback, true);
            app.displayEvtPropTimesInLogFileMenu.Text = 'Display Event Propagation Times in KSPTOT Log File';
            app.displayEvtPropTimesInLogFileMenu.Tag = 'displayEvtPropTimesInLogFileMenu';
            app.configureCelestialBodyStateCacheMenu = uimenu(app.settingsMenu);
            app.configureCelestialBodyStateCacheMenu.MenuSelectedFcn = createCallbackFcn(app, @configureCelestialBodyStateCacheMenu_Callback, true);
            app.configureCelestialBodyStateCacheMenu.Separator = 'on';
            app.configureCelestialBodyStateCacheMenu.Text = 'Configure Celestial Body State Cache';
            app.configureCelestialBodyStateCacheMenu.Tag = 'configureCelestialBodyStateCacheMenu';
            app.toolsMenu = uimenu(app.ma_LvdMainGUI);
            app.toolsMenu.Text = 'Tools';
            app.toolsMenu.Tag = 'toolsMenu';
            app.astroCalculatorsMenu = uimenu(app.toolsMenu);
            app.astroCalculatorsMenu.MenuSelectedFcn = createCallbackFcn(app, @astroCalculatorsMenu_Callback, true);
            app.astroCalculatorsMenu.Text = 'Astrodynamics Calculators';
            app.astroCalculatorsMenu.Tag = 'astroCalculatorsMenu';
            app.haloOrbitConstructorMenu = uimenu(app.toolsMenu);
            app.haloOrbitConstructorMenu.MenuSelectedFcn = createCallbackFcn(app, @haloOrbitConstructorMenu_Callback, true);
            app.haloOrbitConstructorMenu.Text = 'Halo Orbit Constructor';
            app.haloOrbitConstructorMenu.Tag = 'haloOrbitConstructorMenu';
            app.graphicalAnalysisMenu = uimenu(app.toolsMenu);
            app.graphicalAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @graphicalAnalysisMenu_Callback, true);
            app.graphicalAnalysisMenu.Separator = 'on';
            app.graphicalAnalysisMenu.Text = 'Graphical Analysis';
            app.graphicalAnalysisMenu.Tag = 'graphicalAnalysisMenu';
            app.celBodyCatalogMenu = uimenu(app.toolsMenu);
            app.celBodyCatalogMenu.MenuSelectedFcn = createCallbackFcn(app, @celBodyCatalogMenu_Callback, true);
            app.celBodyCatalogMenu.Separator = 'on';
            app.celBodyCatalogMenu.Text = 'Celestial Body Catalog';
            app.celBodyCatalogMenu.Tag = 'celBodyCatalogMenu';
            app.uitoolbar1 = uitoolbar(app.ma_LvdMainGUI);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.newMissionPlanToolbar = uipushtool(app.uitoolbar1);
            app.newMissionPlanToolbar.Tag = 'newMissionPlanToolbar';
            app.newMissionPlanToolbar.Tooltip = 'New Mission Plan';
            app.newMissionPlanToolbar.ClickedCallback = createCallbackFcn(app, @newMissionPlanToolbar_ClickedCallback, true);
            app.newMissionPlanToolbar.Icon = 'newMissionPlanToolbar_image.png';
            app.openMissionPlanToolbar = uipushtool(app.uitoolbar1);
            app.openMissionPlanToolbar.Tag = 'openMissionPlanToolbar';
            app.openMissionPlanToolbar.Tooltip = 'Open Mission Plan';
            app.openMissionPlanToolbar.ClickedCallback = createCallbackFcn(app, @openMissionPlanToolbar_ClickedCallback, true);
            app.openMissionPlanToolbar.Icon = 'openMissionPlanToolbar_image.png';
            app.saveMissionPlanToolbar = uipushtool(app.uitoolbar1);
            app.saveMissionPlanToolbar.Tag = 'saveMissionPlanToolbar';
            app.saveMissionPlanToolbar.Tooltip = 'Save Mission Plan';
            app.saveMissionPlanToolbar.ClickedCallback = createCallbackFcn(app, @saveMissionPlanToolbar_ClickedCallback, true);
            app.saveMissionPlanToolbar.Icon = 'saveMissionPlanToolbar_image.png';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Rotate 3D';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'uitoggletool1_image.png';
            app.uitoggletool1.Separator = 'on';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Pan';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = 'uitoggletool2_image.png';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Zoom In';
            app.uitoggletool3.ClickedCallback = createCallbackFcn(app, @uitoggletool3_ClickedCallback, true);
            app.uitoggletool3.Icon = 'uitoggletool3_image.png';
            app.uitoggletool4 = uitoggletool(app.uitoolbar1);
            app.uitoggletool4.Tag = 'uitoggletool4';
            app.uitoggletool4.Tooltip = 'Zoom Out';
            app.uitoggletool4.ClickedCallback = createCallbackFcn(app, @uitoggletool4_ClickedCallback, true);
            app.uitoggletool4.Icon = 'uitoggletool4_image.png';
            app.toggleCameraToolbar = uitoggletool(app.uitoolbar1);
            app.toggleCameraToolbar.Tag = 'toggleCameraToolbar';
            app.toggleCameraToolbar.Tooltip = 'Toggle Camera Toolbar';
            app.toggleCameraToolbar.ClickedCallback = createCallbackFcn(app, @toggleCameraToolbar_ClickedCallback, true);
            app.toggleCameraToolbar.Icon = 'toggleCameraToolbar_image.png';
            app.toggleCameraToolbar.Separator = 'on';
            app.dispAxesPanel = uipanel(app.ma_LvdMainGUI);
            app.dispAxesPanel.SizeChangedFcn = createCallbackFcn(app, @dispAxesPanel_SizeChangedFcn, true);
            app.dispAxesPanel.Tag = 'dispAxesPanel';
            app.dispAxes = uiaxes(app.dispAxesPanel);
            app.dispAxes.Tag = 'dispAxes';
            app.scriptResultsOutOfDateLbl = uilabel(app.dispAxesPanel);
            app.scriptResultsOutOfDateLbl.Tag = 'scriptResultsOutOfDateLbl';
            app.scriptResultsOutOfDateLbl.Visible = 'off';
            app.scriptResultsOutOfDateLbl.Tooltip = 'Mission Case has been updated but script is set to not autopropagate.  In-memory results of script execution will be out of date until the script is propagated (ctrl-p).';
            app.scriptResultsOutOfDateLbl.Text = 'Script execution results out of date.';
            app.plotWorkingLbl = uilabel(app.dispAxesPanel);
            app.plotWorkingLbl.Tag = 'plotWorkingLbl';
            app.plotWorkingLbl.Visible = 'off';
            app.plotWorkingLbl.Tooltip = 'Plot is rendering.';
            app.plotWorkingLbl.Text = 'Working...';
            app.titleText = uilabel(app.ma_LvdMainGUI);
            app.titleText.Tag = 'titleText';
            app.titleText.Text = {'KSP Trajectory Optimization Tool'; 'Launch Vehicle Designer'};
            app.uipanel1 = uipanel(app.ma_LvdMainGUI);
            app.uipanel1.Title = 'Output';
            app.uipanel1.Tag = 'uipanel1';
            app.outputText = uitextarea(app.uipanel1);
            app.outputText.Tag = 'outputText';
            app.initScStatePanel = uipanel(app.ma_LvdMainGUI);
            app.initScStatePanel.Title = 'Initial Spacecraft State';
            app.initScStatePanel.Tag = 'initScStatePanel';
            app.initialStateReadoutLabel = uilabel(app.initScStatePanel);
            app.initialStateReadoutLabel.Tag = 'initialStateReadoutLabel';
            app.initialStateReadoutLabel.Text = {'Universal Time (UT)     ='; '  '; 'Semi-major Axis         = '; 'Eccentricity            ='; 'Inclination             ='; 'Right Asc. of Asc. Node = '; 'Argument of Periapse    ='; 'True Anomaly            ='; '   '; 'Dry Mass                ='; 'Fuel/Ox Mass            ='; 'Monopropellant Mass     ='; 'Xenon Mass              ='; 'Total Mass              ='};
            app.finalScStatePanel = uipanel(app.ma_LvdMainGUI);
            app.finalScStatePanel.Title = 'Final Spacecraft State';
            app.finalScStatePanel.Tag = 'finalScStatePanel';
            app.finalStateReadoutLabel = uilabel(app.finalScStatePanel);
            app.finalStateReadoutLabel.Tag = 'finalStateReadoutLabel';
            app.finalStateReadoutLabel.Text = {'Universal Time (UT)     ='; '  '; 'Semi-major Axis         = '; 'Eccentricity            ='; 'Inclination             ='; 'Right Asc. of Asc. Node = '; 'Argument of Periapse    ='; 'True Anomaly            ='; '   '; 'Dry Mass                ='; 'Fuel/Ox Mass            ='; 'Monopropellant Mass     ='; 'Xenon Mass              ='; 'Total Mass              ='};
            app.dispAxisTitleLabel = uilabel(app.ma_LvdMainGUI);
            app.dispAxisTitleLabel.Tag = 'dispAxisTitleLabel';
            app.dispAxisTitleLabel.Text = {'Title'; 'Title'; 'Title'};
            app.decrOrbitToPlotNum = uibutton(app.ma_LvdMainGUI, 'push');
            app.decrOrbitToPlotNum.ButtonPushedFcn = createCallbackFcn(app, @decrOrbitToPlotNum_Callback, true);
            app.decrOrbitToPlotNum.Tag = 'decrOrbitToPlotNum';
            app.decrOrbitToPlotNum.Text = '←';
            app.incrOrbitToPlotNum = uibutton(app.ma_LvdMainGUI, 'push');
            app.incrOrbitToPlotNum.ButtonPushedFcn = createCallbackFcn(app, @incrOrbitToPlotNum_Callback, true);
            app.incrOrbitToPlotNum.Tag = 'incrOrbitToPlotNum';
            app.incrOrbitToPlotNum.Text = '→';
            app.warningAlertPanel = uipanel(app.ma_LvdMainGUI);
            app.warningAlertPanel.Title = 'Warnings & Alerts';
            app.warningAlertPanel.SizeChangedFcn = createCallbackFcn(app, @warningAlertPanel_SizeChangedFcn, true);
            app.warningAlertPanel.Tag = 'warningAlertPanel';
            app.warning1Lbl = uilabel(app.warningAlertPanel);
            app.warning1Lbl.Tag = 'warning1Lbl';
            app.warning1Lbl.Visible = 'off';
            app.warning1Lbl.Text = 'Warning 1';
            app.warning2Lbl = uilabel(app.warningAlertPanel);
            app.warning2Lbl.Tag = 'warning2Lbl';
            app.warning2Lbl.Visible = 'off';
            app.warning2Lbl.Text = 'Warning 1';
            app.warning3Lbl = uilabel(app.warningAlertPanel);
            app.warning3Lbl.Tag = 'warning3Lbl';
            app.warning3Lbl.Visible = 'off';
            app.warning3Lbl.Text = 'Warning 1';
            app.warning4Lbl = uilabel(app.warningAlertPanel);
            app.warning4Lbl.Tag = 'warning4Lbl';
            app.warning4Lbl.Visible = 'off';
            app.warning4Lbl.Text = 'Warning 1';
            app.warning5Lbl = uilabel(app.warningAlertPanel);
            app.warning5Lbl.Tag = 'warning5Lbl';
            app.warning5Lbl.Visible = 'off';
            app.warning5Lbl.Text = 'Warning 1';
            app.warning6Lbl = uilabel(app.warningAlertPanel);
            app.warning6Lbl.Tag = 'warning6Lbl';
            app.warning6Lbl.Visible = 'off';
            app.warning6Lbl.Text = 'Warning 1';
            app.warnAlertsSlider = uislider(app.warningAlertPanel);
            app.warnAlertsSlider.Limits = [0 1];
            app.warnAlertsSlider.MajorTicks = [];
            app.warnAlertsSlider.ValueChangedFcn = createCallbackFcn(app, @warnAlertsSlider_Callback, true);
            app.warnAlertsSlider.MinorTicks = [];
            app.warnAlertsSlider.Tag = 'warnAlertsSlider';
            app.warnAlertsSlider.Value = 1;
            app.timeSliderValueLabel = uilabel(app.ma_LvdMainGUI);
            app.timeSliderValueLabel.Tag = 'timeSliderValueLabel';
            app.timeSliderValueLabel.Text = 'Static Text';
            app.insertEventButton = uibutton(app.ma_LvdMainGUI, 'push');
            app.insertEventButton.ButtonPushedFcn = createCallbackFcn(app, @insertEventButton_Callback, true);
            app.insertEventButton.Tag = 'insertEventButton';
            app.insertEventButton.Text = 'Insert Sequential Event';
            app.scriptListbox = uilistbox(app.ma_LvdMainGUI);
            app.scriptListbox.Items = {};
            app.scriptListbox.ValueChangedFcn = createCallbackFcn(app, @scriptListbox_Callback, true);
            app.scriptListbox.Tag = 'scriptListbox';
            app.scriptListbox.Tooltip = 'The launch vehicle mission script.';
            app.scriptListbox.Value = {};
            app.scriptWorkingLbl = uilabel(app.ma_LvdMainGUI);
            app.scriptWorkingLbl.Tag = 'scriptWorkingLbl';
            app.scriptWorkingLbl.Visible = 'off';
            app.scriptWorkingLbl.Tooltip = 'Script is executing.';
            app.scriptWorkingLbl.Text = 'Working...';
            app.moveEventDown = uibutton(app.ma_LvdMainGUI, 'push');
            app.moveEventDown.ButtonPushedFcn = createCallbackFcn(app, @moveEventDown_Callback, true);
            app.moveEventDown.Tag = 'moveEventDown';
            app.moveEventDown.Tooltip = 'Move selected event down in the script.';
            app.moveEventDown.Text = '↓';
            app.deleteEvent = uibutton(app.ma_LvdMainGUI, 'push');
            app.deleteEvent.ButtonPushedFcn = createCallbackFcn(app, @deleteEvent_Callback, true);
            app.deleteEvent.Tag = 'deleteEvent';
            app.deleteEvent.Tooltip = 'Delete selected event from script.';
            app.deleteEvent.Text = 'Delete Event';
            app.moveEventUp = uibutton(app.ma_LvdMainGUI, 'push');
            app.moveEventUp.ButtonPushedFcn = createCallbackFcn(app, @moveEventUp_Callback, true);
            app.moveEventUp.Tag = 'moveEventUp';
            app.moveEventUp.Tooltip = 'Move selected event up in the script.';
            app.moveEventUp.Text = '↑';
            app.insertNonSeqEventButton = uibutton(app.ma_LvdMainGUI, 'push');
            app.insertNonSeqEventButton.ButtonPushedFcn = createCallbackFcn(app, @insertNonSeqEventButton_Callback, true);
            app.insertNonSeqEventButton.Tag = 'insertNonSeqEventButton';
            app.insertNonSeqEventButton.Text = 'Insert Non-Sequential Event';
            app.nonSeqEventsListbox = uilistbox(app.ma_LvdMainGUI);
            app.nonSeqEventsListbox.Items = {};
            app.nonSeqEventsListbox.ValueChangedFcn = createCallbackFcn(app, @nonSeqEventsListbox_Callback, true);
            app.nonSeqEventsListbox.Tag = 'nonSeqEventsListbox';
            app.nonSeqEventsListbox.Tooltip = 'Non-sequential events are events that can occur at any time during the mission.';
            app.nonSeqEventsListbox.Value = {};
            app.deleteNonSeqEventButton = uibutton(app.ma_LvdMainGUI, 'push');
            app.deleteNonSeqEventButton.ButtonPushedFcn = createCallbackFcn(app, @deleteNonSeqEventButton_Callback, true);
            app.deleteNonSeqEventButton.Tag = 'deleteNonSeqEventButton';
            app.deleteNonSeqEventButton.Tooltip = 'Delete selected event from non-sequential events list.';
            app.deleteNonSeqEventButton.Text = 'Delete Non-Sequential Event';
            app.uipanel5 = uipanel(app.ma_LvdMainGUI);
            app.uipanel5.Tag = 'uipanel5';
            app.DispAxesTimeSlider = uislider(app.ma_LvdMainGUI);
            app.DispAxesTimeSlider.MajorTicks = [];
            app.DispAxesTimeSlider.MinorTicks = [];
            app.DispAxesTimeSlider.Tooltip = {'''Adjust slider to view the location of vehicles and selected celestial bodies at a given time.'''};
            app.scriptListboxContextMenu = uicontextmenu(app.ma_LvdMainGUI);
            app.scriptListboxContextMenu.Tag = 'scriptListboxContextMenu';
            app.toggleOptimForSelEventMenu = uimenu(app.scriptListboxContextMenu);
            app.toggleOptimForSelEventMenu.MenuSelectedFcn = createCallbackFcn(app, @toggleOptimForSelEventMenu_Callback, true);
            app.toggleOptimForSelEventMenu.Text = 'Toggle Optimization for Selected Event (Cntrl-F)';
            app.toggleOptimForSelEventMenu.Tag = 'toggleOptimForSelEventMenu';
            app.viewStateAfterSelectedEventMenu = uimenu(app.scriptListboxContextMenu);
            app.viewStateAfterSelectedEventMenu.MenuSelectedFcn = createCallbackFcn(app, @viewStateAfterSelectedEventMenu_Callback, true);
            app.viewStateAfterSelectedEventMenu.Separator = 'on';
            app.viewStateAfterSelectedEventMenu.Text = 'View State After Selected Event';
            app.viewStateAfterSelectedEventMenu.Tag = 'viewStateAfterSelectedEventMenu';
            app.createContConstraintsWithSelEvtMenu = uimenu(app.scriptListboxContextMenu);
            app.createContConstraintsWithSelEvtMenu.MenuSelectedFcn = createCallbackFcn(app, @createContConstraintsWithSelEvtMenu_Callback, true);
            app.createContConstraintsWithSelEvtMenu.Separator = 'on';
            app.createContConstraintsWithSelEvtMenu.Text = 'Create Continuity Constraints with Selected Event';
            app.createContConstraintsWithSelEvtMenu.Tag = 'createContConstraintsWithSelEvtMenu';
            app.copyUtAtStartOfSelEventMenu = uimenu(app.scriptListboxContextMenu);
            app.copyUtAtStartOfSelEventMenu.MenuSelectedFcn = createCallbackFcn(app, @copyUtAtStartOfSelEventMenu_Callback, true);
            app.copyUtAtStartOfSelEventMenu.Separator = 'on';
            app.copyUtAtStartOfSelEventMenu.Text = 'Copy UT at Start of Selected Event';
            app.copyUtAtStartOfSelEventMenu.Tag = 'copyUtAtStartOfSelEventMenu';
            app.copyUtAtEndOfSelEventMenu = uimenu(app.scriptListboxContextMenu);
            app.copyUtAtEndOfSelEventMenu.MenuSelectedFcn = createCallbackFcn(app, @copyUtAtEndOfSelEventMenu_Callback, true);
            app.copyUtAtEndOfSelEventMenu.Text = 'Copy UT at End of Selected Event';
            app.copyUtAtEndOfSelEventMenu.Tag = 'copyUtAtEndOfSelEventMenu';
            app.copyDurationOfSelEventMenu = uimenu(app.scriptListboxContextMenu);
            app.copyDurationOfSelEventMenu.MenuSelectedFcn = createCallbackFcn(app, @copyDurationOfSelEventMenu_Callback, true);
            app.copyDurationOfSelEventMenu.Text = 'Copy Duration (sec) of Selected Event';
            app.copyDurationOfSelEventMenu.Tag = 'copyDurationOfSelEventMenu';
            app.copyOrbitAfterSelectedEventMenu = uimenu(app.scriptListboxContextMenu);
            app.copyOrbitAfterSelectedEventMenu.MenuSelectedFcn = createCallbackFcn(app, @copyOrbitAfterSelectedEventMenu_Callback, true);
            app.copyOrbitAfterSelectedEventMenu.Text = 'Copy Orbit After Selected Event';
            app.copyOrbitAfterSelectedEventMenu.Tag = 'copyOrbitAfterSelectedEventMenu';
            app.advanceScriptToSelectedEventMenu = uimenu(app.scriptListboxContextMenu);
            app.advanceScriptToSelectedEventMenu.MenuSelectedFcn = createCallbackFcn(app, @advanceScriptToSelectedEventMenu_Callback, true);
            app.advanceScriptToSelectedEventMenu.Separator = 'on';
            app.advanceScriptToSelectedEventMenu.Text = 'Advance Script to Selected Event';
            app.advanceScriptToSelectedEventMenu.Tag = 'advanceScriptToSelectedEventMenu';
            app.uploadImpDvActionToKspMenu = uimenu(app.scriptListboxContextMenu);
            app.uploadImpDvActionToKspMenu.MenuSelectedFcn = createCallbackFcn(app, @uploadImpDvActionToKspMenu_Callback, true);
            app.uploadImpDvActionToKspMenu.Separator = 'on';
            app.uploadImpDvActionToKspMenu.Text = 'Upload Impulsive DV Action to KSP';
            app.uploadImpDvActionToKspMenu.Tag = 'uploadImpDvActionToKspMenu';
            % Assign app.scriptListboxContextMenu
            app.scriptListbox.ContextMenu = app.scriptListboxContextMenu;
            app.outputContextMenu = uicontextmenu(app.ma_LvdMainGUI);
            app.outputContextMenu.Tag = 'outputContextMenu';
            app.clearOutputMenu = uimenu(app.outputContextMenu);
            app.clearOutputMenu.MenuSelectedFcn = createCallbackFcn(app, @clearOutputMenu_Callback, true);
            app.clearOutputMenu.Text = 'Clear Output';
            app.clearOutputMenu.Tag = 'clearOutputMenu';
            % Assign app.outputContextMenu
            app.outputText.ContextMenu = app.outputContextMenu;
            % Show the figure after all components are created
            app.ma_LvdMainGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ma_LvdMainGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ma_LvdMainGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)ma_LvdMainGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ma_LvdMainGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ma_LvdMainGUI)
        end
    end
end