classdef lvd_EditConstraintsGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        lvd_EditConstraintsGUI  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        Panel_2                 matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        ConstraintInfoTextArea  matlab.ui.control.TextArea
        Panel                   matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        GridLayout4             matlab.ui.container.GridLayout
        removeConstraintButton  matlab.ui.control.Button
        addConstraintButton     matlab.ui.control.Button
        constraintsListBox      matlab.ui.control.ListBox
        closeButton             matlab.ui.control.Button
        text2                   matlab.ui.control.Label
    end

    methods (Access = private)
        function mouseMovedCallback(app, jListbox, jEventData, hListbox, tooltipStrs)
%             % Get the currently-hovered list-item
%             mousePos = java.awt.Point(jEventData.getX, jEventData.getY);
%             hoverIndex = jListbox.locationToIndex(mousePos) + 1;
% 
%             % Modify the tooltip based on the hovered item
%             if(hoverIndex > 0 && hoverIndex <= length(tooltipStrs))
%                 msgStr = tooltipStrs{hoverIndex};
%                 set(hListbox, 'Tooltip',msgStr);
%             else
%                 set(hListbox, 'Tooltip','');
%             end 
        end

        function populateGUI(app, handles, lvdData)
            [~, ~] = app.setListboxStr();

            setTooltipStrings(app, handles, lvdData);
        end
        
        function [listboxStr, consts] = setListboxStr(app)
            lvdData = getappdata(app.lvd_EditConstraintsGUI,'lvdData');
            [listboxStr, consts] = lvdData.optimizer.constraints.getListboxStr();
            app.constraintsListBox.Items = listboxStr;
            app.constraintsListBox.ItemsData = consts;
            
            num = numel(consts);
            if(num <= 0)
                app.removeConstraintButton.Enable = 'off';
            else
                app.removeConstraintButton.Enable = 'on';
            end
        end

        function setTooltipStrings(app, handles, lvdData)
%             warning('off','MATLAB:ui:javaframe:PropertyToBeRemoved');
% 
%             hListbox = handles.constraintsListBox;
%             jScrollPane = findjobj(hListbox);
%             jListbox = jScrollPane.getViewport.getComponent(0);
%             jListbox = handle(jListbox, 'CallbackProperties');
% 
%             tooltipStrs = lvdData.optimizer.constraints.getToolboxStrs();
%             set(jListbox, 'MouseMovedCallback', @(src, evt) mouseMovedCallback(app, src, evt, hListbox, tooltipStrs));
% 
%             warning('on','MATLAB:ui:javaframe:PropertyToBeRemoved');

            tooltipStrs = lvdData.optimizer.constraints.getToolboxStrs();
            
            consts = app.constraintsListBox.ItemsData;
            const = app.constraintsListBox.Value;
            if(numel(consts) > 0 && not(isempty(const)))
                ind = find(const == consts, 1, 'first');
                tooltipStr = tooltipStrs(ind);
                app.ConstraintInfoTextArea.Value = tooltipStr;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function lvd_EditConstraintsGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to lvd_EditConstraintsGUI (see VARARGIN)

            % Choose default command line output for lvd_EditConstraintsGUI
            handles.output = hObject;

            centerUIFigure(app.lvd_EditConstraintsGUI);
            applySelectedThemeToApp(app);
            
            lvdData = varargin{1};
            setappdata(hObject,'lvdData',lvdData);

            populateGUI(app, handles, lvdData);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes lvd_EditConstraintsGUI wait for user response (see UIRESUME)
            uiwait(handles.lvd_EditConstraintsGUI);
        end

        % Button pushed function: addConstraintButton
        function addConstraintButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to addConstraintButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditConstraintsGUI,'lvdData');

            listBoxStr = ConstraintEnum.getListBoxStr();

            out = AppDesignerGUIOutput();
            listdlgARH_App('ListString',listBoxStr, ...
                'SelectionMode', 'single', ...
                'ListSize', [300 300], ...
                'Name', 'Constraint Type', ...
                'PromptString', 'Select the constraint type:', ...
                'out',out);
            Selection = out.output{1};
            ok = out.output{2};

            if(ok == 1)
                [~, enum] = ConstraintEnum.getIndForName(listBoxStr{Selection});
                constClass = enum.class;
                input1 = enum.constructorInput1; %#ok<NASGU> 

                newConstraint = eval(sprintf('%s.getDefaultConstraint(%s, %s)', constClass, 'input1', 'lvdData'));

                addConstraintTf = newConstraint.openEditConstraintUI(lvdData);

                if(addConstraintTf)
                    constraintSet = lvdData.optimizer.constraints;
                    constraintSet.addConstraint(newConstraint);

                    [~, ~] = app.setListboxStr();
                    app.constraintsListBox.Value = newConstraint;

                    if(handles.constraintsListBox.Value <= 0)
                        handles.constraintsListBox.Value = 1;
                    elseif(handles.constraintsListBox.Value > length(listBoxStr))
                        handles.constraintsListBox.Value = length(listBoxStr);
                    end
                end
            end

            setTooltipStrings(app, handles, lvdData);
        end

        % Button pushed function: closeButton
        function closeButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to closeButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.lvd_EditConstraintsGUI);
        end

        % Value changed function: constraintsListBox
        function constraintsListBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to constraintsListBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns constraintsListBox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from constraintsListBox
%             if(strcmpi(get(handles.lvd_EditConstraintsGUI,'SelectionType'),'open'))
%                 lvdData = getappdata(handles.lvd_EditConstraintsGUI,'lvdData');
% 
%                 constraintSet = lvdData.optimizer.constraints;
%                 selConstraint = get(handles.constraintsListBox,'Value');
%                 constraint = constraintSet.getConstraintForInd(selConstraint);
% 
%                 constraint.openEditConstraintUI(lvdData);
% 
%                 [~, ~] = app.setListboxStr();
% 
%                 setTooltipStrings(app, handles, lvdData);
%             end

            lvdData = getappdata(handles.lvd_EditConstraintsGUI,'lvdData');
            setTooltipStrings(app, handles, lvdData)
        end

        % Window key press function: lvd_EditConstraintsGUI
        function lvd_EditConstraintsGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to lvd_EditConstraintsGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            switch(eventdata.Key)
                case 'return'
                    close(handles.lvd_EditConstraintsGUI);
                case 'enter'
                    close(handles.lvd_EditConstraintsGUI);
                case 'escape'
                    close(handles.lvd_EditConstraintsGUI);
            end
        end

        % Button pushed function: removeConstraintButton
        function removeConstraintButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeConstraintButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            lvdData = getappdata(handles.lvd_EditConstraintsGUI,'lvdData');
            constraintSet = lvdData.optimizer.constraints;

            constraint = app.constraintsListBox.Value;

            constraintSet.removeConstraint(constraint);

            [~, ~] = app.setListboxStr();

%             numConstraints = length(listBoxStr);
%             if(selConstraint > numConstraints)
%                 set(handles.constraintsListBox,'Value',numConstraints);
%             end

            setTooltipStrings(app, handles, lvdData);
        end

        % Window button down function: lvd_EditConstraintsGUI
        function lvd_EditConstraintsGUIWindowButtonDown(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            selType = app.lvd_EditConstraintsGUI.SelectionType;
            curObj = app.lvd_EditConstraintsGUI.CurrentObject;

            if(strcmpi(selType, 'open'))
                if(curObj == app.constraintsListBox && numel(app.constraintsListBox.ItemsData) > 0)
                    lvdData = getappdata(handles.lvd_EditConstraintsGUI,'lvdData');
    
                    constraint = app.constraintsListBox.Value;
    
                    constraint.openEditConstraintUI(lvdData);
    
                    [~, ~] = app.setListboxStr();
    
                    setTooltipStrings(app, handles, lvdData);
                end
            end
        end

        % Window button motion function: lvd_EditConstraintsGUI
        function lvd_EditConstraintsGUIWindowButtonMotion(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.lvd_EditConstraintsGUI = uifigure('Visible', 'off');
            app.lvd_EditConstraintsGUI.Name = 'Edit Constraints';
            app.lvd_EditConstraintsGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.lvd_EditConstraintsGUI.WindowButtonDownFcn = createCallbackFcn(app, @lvd_EditConstraintsGUIWindowButtonDown, true);
            app.lvd_EditConstraintsGUI.WindowButtonMotionFcn = createCallbackFcn(app, @lvd_EditConstraintsGUIWindowButtonMotion, true);
            app.lvd_EditConstraintsGUI.WindowKeyPressFcn = createCallbackFcn(app, @lvd_EditConstraintsGUI_WindowKeyPressFcn, true);
            app.lvd_EditConstraintsGUI.Tag = 'lvd_EditConstraintsGUI';
            app.GridLayout = uigridlayout(app.lvd_EditConstraintsGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Constraints';
            app.closeButton = uibutton(app.GridLayout, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton_Callback, true);
            app.closeButton.Tag = 'closeButton';
            app.closeButton.Icon = 'close_window(1).png';
            app.closeButton.Text = 'Close';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.constraintsListBox = uilistbox(app.GridLayout3);
            app.constraintsListBox.Items = {'Listbox'};
            app.constraintsListBox.ValueChangedFcn = createCallbackFcn(app, @constraintsListBox_Callback, true);
            app.constraintsListBox.Tag = 'constraintsListBox';
            app.constraintsListBox.Value = 'Listbox';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.addConstraintButton = uibutton(app.GridLayout4, 'push');
            app.addConstraintButton.ButtonPushedFcn = createCallbackFcn(app, @addConstraintButton_Callback, true);
            app.addConstraintButton.Tag = 'addConstraintButton';
            app.addConstraintButton.Icon = 'add.png';
            app.addConstraintButton.Text = 'Add Constraint';
            app.removeConstraintButton = uibutton(app.GridLayout4, 'push');
            app.removeConstraintButton.ButtonPushedFcn = createCallbackFcn(app, @removeConstraintButton_Callback, true);
            app.removeConstraintButton.Tag = 'removeConstraintButton';
            app.removeConstraintButton.Icon = 'remove.png';
            app.removeConstraintButton.Text = 'Remove Constraint';
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.Panel_2);
            app.ConstraintInfoTextArea = uitextarea(app.GridLayout5);
            app.ConstraintInfoTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.lvd_EditConstraintsGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_EditConstraintsGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.lvd_EditConstraintsGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)lvd_EditConstraintsGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.lvd_EditConstraintsGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.lvd_EditConstraintsGUI)
        end
    end
end