classdef lvd_SensorReports < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SensorReportFigureGUI    matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        TabGroup                 matlab.ui.container.TabGroup
        GenerateReportTab        matlab.ui.container.Tab
        GridLayout4              matlab.ui.container.GridLayout
        GridLayout8              matlab.ui.container.GridLayout
        ReportFolderSelector     wt.FileSelector
        ReportOutputFolderLabel  matlab.ui.control.Label
        TargetsPanel             matlab.ui.container.Panel
        GridLayout6              matlab.ui.container.GridLayout
        TargetsListbox           matlab.ui.control.ListBox
        SensorsPanel             matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        SensorsListbox           matlab.ui.control.ListBox
        GenerateReportButton     matlab.ui.control.Button
        OutputInstructionsTab    matlab.ui.container.Tab
        GridLayout7              matlab.ui.container.GridLayout
        Label2                   matlab.ui.control.Label
        text2                    matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        BusyStatusLabel          matlab.ui.control.Label
        ProgressBar              wt.ProgressBar
    end


    properties (Access = private)
        lvdData LvdData
    end
    methods (Access = private)
        function populateGUI(app)
            [listboxStr, sensors] = app.lvdData.sensors.getListboxStr();
            app.SensorsListbox.Items = listboxStr;
            app.SensorsListbox.ItemsData = sensors;
            app.SensorsListbox.Value = sensors;

            [listboxStr, targets] = app.lvdData.sensorTgts.getListboxStr();
            app.TargetsListbox.Items = listboxStr;
            app.TargetsListbox.ItemsData = targets;
            app.TargetsListbox.Value = targets;
            
            app.ProgressBar.startProgress('');
            app.BusyStatusLabel.Text = '';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            centerUIFigure(app.SensorReportFigureGUI);

            app.lvdData = varargin{1};

            app.populateGUI();

            uiwait(app.SensorReportFigureGUI);
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
            sensors = app.SensorsListbox.Value;
            targets = app.TargetsListbox.Value;
            
            app.GenerateReportButton.Enable = false;
            
            if(app.ReportFolderSelector.ValueIsValidPath)
                reportFolder = app.ReportFolderSelector.FullPath; 
            else
                reportFolder = pwd;
            end
            
            try
                for(i=1:length(sensors)) %#ok<*NO4LP> 
                    sensor = sensors(i);
                    
                    report = LaunchVehicleSensorReport(sensor, app.lvdData);
                    filepath(i) = report.generateReport(targets, app.ProgressBar, app.BusyStatusLabel, i, length(sensors), reportFolder); %#ok<AGROW> 
                end
            catch ME %#ok<NASGU> 
                app.ProgressBar.finishProgress('');
                app.BusyStatusLabel.Text = 'Done.';
                app.GenerateReportButton.Enable = true;
            end
            
            app.ProgressBar.finishProgress('');
            app.BusyStatusLabel.Text = 'Done.';
            app.GenerateReportButton.Enable = true;
            
            msg = sprintf("The following XLS files have been generated: \n\n%s", sprintf("%s\n",filepath));
            uialert(app.SensorReportFigureGUI, msg, 'Reports Generated', "Icon","success");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SensorReportFigureGUI = uifigure('Visible', 'off');
            app.SensorReportFigureGUI.Name = 'Sensor Reports';
            app.SensorReportFigureGUI.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.SensorReportFigureGUI);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.ProgressBar = wt.ProgressBar(app.GridLayout3);
            app.ProgressBar.ShowTimeRemaining = 'off';
            app.BusyStatusLabel = uilabel(app.GridLayout3);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.text2 = uilabel(app.GridLayout2);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Sensor Reports';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.GenerateReportTab = uitab(app.TabGroup);
            app.GenerateReportTab.Title = 'Generate Report';
            app.GridLayout4 = uigridlayout(app.GenerateReportTab);
            app.GenerateReportButton = uibutton(app.GridLayout4, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'Generate Report';
            app.SensorsPanel = uipanel(app.GridLayout4);
            app.SensorsPanel.Title = 'Sensors';
            app.GridLayout5 = uigridlayout(app.SensorsPanel);
            app.SensorsListbox = uilistbox(app.GridLayout5);
            app.SensorsListbox.Multiselect = 'on';
            app.SensorsListbox.Value = {'Item 1'};
            app.TargetsPanel = uipanel(app.GridLayout4);
            app.TargetsPanel.Title = 'Targets';
            app.GridLayout6 = uigridlayout(app.TargetsPanel);
            app.TargetsListbox = uilistbox(app.GridLayout6);
            app.TargetsListbox.Multiselect = 'on';
            app.TargetsListbox.Value = {'Item 1'};
            app.GridLayout8 = uigridlayout(app.GridLayout4);
            app.ReportOutputFolderLabel = uilabel(app.GridLayout8);
            app.ReportOutputFolderLabel.Text = 'Report Output Folder:';
            app.ReportFolderSelector = wt.FileSelector(app.GridLayout8);
            app.ReportFolderSelector.SelectionType = 'folder';
            app.ReportFolderSelector.Tooltip = "The folder in which the generated report XLS files wil be placed.  If empty, it will be the current working MATLAB directory. ";
            app.OutputInstructionsTab = uitab(app.TabGroup);
            app.OutputInstructionsTab.Title = 'Output Instructions';
            app.GridLayout7 = uigridlayout(app.OutputInstructionsTab);
            app.Label2 = uilabel(app.GridLayout7);
            app.Label2.Text = {'Each sensor selected produces an XLS file with data for each selected target.  The following types of worksheets are created in each sensor''s XLS file:'; ''; 'Coverage - A 0 means that the sensor cannot see that target point and a 1 means the sensor can see the target point.  Instantaneous coverage is how much of the target the sensor currently sees and cumulative coverage is how much of the target has been cumulatively seen over time.  In these cases, 0 means no coverage and 1 means full coverage.'; ''; 'Azimuth - The azimuth angle from the sensor boresight to the target point, in degrees.  Azimuth is defined as the angle in the sensor''s XY plane, counterclockwise about the sensor''s Z axis.'; ''; 'Elevation - The elevation angle from the sensor boresight to the target point, in degrees.  Elevation is defined as the angle to the target above or below the sensor''s XY plane.'; ''; 'Range - The range from the sensor to the target point, in kilometers.'; ''; 'Angle - The absolute angle from the sensor boresight to the target point, in degrees.'; ''; 'The sensor coordinate system is defined such that the X axis is pointed along the sensor boresight.  The sensor Z axis is constrained to be as close to the sensor''s pointing model''s Z axis as possible.'; ''};
            % Show the figure after all components are created
            app.SensorReportFigureGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lvd_SensorReports(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SensorReportFigureGUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SensorReportFigureGUI)
        end
    end
end