classdef listdlgARH_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ListDlgARH       matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        GridLayout2      matlab.ui.container.GridLayout
        CancelButton     matlab.ui.control.Button
        OKButton         matlab.ui.control.Button
        SelectAllButton  matlab.ui.control.Button
        ListBox          matlab.ui.control.ListBox
        PromptLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        out AppDesignerGUIOutput
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            narginchk(1,inf)

            figname = '';
            smode = true;   % (multiple)
            promptstring = {};
            liststring = [];
            listdata = [];
            listsize = [160 300];
            initialvalue = [];
            okstring = getString(message('MATLAB:uistring:popupdialogs:OK'));
            cancelstring = getString(message('MATLAB:uistring:popupdialogs:Cancel'));
            fus = 8;
            ffs = 8;
            uh = 22;

            if mod(length(varargin),2) ~= 0
                % input args have not com in pairs, woe is me
                error(message('MATLAB:listdlg:InvalidArgument'))
            end

            for i=1:2:length(varargin)
                switch lower(varargin{i})
                    case 'name'
                        figname = varargin{i+1};
                    case 'promptstring'
                        promptstring = varargin{i+1};
                    case 'selectionmode'
                        switch lower(varargin{i+1})
                            case 'single'
                                smode = false;
                            case 'multiple'
                                smode = true;
                        end
                    case 'listsize'
                        listsize = varargin{i+1};
                    case 'liststring'
                        liststring = varargin{i+1};
                    case 'listdata'
                        listdata = varargin{i+1};
                    case 'initialvalue'
                        initialvalue = varargin{i+1};
                    case 'out'
                        app.out = varargin{i+1};
                    case 'uh'
                        uh = varargin{i+1};
                    case 'fus'
                        fus = varargin{i+1};
                    case 'ffs'
                        ffs = varargin{i+1};
                    case 'okstring'
                        okstring = varargin{i+1};
                    case 'cancelstring'
                        cancelstring = varargin{i+1};
                    otherwise
                        error(message('MATLAB:listdlg:UnknownParameter', varargin{ i }))
                end
            end

            if(isempty(app.out))
                error('There must be a AppDesignerGUIOutput object provided to get data out of the listbox.');
            end

            if ischar(promptstring)
                promptstring = cellstr(promptstring); 
            end
            
            if isempty(initialvalue) && smode==false
                initialvalue = 1;
            end
            
            if isempty(liststring)
                error(message('MATLAB:listdlg:NeedParameter'))
            end

            if(isempty(listdata))
                listdata = liststring;
            end

            if(numel(listdata) ~= numel(liststring))
                error('Number of listdata items must be the same as the number of liststring items.');
            end

            app.ListDlgARH.Name = figname;
            app.PromptLabel.Text = promptstring;
            app.PromptLabel.Tooltip = promptstring;
            app.ListBox.Multiselect = smode;
            app.SelectAllButton.Enable = smode;
            
            ex = get(0,'DefaultUicontrolFontSize')*1.7;  % height extent per line of uicontrol text (approx)
            w = 2*(fus+ffs)+listsize(1);
            h = 2*ffs+6*fus+ex*length(promptstring)+listsize(2)+uh+(smode)*(fus+uh);

            pos = app.ListDlgARH.Position;
            pos(3:4) = [w h];
            app.ListDlgARH.Position = pos;

            app.ListBox.Items = liststring;
            app.ListBox.ItemsData = listdata;
            app.ListBox.Value = listdata(initialvalue);

            app.OKButton.Text = okstring;
            app.CancelButton.Text = cancelstring;

            centerUIFigure(app.ListDlgARH);

            uiwait(app.ListDlgARH);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.out.output{1} = find(ismember(app.ListBox.ItemsData,app.ListBox.Value));
            app.out.output{2} = 1;

            close(app.ListDlgARH);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.out.output{1} = [];
            app.out.output{2} = 0;

            close(app.ListDlgARH);
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.ListBox.Value = app.ListBox.ItemsData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ListDlgARH = uifigure('Visible', 'off');
            app.ListDlgARH.Name = 'MATLAB App';
            app.ListDlgARH.Icon = 'logoSquare_48px_transparentBg.png';
            app.GridLayout = uigridlayout(app.ListDlgARH);
            app.PromptLabel = uilabel(app.GridLayout);
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.SelectAllButton = uibutton(app.GridLayout, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.OKButton = uibutton(app.GridLayout2, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.ListDlgARH.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = listdlgARH_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ListDlgARH)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ListDlgARH)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ListDlgARH)
        end
    end
end